classdef Audio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout36               matlab.ui.container.GridLayout
        GridLayout37               matlab.ui.container.GridLayout
        FinalizarButton            matlab.ui.control.Button
        ReproducirButton           matlab.ui.control.Button
        AmplitudLineal_S           matlab.ui.control.Slider
        AmplitudLinealSliderLabel  matlab.ui.control.Label
        Amplitud                   matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel     matlab.ui.control.Label
        AjustedeVolumenLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        Signal;
        player;
        FrecuenciaMuestreo;
        
    end
    
    
    methods (Access = public)
        
        function AgregarSignal(app,S, Fs, G)
            app.Signal = S;
            app.FrecuenciaMuestreo = Fs;
            
            app.Amplitud.Value = G;
            app.AmplitudLineal_S.Value = G;
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            app.AmplitudLineal_S.Value = app.Amplitud.Value;

            disp("Reproducir ");
            disp(app.FrecuenciaMuestreo);

            if isempty(app.FrecuenciaMuestreo) || app.FrecuenciaMuestreo <= 80
                msgbox(['La frecuencia de muestreo no es válida.'], 'Confirmación', 'help');
            else
                app.Signal = app.Signal.*app.Amplitud.Value;
                app.player = audioplayer(app.Signal, app.FrecuenciaMuestreo);
                play(app.player);
            end


            %countName = 1;
            % Verificacion si el archivo wav existe
            %while exist(['Signal' num2str(countName) '.wav'], 'file') == 2
            %    countName = countName + 1;
            %end
            %audiowrite(['Signal' num2str(countName) '.wav'],app.Signal,app.Fs);%Escribir como audio
            
        end

        % Value changing function: AmplitudLineal_S
        function AmplitudLineal_SValueChanging(app, event)
            changingValue = event.Value;
            app.Amplitud.Value = changingValue;
        end

        % Value changed function: AmplitudLineal_S
        function AmplitudLineal_SValueChanged(app, event)
            
            if ~isempty(app.player)

                % Se obtiene la muestra actual
                TiempoAudio = app.player.CurrentSample;
                disp("Tiempo actual con Slider");
                disp(TiempoAudio);
                stop(app.player); % Se detiene la reproducción
            
                % Aplica el cambio de amplitud SOBRE TODA la señal original
                app.Signal = app.Signal .* app.Amplitud.Value;
            
                % Reproduce SOLO desde donde va.
                Sigl = app.Signal(TiempoAudio:end, :);
            
                % Crea un nuevo audioplayer
                app.player = audioplayer(Sigl, app.FrecuenciaMuestreo);
                play(app.player);
                
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            disp('cerrando...');
            delete(app);
        end

        % Button pushed function: FinalizarButton
        function FinalizarButtonPushed(app, event)
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout36 = uigridlayout(app.UIFigure);
            app.AjustedeVolumenLabel = uilabel(app.GridLayout36);
            app.AjustedeVolumenLabel.Text = 'Ajuste de Volumen';
            app.AmplitudEditFieldLabel = uilabel(app.GridLayout36);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.Amplitud = uieditfield(app.GridLayout36, 'numeric');
            app.Amplitud.Value = 5;
            app.AmplitudLinealSliderLabel = uilabel(app.GridLayout36);
            app.AmplitudLinealSliderLabel.Text = 'Amplitud Lineal';
            app.AmplitudLineal_S = uislider(app.GridLayout36);
            app.AmplitudLineal_S.ValueChangedFcn = createCallbackFcn(app, @AmplitudLineal_SValueChanged, true);
            app.AmplitudLineal_S.ValueChangingFcn = createCallbackFcn(app, @AmplitudLineal_SValueChanging, true);
            app.AmplitudLineal_S.Value = 5;
            app.GridLayout37 = uigridlayout(app.GridLayout36);
            app.ReproducirButton = uibutton(app.GridLayout37, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.FinalizarButton = uibutton(app.GridLayout37, 'push');
            app.FinalizarButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizarButtonPushed, true);
            app.FinalizarButton.Text = 'Finalizar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end