classdef Crear < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        SignalParametersTab             matlab.ui.container.TabGroup
        MicrofonoTab                    matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        GridLayout29                    matlab.ui.container.GridLayout
        GuardarButton                   matlab.ui.control.Button
        ReproducirButton                matlab.ui.control.StateButton
        GrabarButton                    matlab.ui.control.StateButton
        GridLayout14                    matlab.ui.container.GridLayout
        ndeInicioTxtMicrofono           matlab.ui.control.NumericEditField
        nInicialEditFieldLabel          matlab.ui.control.Label
        TiempoGrabsTxt                  matlab.ui.control.NumericEditField
        TiempoGrabsEditFieldLabel       matlab.ui.control.Label
        NmCanalesTxt                    matlab.ui.control.NumericEditField
        NmCanalesEditFieldLabel         matlab.ui.control.Label
        BitsMuestraTxt                  matlab.ui.control.NumericEditField
        BitsMuestraEditFieldLabel       matlab.ui.control.Label
        FrecuenciadeMuestreoTxt         matlab.ui.control.NumericEditField
        FrecuenciadeMuestreoEditFieldLabel  matlab.ui.control.Label
        SimuladaTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout27                    matlab.ui.container.GridLayout
        SimularButton                   matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ImpulsoButton                   matlab.ui.control.RadioButton
        SincButton                      matlab.ui.control.RadioButton
        ChirpButton                     matlab.ui.control.RadioButton
        EscalnButton                    matlab.ui.control.RadioButton
        TriangularButton                matlab.ui.control.RadioButton
        RampaButton                     matlab.ui.control.RadioButton
        CosenoButton                    matlab.ui.control.RadioButton
        SenoButton                      matlab.ui.control.RadioButton
        DientedeSierraButton            matlab.ui.control.RadioButton
        GridLayout3                     matlab.ui.container.GridLayout
        FrecuenciaFinaltxt              matlab.ui.control.NumericEditField
        FrecuenciaFinalLabel            matlab.ui.control.Label
        ndeFinTxt                       matlab.ui.control.NumericEditField
        ndeFinEditField_3Label          matlab.ui.control.Label
        ndeInicioTxt                    matlab.ui.control.NumericEditField
        ndeInicioEditField_3Label       matlab.ui.control.Label
        DesplazamientoTxt               matlab.ui.control.NumericEditField
        DesplazamientoEditField_3Label  matlab.ui.control.Label
        FaseTxt                         matlab.ui.control.NumericEditField
        FaseradEditField_3Label         matlab.ui.control.Label
        FrecuenciaMuestreoTxt           matlab.ui.control.NumericEditField
        FrecuenciaMuestreoEditField_3Label  matlab.ui.control.Label
        FrecuenciaAnlogaTxt             matlab.ui.control.NumericEditField
        FrecuenciaAnlogaoInicialLabel   matlab.ui.control.Label
        Gananciatxt                     matlab.ui.control.NumericEditField
        GananciaLabel                   matlab.ui.control.Label
        ArchivoTab                      matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        GridLayout24                    matlab.ui.container.GridLayout
        GridLayout26                    matlab.ui.container.GridLayout
        GridLayout31                    matlab.ui.container.GridLayout
        CargarArchivoButton_2           matlab.ui.control.Button
        PathLabel                       matlab.ui.control.Label
        GridLayout3_2                   matlab.ui.container.GridLayout
        FrecuenciaDeMuestreoTxt         matlab.ui.control.NumericEditField
        tiempoLabel                     matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        CrearSealButton                 matlab.ui.control.Button
        InfoLabel                       matlab.ui.control.Label
        TarjetaTab                      matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout30                    matlab.ui.container.GridLayout
        TimeLabel                       matlab.ui.control.Label
        AdquirirSealButton              matlab.ui.control.StateButton
        GridLayout23                    matlab.ui.container.GridLayout
        EntradasDropDown                matlab.ui.control.DropDown
        EntradasLabel                   matlab.ui.control.Label
        PuertosCOMDropDown              matlab.ui.control.DropDown
        PuertosCOMLabel                 matlab.ui.control.Label
        ndeInicioTxtTarjeta             matlab.ui.control.NumericEditField
        ndeinicioLabel                  matlab.ui.control.Label
        FrecuenciademuestreoTxt         matlab.ui.control.NumericEditField
        FrecuenciadeMuestreofsLabel     matlab.ui.control.Label
        TiempoTxt                       matlab.ui.control.NumericEditField
        TiemposLabel                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        Main; % VentanaPrincipal
        Tipo;
        Ganancia;FrecuenciaAnaloga;FrecuenciaMuestreo;Fase;Desplazamiento;n_inicio;n_final;n; %Parametros
        Signal = [];
        NombreArch, pathname;
    end
    
    properties (Access = private)
        CronometroTimer % timer object
        TiempoTranscurrido = 0; % segundos
    end
    
    methods (Access = public)
        
        %uncion para Actualizar las variables globales en la clase de crear
       function Cerrar(app)
            app.Tipo = 0;
            disp('cerrando...');
            delete(app);
       end

       function actualizarCronometro(app, END)
            app.TiempoTranscurrido = app.TiempoTranscurrido + 1;
            if(app.TiempoTranscurrido > 0)
                minutos = floor(app.TiempoTranscurrido / 60);
                segundos = mod(app.TiempoTranscurrido, 60);
                app.TimeLabel.Text = sprintf('Time: %02d:%02d', minutos, segundos);
                
                if app.TiempoTranscurrido > END
                    stop(app.CronometroTimer);
                    %pause(0.1); % Esperar 10 ms para no congelar el sistema
                end 

            end
        end
        
        function StopButtonPushed(app, event)
            if ~isempty(app.CronometroTimer) && isvalid(app.CronometroTimer)
                stop(app.CronometroTimer);
                delete(app.CronometroTimer);
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.CloseRequestFcn = @(src, event) Cerrar(app);
            ports = serialportlist("all"); % Lista todos los puertos COM disponibles
            if isempty(ports)
                app.PuertosCOMDropDown.Items = {'No hay puertos'};
            else
                app.PuertosCOMDropDown.Items = ports;
            end
        end

        % Callback function
        function SealDeentradaButtonGroupSelectionChanged(app, event)
            
        end

        % Callback function
        function SignalParametersTabSelectionChanged(app, event)
            selectedTab = app.SignalParametersTab.SelectedTab;
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
           app.Tipo = 2;
           app.Ganancia = app.Gananciatxt.Value;
           app.FrecuenciaAnaloga = app.FrecuenciaAnlogaTxt.Value;
           app.FrecuenciaMuestreo = app.FrecuenciaMuestreoTxt.Value;
           app.Fase = app.FaseTxt.Value;
           app.Desplazamiento = app.DesplazamientoTxt.Value;
           app.n_inicio = app.ndeInicioTxt.Value;
           app.n_final = app.ndeFinTxt.Value;

           app.n = app.n_inicio:app.n_final;

           if  app.ButtonGroup.SelectedObject == app.SenoButton
                
               app.Signal = app.Ganancia*sin(2*pi*app.FrecuenciaAnaloga*(app.n-app.Desplazamiento)/app.FrecuenciaMuestreo + app.Fase);

           elseif app.ButtonGroup.SelectedObject == app.CosenoButton

               app.Signal =app.Ganancia*cos(2*pi*app.FrecuenciaAnaloga*(app.n-app.Desplazamiento)/app.FrecuenciaMuestreo+ app.Fase);

           elseif app.ButtonGroup.SelectedObject == app.DientedeSierraButton

               app.Signal= app.Ganancia*sawtooth((2*pi*app.FrecuenciaAnaloga*(app.n-app.Desplazamiento)/app.FrecuenciaMuestreo+ app.Fase)); % Diente de sierra
               

           elseif app.ButtonGroup.SelectedObject == app.RampaButton

                app.Signal = app.Ganancia*(app.n-app.Desplazamiento);

           elseif app.ButtonGroup.SelectedObject == app.TriangularButton

                app.Signal= app.Ganancia*sawtooth((2*pi*app.FrecuenciaAnaloga*(app.n-app.Desplazamiento)/app.FrecuenciaMuestreo+ app.Fase),0.5); % Triangular

           elseif app.ButtonGroup.SelectedObject == app.EscalnButton

                app.Signal = app.Ganancia*[zeros(1,abs(app.n_inicio)+app.Desplazamiento)  ones(1, abs(app.n_final)+1-app.Desplazamiento)];%Escalon

           elseif app.ButtonGroup.SelectedObject == app.ChirpButton
                
                % Parámetros
                Fs = app.FrecuenciaMuestreo;         % Frecuencia de muestreo (Hz)
                T = round(length(app.n)/Fs);         % Duración total (segundos)
                
                t = linspace(app.n_inicio, T, app.n_final);            % Vector de tiempo
                
                f0 = app.FrecuenciaAnaloga;          % Frecuencia inicial (Hz)
                f1 = app.FrecuenciaFinaltxt.Value;   % Frecuencia final (Hz)
                
                                                     % Calcular pendiente k
                k = (f1 - f0) / T;                   % Hz por segundo
                
               
                app.Signal = app.Ganancia * sin(2*pi*(f0*t + (k/2)*t.^2) + app.Fase) + app.Desplazamiento;
                
                app.n = t;
                

           elseif app.ButtonGroup.SelectedObject == app.SincButton

                app.Signal= app.Ganancia*sinc(2*pi*app.FrecuenciaAnaloga*(n-app.Desplazamiento)/app.FrecuenciaMuestreo+ app.Fase); % Sinc

           elseif app.ButtonGroup.SelectedObject == app.ImpulsoButton
               
                app.Signal = app.Ganancia*[zeros(1,abs(app.n_inicio)+app.Desplazamiento)  1 zeros(1,abs(app.n_final)-app.Desplazamiento)];

           end
           
          uiresume(app.UIFigure);
        end

        % Value changed function: GrabarButton
        function GrabarButtonValueChanged(app, event)
            value = app.GrabarButton.Value;
            app.Tipo = 1;
            app.FrecuenciaMuestreo=app.FrecuenciadeMuestreoTxt.Value;    % Frecuencia de muestreo   
            nBit=app.BitsMuestraTxt.Value;   % Número de bits por muestra
            nChan=app.NmCanalesTxt.Value;   % Número de canales
            tRec1=app.TiempoGrabsTxt.Value;    % Tiempo de grabación en s.
            app.n_inicio = app.ndeInicioTxtMicrofono.Value;
            countName = 1;

            aud1 = audiorecorder(app.FrecuenciaMuestreo,nBit,nChan); % Crear un objeto audiorecorder
            
            disp('Iniciando la grabación...');
            
            recordblocking(aud1, tRec1); % Grabar  audio
            
            disp('Fin de la grabación.');
            
            %app.Signal = getaudiodata(aud1); % Obtener los datos de la señal x1
            app.n_final = (length(app.Signal)-1)+app.n_inicio;
            app.n = app.n_inicio:app.n_final;
           
            %plot(app.GraficaAudioInput,apn,app.Signal)

            uiresume(app.UIFigure);
        end

        % Value changed function: ReproducirButton
        function ReproducirButtonValueChanged(app, event)
            value = app.ReproducirButton.Value;
             if app.Fs>4000
                
                 sound(app.S1,app.Fs);
                
            else
                disp("Error frecuencia de muestreo por debajo de 4000")
            end
        end

        % Button pushed function: CrearSealButton
        function CrearSealButtonPushed(app, event)
            if ~isempty(app.PathLabel.Text)
                NArchivo_Tipo = split(app.NombreArch, '.');                        % Se separa el archivo seleccionado para ver la extención
                
                if strcmp(NArchivo_Tipo(2), 'wav') || strcmp(NArchivo_Tipo(2), 'csv')
                    bien = 0;
                    if strcmp(NArchivo_Tipo(2), 'wav')
        
                        [app.Signal,app.FrecuenciaMuestreo] = audioread(fullfile(app.pathname,app.NombreArch)); % Leer el archivo de audio
                        % Información del archivo de audio.
                        info = audioinfo(app.NombreArch);
                        % Mostrar la información en el cuadro de texto
                        %app.InfoLabel.Text = sprintf('Nombre: %s\nDuración: %f segundos\nFrecuencia de muestreo: %d Hz\nNumero de canales: %d\nCantidad de Bits: %d\nTitle: %s\nComentarios: %s\nArtista: %s\n',info.Filename, info.TotalSamples/info.SampleRate, info.SampleRate, info.NumChannels, info.BitsPerSample, info.Title, info.Comment, info.Artist);
                        
                        bien = 1;
    
                    elseif strcmp(NArchivo_Tipo(2), 'csv')
                        
                        matriz = readmatrix(app.PathLabel.Text); 
                        [numFilas, numColumnas] = size(matriz);
                        disp("Csv Columnas:");
                        disp(numColumnas); 
                        app.Signal = matriz;
                        %app.InfoLabel.Text = sprintf('Nombre: %s\nRuta: %s',NombreArch, app.PathLabel.Text);
                                                    
                        app.FrecuenciaMuestreo = app.FrecuenciaDeMuestreoTxt.Value;
                        bien = 1;

                    
                    end
                    if bien==1
                        N = length(app.Signal);
                        app.n_inicio = 0;                                               % Se asume un instante inicial 0
                        app.n_final = N-1;
                        app.n= app.n_inicio:app.n_final;
                        
                        X = abs(fft(app.Signal));                                       % Magnitud del espectro
                        f = app.n * (app.FrecuenciaMuestreo/N-1);                       % Vector de frecuencias  
                        [~, idx] = max(X(1:N/2));                                       % Encuentra el pico en el espectro
                        app.FrecuenciaAnaloga = f(idx);                                 % Frecuencia dominante en Hz
        
                        app.Ganancia = max(abs(app.Signal));                            % Amplitud máxima
                        
                        X_fft = fft(app.Signal);                                        % Transformada de Fourier
                        app.Fase = angle(X_fft(idx));                                   % Fase en radianes
        
                        app.Desplazamiento = mean(app.Signal);
                    end
                else
                    msgbox('No es una señal wav o csv', 'Error de entrada');
                end
                uiresume(app.UIFigure);
           end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            
        end

        % Value changed function: AdquirirSealButton
        function AdquirirSealButtonValueChanged(app, event)
            % Parámetros
            app.Tipo = 4;
            app.FrecuenciaMuestreo = app.FrecuenciademuestreoTxt.Value;         % Frecuencia de muestreo (Hz)
            if(app.FrecuenciaMuestreo>=10) 
                T = app.TiempoTxt.Value;                                            % Duración total (segundos)
                app.n_inicio = app.ndeInicioTxtTarjeta.Value;                       % Tiempo inicial
                
                app.n_final = app.FrecuenciaMuestreo * T;                                                                           % Total de muestras
                app.n = app.n_inicio : 1/app.FrecuenciaMuestreo : app.n_inicio + T - 1/app.FrecuenciaMuestreo;  % Vector de tiempo
    
                % Configuración del puerto serial
                comPort = app.PuertosCOMDropDown.Value;                             % Recibe el puerto de cominicacion de dropdown
                arduino = serialport(comPort, 115200);
                pause(1);                                                           % Esperar a que Arduino se reinicie
                
                % Limpia el buffer de entrada
                flush(arduino);
                
                sampleInterval = round(1000/app.FrecuenciaMuestreo);
    
                disp(sampleInterval);
    
                % Bytes
                highByteN = bitshift(app.n_final, -8);                              % muestras totales (cantidad de muetras n)
                lowByteN  = bitand(app.n_final, 255);
                highByteT = bitshift(sampleInterval, -8);                           % intervalos entre muestras (periodo de muestreo Ts)
                lowByteT  = bitand(sampleInterval, 255);
                pinByte   = uint8(app.EntradasDropDown.ValueIndex - 1);             % Selección del pin analógico
               
                % Enviar el número del pin al Arduino
                write(arduino, [highByteN, lowByteN, highByteT, lowByteT, pinByte], "uint8");
                
    
                app.TiempoTranscurrido = 0;
    
                % Si ya hay un timer corriendo, detenerlo primero
                if ~isempty(app.CronometroTimer) && isvalid(app.CronometroTimer)
                    stop(app.CronometroTimer);
                    delete(app.CronometroTimer);
                end
            
                % Crear el timer
                app.CronometroTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 1, ...
                    'TimerFcn', @(~,~) actualizarCronometro(app,T));
                
                start(app.CronometroTimer);


                % Leer datos en paquetes
                rawData = read(arduino, 2 * app.n_final, "uint8");
                % Reconstruir datos de 2 bytes
                app.Signal = double(rawData(1:2:end)) * 256 + double(rawData(2:2:end));


                N = length(app.Signal);
                
    
                X = abs(fft(app.Signal));   % Magnitud del espectro
                f = (app.n_inicio:app.n_final) * (app.FrecuenciaMuestreo/N);  % Vector de frecuencias  
                [~, idx] = max(X(1:N/2));   % Encuentra el pico en el espectro
                app.FrecuenciaAnaloga = f(idx);  % Frecuencia dominante en Hz
                
                app.Ganancia = max(abs(app.Signal));  % Amplitud máxima
    
                X_fft = fft(app.Signal);  % Transformada de Fourier
                app.Fase = angle(X_fft(idx));  % Fase en radianes
    
                app.Desplazamiento = mean(app.Signal);
                uiresume(app.UIFigure);
            else
                msgbox('Porfavor ingresar frecuencias de muestreo mayores a 10Hz', 'Cancelado');
            end
            
        end

        % Button pushed function: CargarArchivoButton_2
        function CargarArchivoButton_2Pushed(app, event)
            app.Tipo = 3;
    
            [app.NombreArch, app.pathname] = uigetfile('*.*', 'Seleccionar archivo');% Buscar Archivo txt o wav
            if isequal(app.NombreArch, 0)                                          % verifica la selección
                disp('No se seleccionó ningún archivo');
                
            else
                app.PathLabel.Text = fullfile(app.pathname, app.NombreArch);
            end

            % Restaurar el focus a tu App
            figure(app.UIFigure) % <- Esto hace que tu app vuelva al frente
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.SignalParametersTab = uitabgroup(app.Panel);
            app.MicrofonoTab = uitab(app.SignalParametersTab);
            app.MicrofonoTab.Title = 'Microfono';
            app.GridLayout28 = uigridlayout(app.MicrofonoTab);
            app.GridLayout14 = uigridlayout(app.GridLayout28);
            app.FrecuenciadeMuestreoEditFieldLabel = uilabel(app.GridLayout14);
            app.FrecuenciadeMuestreoEditFieldLabel.Text = 'Frecuencia de Muestreo';
            app.FrecuenciadeMuestreoTxt = uieditfield(app.GridLayout14, 'numeric');
            app.FrecuenciadeMuestreoTxt.ValueDisplayFormat = '%.0f';
            app.FrecuenciadeMuestreoTxt.Value = 44100;
            app.BitsMuestraEditFieldLabel = uilabel(app.GridLayout14);
            app.BitsMuestraEditFieldLabel.Text = 'Bits/Muestra';
            app.BitsMuestraTxt = uieditfield(app.GridLayout14, 'numeric');
            app.BitsMuestraTxt.ValueDisplayFormat = '%.0f';
            app.BitsMuestraTxt.Value = 16;
            app.NmCanalesEditFieldLabel = uilabel(app.GridLayout14);
            app.NmCanalesEditFieldLabel.Text = 'Núm. Canales';
            app.NmCanalesTxt = uieditfield(app.GridLayout14, 'numeric');
            app.NmCanalesTxt.ValueDisplayFormat = '%.0f';
            app.NmCanalesTxt.Value = 1;
            app.TiempoGrabsEditFieldLabel = uilabel(app.GridLayout14);
            app.TiempoGrabsEditFieldLabel.Text = 'Tiempo Grab. (s)';
            app.TiempoGrabsTxt = uieditfield(app.GridLayout14, 'numeric');
            app.TiempoGrabsTxt.ValueDisplayFormat = '%.0f';
            app.TiempoGrabsTxt.Value = 3;
            app.nInicialEditFieldLabel = uilabel(app.GridLayout14);
            app.nInicialEditFieldLabel.Text = '''n'' Inicial';
            app.ndeInicioTxtMicrofono = uieditfield(app.GridLayout14, 'numeric');
            app.ndeInicioTxtMicrofono.ValueDisplayFormat = '%.0f';
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.GrabarButton = uibutton(app.GridLayout29, 'state');
            app.GrabarButton.ValueChangedFcn = createCallbackFcn(app, @GrabarButtonValueChanged, true);
            app.GrabarButton.Text = 'Grabar';
            app.ReproducirButton = uibutton(app.GridLayout29, 'state');
            app.ReproducirButton.ValueChangedFcn = createCallbackFcn(app, @ReproducirButtonValueChanged, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.GuardarButton = uibutton(app.GridLayout29, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.SimuladaTab = uitab(app.SignalParametersTab);
            app.SimuladaTab.Title = 'Simulada';
            app.GridLayout2 = uigridlayout(app.SimuladaTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GananciaLabel = uilabel(app.GridLayout3);
            app.GananciaLabel.Text = 'Ganancia:';
            app.Gananciatxt = uieditfield(app.GridLayout3, 'numeric');
            app.Gananciatxt.ValueDisplayFormat = '%.2f';
            app.Gananciatxt.Value = 1.5;
            app.FrecuenciaAnlogaoInicialLabel = uilabel(app.GridLayout3);
            app.FrecuenciaAnlogaoInicialLabel.Text = 'Frecuencia Análoga o Inicial:';
            app.FrecuenciaAnlogaTxt = uieditfield(app.GridLayout3, 'numeric');
            app.FrecuenciaAnlogaTxt.ValueDisplayFormat = '%.0f';
            app.FrecuenciaAnlogaTxt.Value = 20;
            app.FrecuenciaMuestreoEditField_3Label = uilabel(app.GridLayout3);
            app.FrecuenciaMuestreoEditField_3Label.Text = 'Frecuencia Muestreo:';
            app.FrecuenciaMuestreoTxt = uieditfield(app.GridLayout3, 'numeric');
            app.FrecuenciaMuestreoTxt.ValueDisplayFormat = '%.0f';
            app.FrecuenciaMuestreoTxt.Value = 400;
            app.FaseradEditField_3Label = uilabel(app.GridLayout3);
            app.FaseradEditField_3Label.Text = 'Fase (rad):';
            app.FaseTxt = uieditfield(app.GridLayout3, 'numeric');
            app.FaseTxt.ValueDisplayFormat = '%.2f';
            app.FaseTxt.Value = 0.5;
            app.DesplazamientoEditField_3Label = uilabel(app.GridLayout3);
            app.DesplazamientoEditField_3Label.Text = 'Desplazamiento:';
            app.DesplazamientoTxt = uieditfield(app.GridLayout3, 'numeric');
            app.DesplazamientoTxt.ValueDisplayFormat = '%.0f';
            app.DesplazamientoTxt.Value = 2;
            app.ndeInicioEditField_3Label = uilabel(app.GridLayout3);
            app.ndeInicioEditField_3Label.Text = '''n'' de Inicio:';
            app.ndeInicioTxt = uieditfield(app.GridLayout3, 'numeric');
            app.ndeInicioTxt.ValueDisplayFormat = '%.0f';
            app.ndeInicioTxt.Value = -5;
            app.ndeFinEditField_3Label = uilabel(app.GridLayout3);
            app.ndeFinEditField_3Label.Text = '''n'' de Fin:';
            app.ndeFinTxt = uieditfield(app.GridLayout3, 'numeric');
            app.ndeFinTxt.ValueDisplayFormat = '%.0f';
            app.ndeFinTxt.Value = 50;
            app.FrecuenciaFinalLabel = uilabel(app.GridLayout3);
            app.FrecuenciaFinalLabel.Text = 'Frecuencia Final (solo chirp): ';
            app.FrecuenciaFinaltxt = uieditfield(app.GridLayout3, 'numeric');
            app.FrecuenciaFinaltxt.ValueDisplayFormat = '%.0f';
            app.FrecuenciaFinaltxt.Value = 16000;
            app.GridLayout27 = uigridlayout(app.GridLayout2);
            app.ButtonGroup = uibuttongroup(app.GridLayout27);
            app.DientedeSierraButton = uiradiobutton(app.ButtonGroup);
            app.DientedeSierraButton.Text = 'Diente de Sierra';
            app.SenoButton = uiradiobutton(app.ButtonGroup);
            app.SenoButton.Text = 'Seno';
            app.SenoButton.Value = true;
            app.CosenoButton = uiradiobutton(app.ButtonGroup);
            app.CosenoButton.Text = 'Coseno';
            app.RampaButton = uiradiobutton(app.ButtonGroup);
            app.RampaButton.Text = 'Rampa';
            app.TriangularButton = uiradiobutton(app.ButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.EscalnButton = uiradiobutton(app.ButtonGroup);
            app.EscalnButton.Text = 'Escalón';
            app.ChirpButton = uiradiobutton(app.ButtonGroup);
            app.ChirpButton.Text = 'Chirp';
            app.SincButton = uiradiobutton(app.ButtonGroup);
            app.SincButton.Text = 'Sinc';
            app.ImpulsoButton = uiradiobutton(app.ButtonGroup);
            app.ImpulsoButton.Text = 'Impulso';
            app.SimularButton = uibutton(app.GridLayout27, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.ArchivoTab = uitab(app.SignalParametersTab);
            app.ArchivoTab.Title = 'Archivo';
            app.GridLayout15 = uigridlayout(app.ArchivoTab);
            app.GridLayout24 = uigridlayout(app.GridLayout15);
            app.InfoLabel = uilabel(app.GridLayout24);
            app.InfoLabel.Text = '';
            app.GridLayout26 = uigridlayout(app.GridLayout24);
            app.GridLayout7 = uigridlayout(app.GridLayout26);
            app.CrearSealButton = uibutton(app.GridLayout7, 'push');
            app.CrearSealButton.ButtonPushedFcn = createCallbackFcn(app, @CrearSealButtonPushed, true);
            app.CrearSealButton.Text = 'Crear Señal';
            app.GridLayout3_2 = uigridlayout(app.GridLayout26);
            app.tiempoLabel = uilabel(app.GridLayout3_2);
            app.tiempoLabel.Text = 'Frecuencia de Muestreo CSV (Hz):';
            app.FrecuenciaDeMuestreoTxt = uieditfield(app.GridLayout3_2, 'numeric');
            app.FrecuenciaDeMuestreoTxt.ValueDisplayFormat = '%.0f';
            app.FrecuenciaDeMuestreoTxt.Value = 44100;
            app.GridLayout31 = uigridlayout(app.GridLayout26);
            app.PathLabel = uilabel(app.GridLayout31);
            app.PathLabel.Text = 'Dirección de archivo...';
            app.CargarArchivoButton_2 = uibutton(app.GridLayout31, 'push');
            app.CargarArchivoButton_2.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoButton_2Pushed, true);
            app.CargarArchivoButton_2.Text = 'CargarArchivo';
            app.TarjetaTab = uitab(app.SignalParametersTab);
            app.TarjetaTab.Title = 'Tarjeta';
            app.GridLayout16 = uigridlayout(app.TarjetaTab);
            app.GridLayout23 = uigridlayout(app.GridLayout16);
            app.TiemposLabel = uilabel(app.GridLayout23);
            app.TiemposLabel.Text = 'Tiempo (s): ';
            app.TiempoTxt = uieditfield(app.GridLayout23, 'numeric');
            app.TiempoTxt.ValueDisplayFormat = '%.0f';
            app.TiempoTxt.Value = 10;
            app.FrecuenciadeMuestreofsLabel = uilabel(app.GridLayout23);
            app.FrecuenciadeMuestreofsLabel.Text = 'Frecuencia de Muestreo (Hz): ';
            app.FrecuenciademuestreoTxt = uieditfield(app.GridLayout23, 'numeric');
            app.FrecuenciademuestreoTxt.ValueDisplayFormat = '%.0f';
            app.FrecuenciademuestreoTxt.Value = 1000;
            app.ndeinicioLabel = uilabel(app.GridLayout23);
            app.ndeinicioLabel.Text = '''n'' de inicio:';
            app.ndeInicioTxtTarjeta = uieditfield(app.GridLayout23, 'numeric');
            app.ndeInicioTxtTarjeta.ValueDisplayFormat = '%.0f';
            app.PuertosCOMLabel = uilabel(app.GridLayout23);
            app.PuertosCOMLabel.Text = 'Puertos COM:';
            app.PuertosCOMDropDown = uidropdown(app.GridLayout23);
            app.PuertosCOMDropDown.Items = {'COM'};
            app.PuertosCOMDropDown.Value = 'COM';
            app.EntradasLabel = uilabel(app.GridLayout23);
            app.EntradasLabel.Text = 'Entradas:';
            app.EntradasDropDown = uidropdown(app.GridLayout23);
            app.EntradasDropDown.Items = {'A0', 'A1', 'A2', 'A3', 'A4', 'A5'};
            app.EntradasDropDown.Value = 'A0';
            app.GridLayout30 = uigridlayout(app.GridLayout16);
            app.AdquirirSealButton = uibutton(app.GridLayout30, 'state');
            app.AdquirirSealButton.ValueChangedFcn = createCallbackFcn(app, @AdquirirSealButtonValueChanged, true);
            app.AdquirirSealButton.Text = 'Adquirir Señal';
            app.TimeLabel = uilabel(app.GridLayout30);
            app.TimeLabel.Text = 'Time: 00:00 ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crear

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end