classdef EstereoSignals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        GridLayout2      matlab.ui.container.GridLayout
        Btn_CrearSignal  matlab.ui.control.Button
        GridLayout_2     matlab.ui.container.GridLayout
        Cbx_Signal2      matlab.ui.control.DropDown
        Cbx_Signal1      matlab.ui.control.DropDown
    end

    
    properties (Access = public)
         Main; % VentanaPrincipal
         Signals = {};
         time = {};
         Fs = [];
         ni = {};
         FrecuenciaMuestreo = 0;
         Amplitud = 0;
         Desplazamiento = 0;
         frecuenciaAnalogica = 0;

         Signal = [];
         nf = 0;
         n = [];
    end
    
    methods (Access = public)
        
        function cargarDropdown(app, signal,  n, ni, fs)
            
            app.Signals = signal;
            app.time = n;
            app.time = n;
            app.ni = ni;
            app.Fs = fs;
            app.Cbx_Signal1.Items = "Señal 1";
            app.Cbx_Signal2.Items = "Señal 1";
            final = length(signal);
            if(length(signal)>1)
                for i = 1:1:final-1
                    app.Cbx_Signal1.Items = [app.Cbx_Signal1.Items, ['Señal ' num2str(i+1)]];
                    app.Cbx_Signal2.Items = [app.Cbx_Signal2.Items, ['Señal ' num2str(i+1)]];
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Btn_CrearSignal
        function Btn_CrearSignalButtonPushed(app, event)
            Cb1idx = app.Cbx_Signal1.ValueIndex; % Conseguir el valor de la señal actual
            Cb2idx = app.Cbx_Signal2.ValueIndex;
            n1 = app.time{Cb1idx};
            n2 = app.time{Cb2idx};
            Fs1 = app.Fs(Cb1idx);
            Fs2 = app.Fs(Cb2idx);
            S1 = app.Signals{Cb1idx};
            S2 = app.Signals{Cb2idx};
            ni1 = app.ni{Cb1idx};
            ni2 = app.ni{Cb2idx};
            disp("FS1/FS2");
            disp(Fs1);
            disp(Fs2);
            disp("ni1/ni2");
            disp(ni1);
            disp(ni2);
            disp("Tamaño Signal1/Signal2");
            disp(length(S1));
            disp(length(S2));
            disp("Tiempo n1/n2");
            disp(length(n1));
            disp(length(n2));
            disp("Estereo Ceros");

            validar = 1;               % Se verifica la existencia de más canales en alguna de las dos señales
            nCanales = size(S1,2);     % proporciona el numero de columnas en s1 (1 son las filas)
            if(nCanales<2)             % validacion de S1
                nCanales = size(S2,2);
                if(nCanales>1)         % Validacion S2
                    validar = 0;
                    msgbox(['La señal ' num2str(Cb2idx) ' tiene más de 2 canales.'], 'Confirmación', 'help');
                end
            else
                validar = 0;
                msgbox(['La señal ' num2str(Cb1idx) ' tiene más de 2 canales.'], 'Confirmación', 'help');
            end

            if(validar==1)  
                
                if (Fs1>Fs2)

                    app.FrecuenciaMuestreo = Fs1;           % Frecuencia de muestreo

                    % Alineando los instantes iniciales de S1 con respecto a S2
                    [S2_aligned, n_Aligned] = alignsignals(S2, n2 , S1,  n1, 'CommonStart'); 
                    % Remuestreando la frecuencia más baja
                    S2_Remuestreada = resample(S2_aligned, Fs1, Fs2);
    
                    % Se el que menor datos tenga para llenar con ceros
                    if length(S2_Remuestreada)>length(S1)
    
                        Signal1 = [S1; zeros(1, length(S2_Remuestreada) - length(S1))];
                        Signal2 = S2_Remuestreada;
    
                    elseif length(S2_Remuestreada)<length(S1)
    
                        Signal2 = [S2_Remuestreada; zeros(1, length(S1) - length(S2_Remuestreada))];
                        Signal1 = S1;
    
                    else
                        
                        Signal2 = S2_Remuestreada;
                        Signal1 = S1;
    
                    end % Fin de ajuste tiempos n (length)
                elseif (Fs2>Fs1)

                    app.FrecuenciaMuestreo = Fs2;           % Frecuencia de muestreo

                    % Alineando los instantes iniciales de S1 con respecto a S2
                    [S1_aligned, n_Aligned] = alignsignals(S1, n1, S2, n2, 'CommonStart'); 
                    % Remuestreando la frecuencia más baja
                    S1_Remuestreada = resample(S1_aligned, Fs2, Fs1);
    
                    % Se el que menor datos tenga para llenar con ceros
                    if length(S1_Remuestreada)>length(S2)
    
                        Signal2 = [S2; zeros(1, length(S1_Remuestreada) - length(S2))];
                        Signal1 = S1_Remuestreada;
    
                    elseif length(S1_Remuestreada)<length(S2)
    
                        Signal1 = [S1_Remuestreada; zeros(1, length(S2) - length(S1_Remuestreada))];
                        Signal2 = S2;
    
                    else
                        
                        Signal1 = S1_Remuestreada;
                        Signal2 = S2;
    
                    end% Fin de ajuste tiempos n (length)
    
                else % Frecuencias Fs1 = Fs2
                    
                    app.FrecuenciaMuestreo = Fs1;           % Frecuencia de muestreo

                    if length(S1)>length(S2)
                        %Se buscan los ceros de relleno
                        
                        ceros = zeros(1, (length(S1)-length(S2)))';
                        disp(length(ceros));
                        % Buscando el centro para dezplazar
                        if ni1>ni2
                            k = ni2 - ni1;
        
                            Signal1 = [circshift(S1, k); ceros];% Desplaza k veces a S1
                            Signal2 = S2;
                            
        
                        elseif ni2>ni1
                            k = ni1 - ni2;
    
                            Signal2 = [circshift(S2, k); ceros];
                            Signal1 = S1;
        
                        else
                            Signal1 = S1;
                            Signal2 = [S2; ceros];
                            
        
                        end % Fin de verificación de ni
                    elseif length(S2)>length(S1)
                        %Se buscan los ceros de relleno
                        ceros = zeros(1, (length(S2)-length(S1)))';
                        
                        % Buscando el centro para dezplazar
                        if ni1>ni2
                            k = ni2 - ni1;
        
                            Signal1 = circshift(S1, k);
                            Signal2 = [S2; ceros];
        
                        elseif ni2>ni1
                            k = ni1 - ni2;
        
                            Signal2 = circshift(app.S2, k);
                            Signal1 = [S1; ceros];
        
                        else
                            disp((length(S2)-length(S1)));
                            disp(S1);
                            Signal1 = [S1; ceros];
                            Signal2 = S2;
        
                        end
                    else
                         
                        if ni1>ni2
                            k = ni2 - ni1;
        
                            Signal1 = circshift(S1, k);
                            Signal2 = S2;
        
                        elseif ni2>ni1
                            k = ni1 - ni2;
       
                            Signal2 = circshift(S2, k);
                            Signal1 = S1;
        
                        else
                            
                            Signal1 = S1;
                            Signal2 = S2;
        
                        end
                    end % Fin de ajuste tiempos n (length)
                end %Fin de frecuencias
                
                app.Signal = [Signal1  Signal2];
                
    
                app.ni = min(app.time{Cb1idx}(1), app.time{Cb2idx}(1));   % nuevo instante inicial
                app.nf = max(app.time{Cb1idx}(end), app.time{Cb2idx}(end)); % nuevo instante final
    
                app.n = app.ni : app.nf;
    
                %dt = app.n(2) - app.n(1);  % Paso de tiempo
                %app.FrecuenciaMuestreo = 1 / dt;           % Frecuencia de muestreo
                
                app.Amplitud = (max(app.Signal) - min(app.Signal)) / 2;
                app.Desplazamiento = mean(app.Signal);  % Promedio (DC offset)
                
                N = length(app.Signal);
                Y = abs(fft(app.Signal));
                f = (0:N-1)*(app.FrecuenciaMuestreo/N);     % Vector de frecuencias
                [~, idx] = max(Y(1:floor(N/2)));            % Pico en la mitad baja
                app.frecuenciaAnalogica = f(idx);     % Frecuencia dominante
                
                
                uiresume(app.UIFigure);
           end %Validación canales 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_2 = uigridlayout(app.GridLayout);
            app.Cbx_Signal1 = uidropdown(app.GridLayout_2);
            app.Cbx_Signal1.Items = {'Signals', ''};
            app.Cbx_Signal1.Value = 'Signals';
            app.Cbx_Signal2 = uidropdown(app.GridLayout_2);
            app.Cbx_Signal2.Items = {'Signals'};
            app.Cbx_Signal2.Value = 'Signals';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Btn_CrearSignal = uibutton(app.GridLayout2, 'push');
            app.Btn_CrearSignal.ButtonPushedFcn = createCallbackFcn(app, @Btn_CrearSignalButtonPushed, true);
            app.Btn_CrearSignal.Text = 'Crear Señal Estereo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EstereoSignals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end