classdef P1_DSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputSignalsTab                 matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        AudioTab                        matlab.ui.container.Tab
        FilePanel                       matlab.ui.container.Panel
        StopresumeButton_2              matlab.ui.control.Button
        UploadButton                    matlab.ui.control.Button
        VolumeSlider_2                  matlab.ui.control.Slider
        VolumeSlider_2Label             matlab.ui.control.Label
        PlayButton_2                    matlab.ui.control.Button
        StopButton_3                    matlab.ui.control.Button
        AudiorecorderPanel              matlab.ui.container.Panel
        SampleRateHzRecord              matlab.ui.control.Spinner
        SampleRateHzSpinnerLabel        matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        StopresumeButton                matlab.ui.control.Button
        PlayButton_5                    matlab.ui.control.Button
        VolumeSlider                    matlab.ui.control.Slider
        VolumeSliderLabel               matlab.ui.control.Label
        ModeSwitch                      matlab.ui.control.RockerSwitch
        ModeSwitchLabel                 matlab.ui.control.Label
        StopButton_2                    matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        SinteticSignalsTab              matlab.ui.container.Tab
        PlottedSignalPanel              matlab.ui.container.Panel
        UIAxes_scs                      matlab.ui.control.UIAxes
        SignalsPanel                    matlab.ui.container.Panel
        Samplingfrequency               matlab.ui.control.Spinner
        SamplingfrequencySpinnerLabel   matlab.ui.control.Label
        PlotSignalButton                matlab.ui.control.Button
        Time                            matlab.ui.control.Spinner
        TimesSpinnerLabel               matlab.ui.control.Label
        Phase                           matlab.ui.control.Spinner
        PhaseSpinnerLabel               matlab.ui.control.Label
        FinalFrequency                  matlab.ui.control.Spinner
        FinalFrequencyHzSpinnerLabel    matlab.ui.control.Label
        InitialFrequency                matlab.ui.control.Spinner
        InitialFrequencyHzSpinnerLabel  matlab.ui.control.Label
        Amplitude                       matlab.ui.control.Spinner
        AmplitudeSpinnerLabel           matlab.ui.control.Label
        TypeofsignalDropDown            matlab.ui.control.DropDown
        TypeofsignalDropDownLabel       matlab.ui.control.Label
        PauseResumeButton               matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        DataAcquisitionTab              matlab.ui.container.Tab
        Fs_HzEditFieldLabel             matlab.ui.control.Label
        Fs_HzEditField                  matlab.ui.control.NumericEditField
        DetectportsButton               matlab.ui.control.Button
        SecondsLabel                    matlab.ui.control.Label
        StatusLabel_2                   matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        AcquisitionTimeEditField        matlab.ui.control.NumericEditField
        AcquisitionTimeLabel            matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        BaudRateDropDown                matlab.ui.control.DropDown
        BaudRateDropDownLabel           matlab.ui.control.Label
        PortsDropDown                   matlab.ui.control.DropDown
        PortsDropDownLabel              matlab.ui.control.Label
        RestartButton                   matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        ProcessingTab                   matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        OperationTab                    matlab.ui.container.Tab
        StopButton_6                    matlab.ui.control.Button
        PlayButton_6                    matlab.ui.control.Button
        InputSignalPanel_3              matlab.ui.container.Panel
        MonotoStereoButton              matlab.ui.control.Button
        InvertSignalButton              matlab.ui.control.Button
        StereotomonoButton              matlab.ui.control.Button
        SelectSignalDropDown            matlab.ui.control.DropDown
        SelectSignalDropDownLabel       matlab.ui.control.Label
        InputSignalPanel_2              matlab.ui.container.Panel
        InputSignal1DropDown            matlab.ui.control.DropDown
        InputSignal1DropDownLabel       matlab.ui.control.Label
        InputSignal2DropDown            matlab.ui.control.DropDown
        InputSignal2DropDownLabel       matlab.ui.control.Label
        TimeEditField_2                 matlab.ui.control.NumericEditField
        TimeEditField_2Label            matlab.ui.control.Label
        FsEditField_2Label              matlab.ui.control.Label
        FsEditField_2                   matlab.ui.control.NumericEditField
        TimeEditField                   matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        FsEditFieldLabel                matlab.ui.control.Label
        MultButton                      matlab.ui.control.Button
        SumButton                       matlab.ui.control.Button
        UIAxes_scs_3                    matlab.ui.control.UIAxes
        UIAxes_scs_4                    matlab.ui.control.UIAxes
        UIAxes_scs_2                    matlab.ui.control.UIAxes
        EchoTab                         matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        k3                              matlab.ui.control.Spinner
        k3msLabel                       matlab.ui.control.Label
        A3                              matlab.ui.control.Spinner
        A3Label                         matlab.ui.control.Label
        A1                              matlab.ui.control.Spinner
        A2                              matlab.ui.control.Spinner
        k1                              matlab.ui.control.Spinner
        k2                              matlab.ui.control.Spinner
        k2sLabel                        matlab.ui.control.Label
        k1sLabel                        matlab.ui.control.Label
        A1Label                         matlab.ui.control.Label
        A2SpinnerLabel                  matlab.ui.control.Label
        InputSignalDropDown_2           matlab.ui.control.DropDown
        InputSignalDropDown_2Label      matlab.ui.control.Label
        Stop_echo                       matlab.ui.control.Button
        Play_echo                       matlab.ui.control.Button
        Plot_echo                       matlab.ui.control.Button
        UIAxes_scs_16                   matlab.ui.control.UIAxes
        UIAxes_scs_15                   matlab.ui.control.UIAxes
        FourierTab                      matlab.ui.container.Tab
        Panel2_2                        matlab.ui.container.Panel
        ApplyFilterButton               matlab.ui.control.Button
        SelectsignalDropDown_2          matlab.ui.control.DropDown
        SelectsignalDropDown_2Label     matlab.ui.control.Label
        Panel2                          matlab.ui.container.Panel
        SelectsignalDropDown            matlab.ui.control.DropDown
        SelectsignalDropDownLabel       matlab.ui.control.Label
        SpectogramButton                matlab.ui.control.Button
        PhaseButton                     matlab.ui.control.Button
        MagnitudeButton                 matlab.ui.control.Button
        UIAxes_scs_14                   matlab.ui.control.UIAxes
        UIAxes_scs_13                   matlab.ui.control.UIAxes
        UIAxes_scs_12                   matlab.ui.control.UIAxes
        UIAxes_scs_11                   matlab.ui.control.UIAxes
        FilteringTab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        FilteredSignalPanel             matlab.ui.container.Panel
        StopButton_5                    matlab.ui.control.Button
        PlayButton_4                    matlab.ui.control.Button
        InputSignalPanel                matlab.ui.container.Panel
        StopButton_4                    matlab.ui.control.Button
        PlayButton_3                    matlab.ui.control.Button
        SignalFilteringPanel            matlab.ui.container.Panel
        SelectfilterDropDown            matlab.ui.control.DropDown
        SelectfilterDropDownLabel       matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        FilterDropDownLabel             matlab.ui.control.Label
        InputSignal1DropDown_2          matlab.ui.control.DropDown
        InputSignal1DropDown_2Label     matlab.ui.control.Label
        WindowSize                      matlab.ui.control.Spinner
        WindowSizeSpinnerLabel          matlab.ui.control.Label
        IIRakbkButton_2                 matlab.ui.control.Button
        FIRhnButton_2                   matlab.ui.control.Button
        UIAxes_scs_18                   matlab.ui.control.UIAxes
        UIAxes_scs_17                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        result          % almacena la señal seleccionada 
        tiempo          % almacena el tiempo correspondiente
        playSignal1     % almacena la reproduccion la señal sintetica seleccionada
        RepAudioObj     % objeto que almacena la reproduccion por micrfono
        getAudio        % almacena la grabación de RepAudioObj en un arreglo numerico
        playRecAudio    % reproducción de RepAudioObj
        AudioFile       % almacena la grabación de un archivo externo
        AudioFile_og
        AudioPath       % almacena la dirección del archivo de grabación
        playFileAudio   % reproducción del archivo de audio externo
        Fs              % Almacena la frecuencia de muestreo del archivo de audio externo necesaria para su reproducción
        N
        Y
        f

        audioLeft
        audioRightDelayed
        timeSec

        value_volumen1
        value_volumen2
       

        %Almacena cada una de las señales sinteticas generadas
        signal_sine
        signal_cosine
        signal_step
        signal_ramp
        signal_chirp
        signal_square
        signal_sawtooth

        inputsvalues
        signal1op
        signal2op
        fs_signal1op
        fs_signal2op

        selectsignalop_s
        selectsignalop_t
        selectsignalop_fs

        signal_stored1
        time_stored1

        signal_stored2
        time_stored2

        SelectedSignal
        FilteredSignal

        signal_storedEcho
        signal_echo
        fs_storedecho
        playerecho


        signal_storedFilter
        time_storedFilter


        filtered
        filtradaFs

        tiempo_filtered


        valor_usuario 
        valor_usuarioak 
        valor_usuariobk 

        player

       %VARIABLES PARA ADQUISICION DE DATOS
       serialObj               % Objeto para el puerto serial
       tiempo_inicial          % Tiempo de inicio
       tiempos                 % Arreglo para almacenar los tiempos transcurridos
       temperaturas            % Arreglo para almacenar las temperaturas
       running = false         % Variable booleana para controlar la adquisición
        
       
       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

       signal_result
       fs_result
       signal_res

       
    end
    
    
    methods (Access = private)

        function results = convolucion(app,x,h,bloqsize,fs)
            ni_x=0;
            Lx= length(x);
            nf_x=Lx+ni_x-1;
            
            ni_h=0;
            Lh=length(h);
            nf_h=Lh+ni_h-1;
            
            Ly=Lx+Lh-1;
            ni_y=ni_x+ni_h;
            nf_y=nf_x+nf_h;
            y=zeros(1,Ly);
            
            Lxb = bloqsize;
            
            % Tamaño salida de bloque yb(n)
            Lyb=Lxb+Lh-1 ;
            % Primer bloque
            xb=x(1 : Lxb);
            disp(size(xb)); % Verifica las dimensiones de xb
            disp(size(h));  % Verifica las dimensiones de h
            disp(['Longitud de x: ', size(xb)]);
            disp(['Longitud de x: ', size(h)]);
            
            y(1 : Lyb)=conv(h,xb);

            % Número de bloques 
            Nb= floor(Lx/Lxb);
            
            for k=2: Nb
                t= 0:1/fs:(k-1)/fs;%vector de tiempos para graficar
                xb=x(1+(k-1)* Lxb :  Lxb*k ) ;
                yb=conv(h,xb);   
                y( 1+(k-1)*Lxb : (k-1)*Lxb+ Lyb)= y( 1+(k-1)*Lxb : (k-1)*Lxb+ Lyb) + yb;


                % Visualizar salida por bloques
                stem(app.UIAxes_scs_18,y); 
                xlim(app.UIAxes_scs_18, [0 length(x)]);
                %xlabel('n'); title('y(n) por bloques'); ylim([-6, 7])
                pause(0.05)  
            end 
            
            % Calcular la convolución para el bloque incompleto y adicionarlo a y(n)     
            Lxr= mod(Lx,Lxb); 
            
            % verificar si la longitud de x es múltiplo de Nb
            if Lxr ~= 0 % Si hay un bloque incomplet
                xbr=x(1+ Nb*Lxb : Lx) ;
                ybr=conv(h,xbr);
                y( 1+Nb*Lxb: Ly)= y( 1+Nb*Lxb: Ly) + ybr;   
                stem(app.UIAxes_scs_18,y); 
            end 
            results=y;
            app.filtradaFs= fs;
        end


        function results = ec_diference(app,x,a,b, fs)
 
            N=length(a);     % Orden N 
            M=length(b)-1;   % Orden M
            
            
            % Señal de entrada x(n)
            Lx=length(x);
            
            y=zeros(1,Lx); % Asignar memoria a y(n)
            v=zeros(1,Lx);  % Asignar memoria a v(n)
            
            for  n=0:Lx-1
                
                m=n+1;
                
                % Calcular v(n) 
                temp_v=0;
                
                for k=0:M
                    
                    kk=k+1;
                    ind= n-k+1;
                
                    if  ind >= 1 && ind < Lx-1
                        %n_k= m-kk+1;  % ajuste del indice de x(n-k) para evitar salir rango
                        temp_v=temp_v+b(kk)*x(ind);
                    end
                end
                v(m)=temp_v;
                
                temp_y=0;
                for k=1:N
                   
                    ind= n-k+1;
                
                    if  ind >= 1 && ind < N
                        %n_k= m-kk+1;  % ajuste del indice de x(n-k) para evitar salir rango
                        temp_y=temp_y+a(k)*y(ind);
                    end
            
                end
                y(m)=temp_y+v(m); % Respuesta total
                
            end   
        n=0:Lx-1;
        stem(app.UIAxes_scs_18,n,y)   
            
        results=y;    
        app.filtradaFs= fs;
        end


 %%%%%%FUNCION PARA ADQUISICION DE DATOS

function startAcquisition(app)

 app.StatusLabel_2.Text = 'Status';  

 % Verificar que el tiempo de adquisición es mayor a 0
if app.AcquisitionTimeEditField.Value <= 0
    disp('Error: El tiempo de adquisición debe ser mayor a 0.');
    return;  % No comenzar la adquisición si el valor no es válido
end

% Si las condiciones se cumplen, iniciar la adquisición de datos
try
    % Intentar conectar al puerto serial solo si no hay conexión
    if isempty(app.serialObj)
        % Obtener el valor seleccionado del puerto
        selectedPort = app.PortsDropDown.Value;   % Valor del puerto seleccionado en el Drop Down
    
        % Obtener el valor seleccionado del baud rate
        selectedBaudRate = str2double(app.BaudRateDropDown.Value);  % Valor de la tasa de baudios seleccionada

        % Establecer la conexión al puerto serial
        app.serialObj = serialport(selectedPort, selectedBaudRate);   % Conexión al puerto serial
    end

    % Restablecer variables de adquisición
    app.tiempo_inicial = datetime('now');        % Guardar el tiempo inicial
    app.tiempos = [];                            % Inicializar arreglo para tiempos
    app.temperaturas = [];                       % Inicializar arreglo para temperaturas
    app.running = true;                          % Activar adquisición de datos

    % Borrar cualquier gráfica anterior
    cla(app.UIAxes2);

    % Establecer cuántas lecturas iniciales ignorar
    numLecturasIgnoradas = 5;
    lecturasIgnoradas = 0;

    % Bucle de adquisición de datos
    while app.running
        if app.serialObj.NumBytesAvailable > 0
            % Leer y procesar datos
            msg = strtrim(readline(app.serialObj));
            temperatura = str2double(msg);
            if isnan(temperatura)
                continue;
            end

            % Ignorar las primeras 'n' lecturas
            if lecturasIgnoradas < numLecturasIgnoradas
                lecturasIgnoradas = lecturasIgnoradas + 1;
                continue;  % Saltar a la siguiente iteración
            end

            % Calcular el tiempo transcurrido (ajustando el tiempo por las lecturas ignoradas)
            tiempo_transcurrido = seconds(datetime('now') - app.tiempo_inicial);

            % Detener la adquisición si el tiempo transcurrido supera el tiempo de adquisición
            % Restar el tiempo usado en las lecturas ignoradas
            tiempoReal = tiempo_transcurrido - (lecturasIgnoradas * 0.1);  % Ajuste por el tiempo ignorado
            if tiempoReal >= app.AcquisitionTimeEditField.Value
                disp('Tiempo de adquisición completado.');
                app.running = false;
                % Cerrar el puerto serial si está abierto
     
                clear app.serialObj;
                app.serialObj = [];                     
                break;
            end

            % Almacenar tiempo y temperatura
            app.tiempos(end+1) = tiempoReal;
            app.temperaturas(end+1) = temperatura;

            % Actualizar gráfica
            x_min = max(0, tiempoReal - app.AcquisitionTimeEditField.Value);
            x_max = tiempoReal;

            if x_max > x_min
                xlim(app.UIAxes2, [x_min, x_max]);
            end

            % Verificación para ylim
            if ~isempty(app.temperaturas)
                min_temp = min(app.temperaturas) - 5;
                max_temp = max(app.temperaturas) + 5;

                % Asegurarse de que los límites son crecientes
                if max_temp > min_temp
                    ylim(app.UIAxes2, [min_temp, max_temp]);
                end
            end

            % Dibujar gráfica
            plot(app.UIAxes2, app.tiempos, app.temperaturas, '-');
            drawnow;
        end
        %pause(0.1);
        Fs_HzEditFieldValueChanged(app);
    end

catch ME
    disp(['Error al conectar o durante la adquisición de datos: ', ME.message]);
     app.StatusLabel_2.Text = 'Error BaudRate';
     stopAcquisition(app); 
end

end


function stopAcquisition(app)
    % Detener la adquisición de datos
    app.running = false;

    % Cerrar el puerto serial si está abierto
    if ~isempty(app.serialObj)
        clear app.serialObj;
        app.serialObj = [];
    end

    % Limpiar la gráfica
    cla(app.UIAxes2);

    % Limpiar los datos almacenados
    app.tiempos = [];
    app.temperaturas = [];

    % Restablecer el valor del campo de tiempo de adquisición
    app.AcquisitionTimeEditField.Value = 0;
   

        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
             stopAcquisition(app); 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            startAcquisition(app);  
        end

        % Value changed function: AcquisitionTimeEditField
        function AcquisitionTimeEditFieldValueChanged(app, event)
            value = app.AcquisitionTimeEditField.Value;
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
          % Verificar si hay datos que exportar
    if isempty(app.tiempos) || isempty(app.temperaturas)
        disp('No hay datos para exportar.');
        return;
    end

    % Limitar el número de decimales a dos para el tiempo y las temperaturas
    tiempos_redondeados = round(app.tiempos, 2);
    temperaturas_redondeadas = round(app.temperaturas, 2);
    
    % Convertir a string y reemplazar los puntos decimales por comas
    tiempos_str = strrep(string(tiempos_redondeados(:)), '.', ',');
    temperaturas_str = strrep(string(temperaturas_redondeadas(:)), '.', ',');
    
    % Crear una tabla con los datos formateados
    data = table(tiempos_str, temperaturas_str, 'VariableNames', {'Tiempo', 'Temperatura'});
    
    % Especificar la ruta y el nombre del archivo
    folderPath = 'C:\Users\Cell Systems\Desktop\DATOS_SENSOR\';
    fileName = ['Datos_' datestr(now, 'yyyymmdd_HHMMSS') '.csv'];  % Nombre del archivo con fecha y hora
    fullFileName = fullfile(folderPath, fileName);

    % Guardar en formato CSV con separador de punto y coma
    try
        writetable(data, fullFileName, 'Delimiter', ';');  % Cambiar el delimitador a punto y coma
        disp(['Datos exportados exitosamente a: ', fullFileName]);
    catch ME
        disp(['Error al exportar los datos: ', ME.message]);
    end
        
        end

        % Button pushed function: PlotSignalButton
        function generateButtonPushed(app, event)
            
            TypeofsignalDropDownValueChanged(app, event);

            plot(app.UIAxes_scs, app.tiempo, app.result);
            xlabel(app.UIAxes_scs, 'Time (s)');
            ylabel(app.UIAxes_scs, 'Amplitude');
            title(app.UIAxes_scs, 'Signal');
            grid(app.UIAxes_scs, 'on');

        end

        % Value changed function: TypeofsignalDropDown
        function TypeofsignalDropDownValueChanged(app, event)
            value = app.TypeofsignalDropDown.Value;
            amplitude= app.Amplitude.Value;
            frecuency= app.InitialFrequency.Value;
            finalfrecuency= app.FinalFrequency.Value;
            time= app.Time.Value;
            samplingfrequency= app.Samplingfrequency.Value;
            app.tiempo= 0:1/samplingfrequency:time;
            phase= app.Phase.Value;

            if value== 1
                app.result = amplitude*sin((2*pi*frecuency*app.tiempo)+(phase*pi/180));
                app.signal_sine = [app.result; app.tiempo];
                app.inputsvalues(1,:) = [samplingfrequency, time];
            elseif value ==2
                app.result = amplitude*cos((2*pi*frecuency*app.tiempo)+(phase*pi/180));
                app.signal_cosine = [app.result; app.tiempo];
                app.inputsvalues(2,:) = [samplingfrequency, time];
            elseif value ==3
                app.result = amplitude * ones(size(app.tiempo));
                app.signal_step = [app.result; app.tiempo];
                app.inputsvalues(3,:) = [samplingfrequency, time];
            elseif  value == 4
                app.result = amplitude * app.tiempo;
                app.signal_ramp = [app.result; app.tiempo];
                app.inputsvalues(4,:) = [samplingfrequency, time];
            elseif value== 5
                fase_actual = 2 * pi * app.tiempo .* ((finalfrecuency - frecuency) / 2 * app.tiempo + frecuency);
                app.result = amplitude * sin(fase_actual);
                app.signal_chirp = [app.result; app.tiempo];
                app.inputsvalues(5,:) = [samplingfrequency, time];
            elseif value== 6
                app.result = amplitude * sign(sin(2 * pi * frecuency * app.tiempo));
                app.signal_square = [app.result; app.tiempo];
                app.inputsvalues(6,:) = [samplingfrequency, time];
            elseif value== 7
                app.result = amplitude * (2 * (app.tiempo / (1/frecuency) - floor(app.tiempo / (1/frecuency) + 1/2)));
                app.signal_sawtooth = [app.result; app.tiempo];
                app.inputsvalues(7,:) = [samplingfrequency, time];
            end
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            samplingfrequency= app.Samplingfrequency.Value;
            app.playSignal1=audioplayer(app.result,samplingfrequency);
            play(app.playSignal1);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.playSignal1);
        end

        % Button pushed function: PauseResumeButton
        function PauseResumeButtonPushed(app, event)
            if isplaying(app.playSignal1) 
                pause(app.playSignal1)
            else
                resume(app.playSignal1)
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            SampleRateRecord = app.SampleRateHzRecord.Value;
            if strcmp(app.ModeSwitch.Value,'Mono')
                app.RepAudioObj = audiorecorder(SampleRateRecord,16,1,-1);
            else
                app.RepAudioObj = audiorecorder(SampleRateRecord,16,2,-1);
            end
            record(app.RepAudioObj);
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            stop(app.RepAudioObj);
            getAudio= getaudiodata(app.RepAudioObj);
            value_volumen1 = app.VolumeSlider.Value;
            app.getAudio = value_volumen1 * getAudio;
            numSamples = length(app.getAudio);
            app.timeSec = (0:numSamples-1) / app.SampleRateHzRecord.Value;

            if strcmp(app.ModeSwitch.Value,'Mono')
                plot(app.UIAxes,app.timeSec, app.getAudio);
            else
                delaySamples = round(app.SampleRateHzRecord.Value * 0.1);
                audioRight = app.getAudio(:,2);
                audioLeft = app.getAudio(:,1);
                audioRightDelayed = [zeros(delaySamples, 1); audioRight(1:end-delaySamples)]; 

                minLength = min(length(audioLeft), length(audioRightDelayed));
                app.audioLeft = audioLeft(1:minLength);
                app.audioRightDelayed = audioRightDelayed(1:minLength);

                app.getAudio = [app.audioLeft, app.audioRightDelayed];
                plot(app.UIAxes, app.timeSec, app.audioLeft); % Canal izquierdo
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.timeSec, app.audioRightDelayed); % Canal derecho
                hold(app.UIAxes, 'off');
            end
            
        end

        % Button pushed function: PlayButton_5
        function PlayButton_5Pushed(app, event)
            StopButton_2Pushed(app, event)
            audio1 = app.getAudio;
            
            app.playRecAudio = audioplayer(audio1, app.SampleRateHzRecord.Value);
            play(app.playRecAudio);

        end

        % Button pushed function: StopresumeButton
        function StopresumeButtonPushed(app, event)
            if isplaying(app.playRecAudio) 
                pause(app.playRecAudio)
            else
                resume(app.playRecAudio)
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file,path] = uigetfile( {'*.m4a', 'Archivos de audio (*.m4a)'},'Ingrese el archivo de audio a procesar');
            [AudioFilem,app.Fs] = audioread(fullfile(path,file));
            
            app.AudioFile_og = mean(AudioFilem, 2);
            value_volumen2 = app.VolumeSlider_2.Value;
            app.AudioFile = value_volumen2 * app.AudioFile_og;
            plot(app.UIAxes_2,app.AudioFile);
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            value_volumen2 = app.VolumeSlider_2.Value;
            app.AudioFile = value_volumen2 * app.AudioFile_og;
            plot(app.UIAxes_2,app.AudioFile);

            app.playFileAudio = audioplayer(app.AudioFile,app.Fs);
            play(app.playFileAudio);
        end

        % Button pushed function: StopresumeButton_2
        function StopresumeButton_2Pushed(app, event)
            if isplaying(app.playFileAudio) 
                pause(app.playFileAudio)
            else
                resume(app.playFileAudio)
            end
        end

        % Button pushed function: StopButton_3
        function StopButton_3Pushed(app, event)
            stop(app.playFileAudio);
        end

        % Value changed function: InputSignal1DropDown
        function InputSignal1DropDownValueChanged(app, event)
            value = str2double(app.InputSignal1DropDown.Value);
            TypeofsignalDropDownValueChanged(app, event);

            res = [];
            time = [];

            if value== 1
            elseif value==2
                res = app.signal_sine(1, :);
                time = app.signal_sine(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value==3
                res = app.signal_cosine(1, :); 
                time = app.signal_cosine(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value ==4
                res = app.signal_step(1, :);
                time = app.signal_step(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif  value == 5
                res = app.signal_ramp(1, :);
                time = app.signal_ramp(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value== 6
                res = app.signal_chirp(1, :);
                time = app.signal_chirp(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value== 7
                res = app.signal_square(1, :);
                time = app.signal_square(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value== 8
                res = app.signal_sawtooth(1, :);
                time = app.signal_sawtooth(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value==9
                numSamples = length(app.getAudio);  % Número de muestras de audio
                time = (0:numSamples-1) / app.SampleRateHzRecord.Value; % Crear vector de tiempo basado en la frecuencia de muestreo
                fs = app.SampleRateHzRecord.Value;
                t = time(end);
                if strcmp(app.ModeSwitch.Value, 'Mono')
                    res = app.getAudio(:, 1)';  % Graficar audio mono
                else
                    % Audio estéreo, canal izquierdo (o puedes usar ambos canales)
                     res_left = app.audioLeft;  % Canal izquierdo
                     res_right = app.audioRightDelayed; % Canal derecho (si es necesario graficarlo también)
                     time = app.timeSec;

                    plot(app.UIAxes_scs_2, time, res_left);
                    xlabel(app.UIAxes_scs_2, 'Time (s)');
                    ylabel(app.UIAxes_scs_2, 'Amplitude');
                    title(app.UIAxes_scs_2, 'Signal 1');
                    grid(app.UIAxes_scs_2, 'on');
        
                    plot(app.UIAxes_scs_3, time, res_right);
                    xlabel(app.UIAxes_scs_3, 'Time (s)');
                    ylabel(app.UIAxes_scs_3, 'Amplitude');
                    title(app.UIAxes_scs_3, 'Signal 2');
                    grid(app.UIAxes_scs_3, 'on');

                    t = time(end);
                    app.signal1op = [res_left'; time];
                    app.fs_signal1op = fs;
                    app.FsEditField.Value = fs;
                    app.TimeEditField.Value = t;

                    app.signal2op = [res_right'; time];
                    app.fs_signal2op = fs;
                    app.FsEditField_2.Value = fs;
                    app.TimeEditField_2.Value = t;

                    Audio = [res_left, res_right];
                    app.signal_result = Audio;
                    app.fs_result = fs;

                    plot(app.UIAxes_scs_4, time, res_left, 'b', 'DisplayName', 'Canal Izquierdo'); % Canal izquierdo en azul
                    hold(app.UIAxes_scs_4, 'on'); % Mantener el gráfico para la siguiente señal
                    plot(app.UIAxes_scs_4, time, res_right, 'r', 'DisplayName', 'Canal Derecho'); % Canal derecho en rojo
                    hold(app.UIAxes_scs_4, 'off'); % Liberar el gráfico
                    grid(app.UIAxes_scs_4, 'on');
                    xlabel(app.UIAxes_scs_4, 'Time (s)');
                    ylabel(app.UIAxes_scs_4, 'Amplitude');
                    title(app.UIAxes_scs_4, 'Signal Result');
                    
                    return;
                end;
            elseif value == 10
                res = app.AudioFile'; % El archivo de audio que subiste
                numSamples = length(app.AudioFile);
                time = (0:numSamples-1) / app.Fs; % Vector de tiempo basado en la frecuencia de muestreo del archivo
                fs = app.Fs;
                t = time(end);
            elseif value == 11
                time = app.tiempos;             
                res = app.temperaturas;
                fs = app.Fs_HzEditField.Value;
                t = app.temperaturas(end);
            end

            app.signal_stored1 = res;
            app.time_stored1=time;

            disp(['Longitud de res: ', num2str(length(res))]);
            disp(['Longitud de time: ', num2str(length(time))]);

            % Graficar si es mono o cualquier otra señal
            plot(app.UIAxes_scs_2, time, res);
            xlabel(app.UIAxes_scs_2, 'Time (s)');
            ylabel(app.UIAxes_scs_2, 'Amplitude');
            title(app.UIAxes_scs_2, 'Signal 1');
            grid(app.UIAxes_scs_2, 'on');
            
            app.FsEditField.Value = fs;
            app.TimeEditField.Value = t;
            
            app.signal1op = [res; time];
            app.fs_signal1op = fs;
      
        end

        % Value changed function: InputSignal2DropDown
        function InputSignal2DropDownValueChanged(app, event)
            value = str2double(app.InputSignal2DropDown.Value);
            TypeofsignalDropDownValueChanged(app, event);

            res = [];
            time = [];

            if value== 1
            elseif value==2
                res = app.signal_sine(1, :);
                time = app.signal_sine(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value==3
                res = app.signal_cosine(1, :); 
                time = app.signal_cosine(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value ==4
                res = app.signal_step(1, :);
                time = app.signal_step(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif  value == 5
                res = app.signal_ramp(1, :);
                time = app.signal_ramp(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value== 6
                res = app.signal_chirp(1, :);
                time = app.signal_chirp(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value== 7
                res = app.signal_square(1, :);
                time = app.signal_square(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value== 8
                res = app.signal_sawtooth(1, :);
                time = app.signal_sawtooth(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value ==9
                numSamples = length(app.getAudio);  % Número de muestras de audio
                time = (0:numSamples-1) / app.SampleRateHzRecord.Value; % Crear vector de tiempo basado en la frecuencia de muestreo
                fs = app.SampleRateHzRecord.Value;
                t = time(end);
                if strcmp(app.ModeSwitch.Value, 'Mono')
                    res = app.getAudio(:, 1)';  % Graficar audio mono
                else
                    % Audio estéreo, canal izquierdo (o puedes usar ambos canales)
                     res_left = app.audioLeft;  % Canal izquierdo
                     res_right = app.audioRightDelayed; % Canal derecho (si es necesario graficarlo también)
                     time = app.timeSec;

                    plot(app.UIAxes_scs_2, time, res_left);
                    xlabel(app.UIAxes_scs_2, 'Time (s)');
                    ylabel(app.UIAxes_scs_2, 'Amplitude');
                    title(app.UIAxes_scs_2, 'Signal 1');
                    grid(app.UIAxes_scs_2, 'on');
        
                    plot(app.UIAxes_scs_3, time, res_right);
                    xlabel(app.UIAxes_scs_3, 'Time (s)');
                    ylabel(app.UIAxes_scs_3, 'Amplitude');
                    title(app.UIAxes_scs_3, 'Signal 2');
                    grid(app.UIAxes_scs_3, 'on');

                    t = time(end);
                    app.signal1op = [res_left'; time];
                    app.fs_signal1op = fs;
                    app.FsEditField.Value = fs;
                    app.TimeEditField.Value = t;

                    app.signal2op = [res_right'; time];
                    app.fs_signal2op = fs;
                    app.FsEditField_2.Value = fs;
                    app.TimeEditField_2.Value = t;

                    Audio = [res_left, res_right];
                    app.signal_result = Audio;
                    app.fs_result = fs;

                    plot(app.UIAxes_scs_4, time, res_left, 'b', 'DisplayName', 'Canal Izquierdo'); % Canal izquierdo en azul
                    hold(app.UIAxes_scs_4, 'on'); % Mantener el gráfico para la siguiente señal
                    plot(app.UIAxes_scs_4, time, res_right, 'r', 'DisplayName', 'Canal Derecho'); % Canal derecho en rojo
                    hold(app.UIAxes_scs_4, 'off'); % Liberar el gráfico
                    grid(app.UIAxes_scs_4, 'on');
                    xlabel(app.UIAxes_scs_4, 'Time (s)');
                    ylabel(app.UIAxes_scs_4, 'Amplitude');
                    title(app.UIAxes_scs_4, 'Signal Result');
                    
                    return;
                end
            elseif value ==10
                res = app.AudioFile'; % Reutilizamos el archivo de audio cargado
                numSamples = length(app.AudioFile);
                time = (0:numSamples-1) / app.Fs; % Calculamos el tiempo usando la Fs del archivo
                fs = app.Fs;
                t = time(end);
            elseif value == 11
                time = app.tiempos;             
                res = app.temperaturas;
                fs = app.Fs_HzEditField.Value;
                t = app.temperaturas(end);
                
            end
            app.signal_stored2 = res;
            app.time_stored2=time;

            plot(app.UIAxes_scs_3, time, res);
                xlabel(app.UIAxes_scs_3, 'Time (s)');
                ylabel(app.UIAxes_scs_3, 'Amplitude');
                title(app.UIAxes_scs_3, 'Signal 2');
                grid(app.UIAxes_scs_3, 'on');

                
            app.FsEditField_2.Value = fs;
            app.TimeEditField_2.Value = t;

            app.signal2op = [res; time];
            app.fs_signal2op = fs;
        end

        % Value changed function: SelectsignalDropDown
        function SelectsignalDropDownValueChanged(app, event)

            value = str2double(app.SelectsignalDropDown.Value);
            FilterDropDownValueChanged(app, event)
            if value==2
                app.SelectedSignal = app.signal_stored1;
                app.N=length(app.SelectedSignal);
                app.Y=fft(app.SelectedSignal);
                app.f=(0:app.N-1)*(app.Samplingfrequency.Value/app.N);
            elseif value==3
                app.SelectedSignal = app.signal_stored2;
                app.N=length(app.SelectedSignal);
                app.Y=fft(app.SelectedSignal);
                app.f=(0:app.N-1)*(app.Samplingfrequency.Value/app.N);
            elseif value==4
            elseif value==5
                app.SelectedSignal = app.filtered;
                app.N=length(app.SelectedSignal);
                app.Y=fft(app.SelectedSignal);
                app.f=(0:app.N-1)*(app.Samplingfrequency.Value/app.N);
                
            end

            app.SelectedSignal = app.SelectedSignal(:);

            if size(app.SelectedSignal, 2) > 1  % Si tiene más de una columna
                app.SelectedSignal = mean(app.SelectedSignal, 2);  % Convertir a mono promediando 
            end
            
        end

        % Button pushed function: MagnitudeButton
        function MagnitudeButtonPushed(app, event)
            SelectsignalDropDownValueChanged(app, event);
            
            plot(app.UIAxes_scs_11, app.f, abs(app.Y));
            xlabel(app.UIAxes_scs_11, 'Frequency (Hz)');
            ylabel(app.UIAxes_scs_11, 'Magnitude');
            title(app.UIAxes_scs_11, 'Fourier Transform Magnitude');
            grid(app.UIAxes_scs_11, 'on');
        end

        % Button pushed function: PhaseButton
        function PhaseButtonPushed(app, event)
            SelectsignalDropDownValueChanged(app, event);
            
            plot(app.UIAxes_scs_12, app.f, angle(app.Y));  
            xlabel(app.UIAxes_scs_12, 'Frequency (Hz)');
            ylabel(app.UIAxes_scs_12, 'Phase (radians)');
            title(app.UIAxes_scs_12, 'Fourier Transform Phase');
            grid(app.UIAxes_scs_12, 'on');
        end

        % Button pushed function: SpectogramButton
        function SpectogramButtonPushed(app, event)
            SelectsignalDropDownValueChanged(app, event);
            samplerate =double(app.Samplingfrequency);
            spectrogram(app.SelectedSignal, 256, 250, 256, samplerate, 'yaxis');
            colormap('jet');  % Mapa de color para el espectrograma
            title('Spectrogram');  % Establece el título del espectrograma
            colorbar;  % Mostrar barra de color


        end

        % Value changed function: SelectsignalDropDown_2
        function SelectsignalDropDown_2ValueChanged(app, event)
            disp('Ejecutando SelectsignalDropDown_2ValueChanged');
            value = str2double(app.SelectsignalDropDown_2.Value);
            SelectsignalDropDownValueChanged(app, event);
            signal=app.SelectedSignal;
            samplerate=double(app.Samplingfrequency.Value);
            time =app.tiempo;
            Fc_low = 100;   % Frecuencia de corte baja en Hz
            Fc_high = 300;  % Frecuencia de corte alta en Hz
            filter_order = 4;  % Orden del filtro

            % Normalizar las frecuencias
            Wn_low = Fc_low / (samplerate / 2);  % Frecuencia de corte baja normalizada
            Wn_high = Fc_high / (samplerate / 2); % Frecuencia de corte alta normalizada

            % Verificar que las frecuencias normalizadas estén entre 0 y 1
            if Wn_low <= 0 || Wn_low >= 1 || Wn_high <= 0 || Wn_high >= 1
                errordlg('Las frecuencias de corte deben estar entre 0 y la frecuencia de Nyquist');
                return;
            end

            if isempty(signal)
                errordlg('La señal seleccionada está vacía. Por favor, selecciona una señal válida.', 'Error');
                return;
            end

            disp(['La longitud de la señal es: ', num2str(length(signal))]);

            switch value
                case 2  % Filtro pasa bajos
                    [b, a] = butter(filter_order, Wn_low, 'low');
                case 3  % Filtro pasa altos
                    [b, a] = butter(filter_order, Wn_high, 'high');
                case 4  % Filtro pasa banda
                    Wn = [Wn_low Wn_high];  % Banda de paso entre Fc_low y Fc_high
                    [b, a] = butter(filter_order, Wn, 'bandpass');
                case 5  % Filtro elimina banda
                    Wn = [Wn_low Wn_high];  % Banda de rechazo entre Fc_low y Fc_high
                    [b, a] = butter(filter_order, Wn, 'stop');
            end


            signal_length = length(signal);
            padding_length = min(1000, floor(signal_length / 2)); % Ajustar el padding_length según la longitud de la señal

            if padding_length < 1
                errordlg('La señal es demasiado corta para aplicar padding.', 'Error');
                return;
            end
            padded_signal = [flipud(signal(1:padding_length)); signal; flipud(signal(end-padding_length+1:end))];

            filtered_padded_signal = filtfilt(b, a, padded_signal);  % Filtrar la señal con padding
            filtered_signal = filtered_padded_signal(padding_length+1:end-padding_length); % Eliminar el padding

            % Asegurarse de que la señal filtrada tenga la misma longitud que la original
            filtered_signal = filtered_signal(1:length(signal));

            app.FilteredSignal=filtered_signal;
            disp(['Longitud de time: ', num2str(length(time))]);
            disp(['Longitud de app.FilteredSignal: ', num2str(length(app.FilteredSignal))]);

            if length(time) ~= length(app.FilteredSignal)
                errordlg('El vector de tiempo y la señal filtrada tienen diferentes longitudes.', 'Error');
                return;
            end

            disp(['Longitud de app.FilteredSignal: ', num2str(length(app.FilteredSignal))]);
            disp(['Primeros 5 valores de app.FilteredSignal: ', num2str(app.FilteredSignal(1:5)')]);

            figure;
            freqz(b, a, 1024, samplerate);
            title('Respuesta en Frecuencia del Filtro');

            % Comparar la señal original y la señal filtrada
            figure;
            subplot(2,1,1);
            plot(time, signal);
            title('Señal Original');
            xlabel('Tiempo (s)');
            ylabel('Amplitud');

            subplot(2,1,2);
            plot(time, app.FilteredSignal, 'Color', [0.8500, 0.3250, 0.0980], 'LineWidth', 1.5);
            title('Señal Filtrada');
            xlabel('Tiempo (s)');
            ylabel('Amplitud');

        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            
        end

        % Value changed function: InputSignalDropDown_2
        function InputSignalDropDown_2ValueChanged2(app, event)
            value = str2double(app.InputSignalDropDown_2.Value);
            
            TypeofsignalDropDownValueChanged(app, event);

            res = [];
            time = [];

            if value== 1
            elseif value==2
                res = app.signal_sine(1, :);
                time = app.signal_sine(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value==3
                res = app.signal_cosine(1, :); 
                time = app.signal_cosine(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value ==4
                res = app.signal_step(1, :);
                time = app.signal_step(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif  value == 5
                res = app.signal_ramp(1, :);
                time = app.signal_ramp(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value== 6
                res = app.signal_chirp(1, :);
                time = app.signal_chirp(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value== 7
                res = app.signal_square(1, :);
                time = app.signal_square(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value== 8
                res = app.signal_sawtooth(1, :);
                time = app.signal_sawtooth(2, :);
                fs = app.inputsvalues(value-1,1); t = app.inputsvalues(value-1,2);
            elseif value ==9
                numSamples = length(app.getAudio);  % Número de muestras de audio
                time = (0:numSamples-1) / double(app.SampleRateHzRecord); % Crear vector de tiempo basado en la frecuencia de muestreo
                fs = app.SampleRateHzRecord.Value;
                t = time(end);
                 if strcmp(app.ModeSwitch.Value, 'Mono')
                     res = app.getAudio(:, 1)';  % Graficar audio mono
                 else
                     % Audio estéreo, canal izquierdo (o puedes usar ambos canales)
                     res_left = app.getAudio(:, 1);  % Canal izquierdo
                     res_right = app.getAudio(:, 2); % Canal derecho (si es necesario graficarlo también)

                     % Aquí podrías graficar ambos canales
                     plot(app.UIAxes_scs_15, time, res_left); % Graficar canal izquierdo
                     hold(app.UIAxes_scs_15, 'on');
                     plot(app.UIAxes_scs_15, time, res_right); % Graficar canal derecho
                     hold(app.UIAxes_scs_15, 'off');
                     xlabel(app.UIAxes_scs_15, 'Time (s)');
                     ylabel(app.UIAxes_scs_15, 'Amplitude');
                     title(app.UIAxes_scs_15, 'Audio Signal (Stereo)');
                     grid(app.UIAxes_scs_15, 'on');
                     
                 end
            elseif value == 10
                % Graficar archivo de audio cargado
                res = app.AudioFile; % El archivo de audio que subiste
                numSamples = length(app.AudioFile);
                time = (0:numSamples-1) / app.Fs; % Vector de tiempo basado en la frecuencia de muestreo del archivo
                fs = app.Fs;
            elseif value == 11
                time = app.tiempos;             
                res = app.temperaturas;
                fs = app.Fs_HzEditField.Value;
                t = app.temperaturas(end);
            end

            app.signal_storedEcho = res;
            app.fs_storedecho=fs;

            disp(['Longitud de res: ', num2str(length(res))]);
            disp(['Longitud de time: ', num2str(length(time))]);

            % Graficar si es mono o cualquier otra señal
            plot(app.UIAxes_scs_15, time, res);
            xlabel(app.UIAxes_scs_15, 'Time (s)');
            ylabel(app.UIAxes_scs_15, 'Amplitude');
            title(app.UIAxes_scs_15, 'Signal');
            grid(app.UIAxes_scs_15, 'on');
            
            
        end

        % Button pushed function: Plot_echo
        function Plot_echoButtonPushed(app, event)
            InputSignalDropDown_2ValueChanged2(app, event);
            %Fs = app.Samplingfrequency.Value;
% 
%             A1 = app.A1.Value;
%             A2 = app.A2.Value;
%             A3 = app.A3.Value;
%             k1 = app.k1.Value;
%             k2 = app.k2.Value;
%             k3 = app.k3.Value;
% 
%             
%             % Validar que los retardos cumplan k1 < k2 < k3
%             if ~(k1 < k2 && k2 < k3)
%                 errordlg('Los retardos deben cumplir k1 < k2 < k3.', 'Error');
%                 return;
%             end
% 
%             if ~(A1 > A2 && A2 > A3)
%                 errordlg('Las amplitudes deben cumplir A1 > A2 > A3.', 'Error');
%                 return;
%             end
% 
%             x = app.signal_storedEcho;
%             x=x(:);
%             y=zeros(size(x));
% 
%             for n = 1:length(x)
%                 y(n) = x(n);
%                 if n > k1
%                     y(n) = y(n) + A1 * x(n-k1);
%                 end
%                 if n > k2
%                     y(n) = y(n) + A2 * x(n-k2);
%                 end
%                 if n > k3
%                     y(n) = y(n) + A3 * x(n-k3);
%                 end
%             end
% 
%             y= y/max(abs(y));
%             app.signal_echo=y;
% 
% 
% 
%             plot(app.UIAxes_scs_16, app.time_storedecho, app.signal_echo);
%             xlabel(app.UIAxes_scs_16, 'Tiempo (s)');
%             ylabel(app.UIAxes_scs_16, 'Amplitud');
%             title(app.UIAxes_scs_16, 'Señal con Eco');
%              grid(app.UIAxes_scs_16, 'on');


              A1 = app.A1.Value;
              A2 = app.A2.Value;
              A3 = app.A3.Value;
              k1 = app.k1.Value;
              k2 = app.k2.Value;
              k3 = app.k3.Value;

              % Validar que los retardos cumplan k1 < k2 < k3
              if ~(k1 < k2 && k2 < k3)
                  errordlg('Los retardos deben cumplir k1 < k2 < k3.', 'Error');
                  return;
              end

              % Validar que las amplitudes cumplan A1 > A2 > A3
              if ~(A1 > A2 && A2 > A3)
                  errordlg('Las amplitudes deben cumplir A1 > A2 > A3.', 'Error');
                  return;
              end

              % Obtener la frecuencia de muestreo
              if isfield(app, 'SampleRateHzRecord') && ~isempty(app.SampleRateHzRecord)
                  Fs = double(app.SampleRateHzRecord);
              elseif isfield(app, 'Fs') && ~isempty(app.Fs)
                  Fs = app.Fs;
              else
                  Fs = app.Samplingfrequency.Value;
              end

              % Convertir los retardos de segundos a muestras
              k1_samples = round(k1 * Fs);
              k2_samples = round(k2 * Fs);
              k3_samples = round(k3 * Fs);

              % Validar que los retardos sean enteros positivos
              if any([k1_samples, k2_samples, k3_samples] <= 0)
                  errordlg('Los retardos deben ser números positivos mayores que cero.', 'Error');


                  return;
              end

              % Obtener la señal seleccionada
              x = app.signal_storedEcho;
              x = x(:);  % Asegurarse de que x es un vector columna

              % Calcular el máximo retardo
              max_delay = max([k1_samples, k2_samples, k3_samples]);

              % Extender la señal original con ceros para acomodar el eco
              x_padded = [x; zeros(max_delay, 1)];

              % Inicializar la señal de salida
              y = zeros(size(x_padded));
              app.N = length(x_padded);

              % Aplicar el efecto de eco
              for n = 1:app.N
                  if n <= length(x)
                      y(n) = x_padded(n);
                  end
                  if n > k1_samples
                      y(n) = y(n) + A1 * x_padded(n - k1_samples);
                  end
                  if n > k2_samples
                      y(n) = y(n) + A2 * x_padded(n - k2_samples);
                  end
                  if n > k3_samples
                      y(n) = y(n) + A3 * x_padded(n - k3_samples);
                  end
              end

              % Normalizar la salida para evitar clipping
              y = y / max(abs(y));

              % Guardar la señal con eco
              app.signal_echo = y;

              % Actualizar el vector de tiempo
              time = (0:length(y)-1)' / Fs;

              % Graficar la señal con eco
              %cla(app.UIAxes_scs_16);  % Limpiar el eje
              plot(app.UIAxes_scs_16, time, y);
              xlabel(app.UIAxes_scs_16, 'Tiempo (s)');
              ylabel(app.UIAxes_scs_16, 'Amplitud');
              title(app.UIAxes_scs_16, 'Señal con Eco');
              grid(app.UIAxes_scs_16, 'on');


        end

        % Button pushed function: Play_echo
        function Play_echoButtonPushed(app, event)
            Plot_echoButtonPushed(app, event);
            
            app.playerecho = audioplayer(app.signal_echo, app.fs_storedecho);
            play(app.playerecho); %El eco

           
        end

        % Button pushed function: Stop_echo
        function Stop_echoButtonPushed(app, event)
            stop(app.playerecho);
        end

        % Button pushed function: SumButton
        function SumButtonPushed(app, event)
            s1 = app.signal1op(1,:); t1 = app.signal1op(2,:);
            fs1 = app.fs_signal1op;
            s2 = app.signal2op(1,:); t2 = app.signal2op(2,:);
            fs2 = app.fs_signal2op;

        % Ajusta la frecuencia de muestreo de la señal de audio2 a la de la señal de audio1 si son diferentes
            if(fs2>=fs1)
                myrecord1_resample=resample(s1,fs1,fs1);
                myrecord2_resample=resample(s2,fs1,fs2);
                fsmin=fs1;
            elseif(fs2<=fs1)
                myrecord1_resample=resample(s1,fs2,fs1);
                myrecord2_resample=resample(s2,fs2,fs2);
                fsmin=fs2;
            end

            max_length = max(length(myrecord1_resample), length(myrecord2_resample));
                  
            %Padding
            s1_pad = [myrecord1_resample, zeros(1, max_length - length(myrecord1_resample))]; %ceros
            s2_pad = [myrecord2_resample, zeros(1, max_length - length(myrecord2_resample))];

            suma1 = s1_pad + s2_pad;
            t = (0:length(suma1)-1)/fsmin;

            plot(app.UIAxes_scs_4, t, suma1);
                xlabel(app.UIAxes_scs_4, 'Time (s)');
                ylabel(app.UIAxes_scs_4, 'Amplitude');
                title(app.UIAxes_scs_4, 'Signal Result');
                grid(app.UIAxes_scs_4, 'on');
            
            app.signal_result = suma1;
            app.fs_result = fsmin;

        end

        % Button pushed function: MultButton
        function MultButtonPushed(app, event)
            s1 = app.signal1op(1,:); t1 = app.signal1op(2,:);
            fs1 = app.fs_signal1op;
            s2 = app.signal2op(1,:); t2 = app.signal2op(2,:);
            fs2 = app.fs_signal2op;

        % Ajusta la frecuencia de muestreo de la señal de audio2 a la de la señal de audio1 si son diferentes
            if(fs2>=fs1)
                myrecord1_resample=resample(s1,fs1,fs1);
                myrecord2_resample=resample(s2,fs1,fs2);
                fsmin=fs1;
            elseif(fs2<=fs1)
                myrecord1_resample=resample(s1,fs2,fs1);
                myrecord2_resample=resample(s2,fs2,fs2);
                fsmin=fs2;
            end

            max_length = max(length(myrecord1_resample), length(myrecord2_resample));
                  
            %Padding
            s1_pad = [myrecord1_resample, zeros(1, max_length - length(myrecord1_resample))]; %ceros
            s2_pad = [myrecord2_resample, zeros(1, max_length - length(myrecord2_resample))];

            mult1 = s1_pad .* s2_pad;
            t = (0:length(mult1)-1)/fsmin;

            plot(app.UIAxes_scs_4, t, mult1);
                xlabel(app.UIAxes_scs_4, 'Time (s)');
                ylabel(app.UIAxes_scs_4, 'Amplitude');
                title(app.UIAxes_scs_4, 'Signal result');
                grid(app.UIAxes_scs_4, 'on');

            app.signal_result = mult1;
            app.fs_result = fsmin;
        end

        % Value changed function: InputSignal1DropDown_2
        function InputSignal1DropDown_2ValueChanged(app, event)
            value = str2double(app.InputSignal1DropDown_2.Value);
            
            TypeofsignalDropDownValueChanged(app, event);

            res = [];
            time = [];

            if value== 1
            elseif value==2
                res = app.signal_sine(1, :);
                time = app.signal_sine(2, :);              
            elseif value==3
                res = app.signal_cosine(1, :); 
                time = app.signal_cosine(2, :);                
            elseif value ==4
                res = app.signal_step(1, :);
                time = app.signal_step(2, :);
            elseif  value == 5
                res = app.signal_ramp(1, :);
                time = app.signal_ramp(2, :);                
            elseif value== 6
                res = app.signal_chirp(1, :);
                time = app.signal_chirp(2, :);
            elseif value== 7
                res = app.signal_square(1, :);
                time = app.signal_square(2, :);
            elseif value== 8
                res = app.signal_sawtooth(1, :);
                time = app.signal_sawtooth(2, :);
            elseif value==9
                 numSamples = length(app.getAudio);  % Número de muestras de audio
                 time = (0:numSamples-1) / double(app.SampleRateHzRecord); % Crear vector de tiempo basado en la frecuencia de muestreo
                 if strcmp(app.ModeSwitch.Value, 'Mono')
                     res = app.getAudio(:, 1)';  % Graficar audio mono
                 else
                     % Audio estéreo, canal izquierdo (o puedes usar ambos canales)
                     res_left = app.getAudio(:, 1);  % Canal izquierdo
                     res_right = app.getAudio(:, 2); % Canal derecho (si es necesario graficarlo también)

                     % Aquí podrías graficar ambos canales
                     plot(app.UIAxes_scs_17, time, res_left); % Graficar canal izquierdo
                     hold(app.UIAxes_scs_17, 'on');
                     plot(app.UIAxes_scs_17, time, res_right); % Graficar canal derecho
                     hold(app.UIAxes_scs_17, 'off');
                     xlabel(app.UIAxes_scs_17, 'Time (s)');
                     ylabel(app.UIAxes_scs_17, 'Amplitude');
                     title(app.UIAxes_scs_17, 'Audio Signal (Stereo)');
                     grid(app.UIAxes_scs_17, 'on');
                     return;
                 end
            elseif value == 10
                % Graficar archivo de audio cargado
                res = app.AudioFile; % El archivo de audio que subiste
                numSamples = length(app.AudioFile);
                time = (0:numSamples-1) / app.Fs; % Vector de tiempo basado en la frecuencia de muestreo del archivo
            elseif value == 11
                time = app.tiempos;             
                res = app.temperaturas;
            end

            app.signal_storedFilter =res;
            app.time_storedFilter =time;
            
            disp(['Longitud de res: ', num2str(length(res))]);
            disp(['Longitud de time: ', num2str(length(time))]);

           % Graficar si es mono o cualquier otra señal
            plot(app.UIAxes_scs_17, time, res);
            xlabel(app.UIAxes_scs_17, 'Time (s)');
            ylabel(app.UIAxes_scs_17, 'Amplitude');
            title(app.UIAxes_scs_17, 'Signal');
            grid(app.UIAxes_scs_17, 'on');

            
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = str2double(app.FilterDropDown.Value);
             disp('Botón de aplicar filtro presionado.');
            InputSignal1DropDown_2ValueChanged(app, event);
            window = app.WindowSize.Value;
            x= app.signal_storedFilter;
            %x = x(:);  % Convertir x en vector columna

            if value==1
            elseif value == 2
                    h = [-0.000779024944408449136062921880352405424, 0.000999731876142587964212404827435420884, 0.003831402877460619166294364390523696784, 0.006331215011077922713567112111832102528, 0.004203293161063792818255002714522561291, -0.006457016133813054704904121905428837636, -0.023107528738289289521601332921818539035, -0.033457559743661630102806725517439190298, -0.019904587044922469885044336024293443188, 0.028977468595656773187041466144364676438, 0.107219132728444313529259090955747524276, 0.189581211105815133910468262001813855022, 0.242562261249433724907831333439389709383, 0.242562261249433724907831333439389709383, 0.189581211105815133910468262001813855022, 0.107219132728444313529259090955747524276, 0.028977468595656773187041466144364676438, -0.019904587044922469885044336024293443188, -0.033457559743661630102806725517439190298, -0.023107528738289289521601332921818539035, -0.006457016133813054704904121905428837636, 0.004203293161063792818255002714522561291, 0.006331215011077922713567112111832102528, 0.003831402877460619166294364390523696784, 0.000999731876142587964212404827435420884, -0.000779024944408449136062921880352405424];
                    disp(['Tamaño de h: ', num2str(length(h))]);
                    app.filtered= convolucion(app, app.signal_storedFilter, h, window, app.Samplingfrequency.Value);
                    
            elseif value == 3
                    h = [0.001884975120330046596470174868898084242, 0.002419010748058414775329971746486990014, 0.001590599013349243634821150727987060236, -0.002628390871960992539180246296837140108, -0.010170538297814175898725608249151264317, -0.015623780536385257697307160640320944367, -0.009593042963005444026536316926012659678, 0.01388983701776957437046267074265415431, 0.048162323465281500767254385664273286238, 0.070115607651607611550126364363677566871, 0.044511802121696433798270220449921907857, -0.078704249325595526665111378861183766276, -0.586918083776343801893915497203124687076, 0.586918083776343801893915497203124687076, 0.078704249325595526665111378861183766276, -0.044511802121696433798270220449921907857, -0.070115607651607611550126364363677566871, -0.048162323465281500767254385664273286238, -0.01388983701776957437046267074265415431, 0.009593042963005444026536316926012659678, 0.015623780536385257697307160640320944367, 0.010170538297814175898725608249151264317, 0.002628390871960992539180246296837140108, -0.001590599013349243634821150727987060236, -0.002419010748058414775329971746486990014, -0.001884975120330046596470174868898084242];
                    disp(['Tamaño de h: ', num2str(length(h))]);
                    app.filtered= convolucion(app, x, h, window, app.Samplingfrequency.Value);
            elseif value == 4
                    h = [-0.000000000000000001922929161567581996809, -0.001569897304044256787849964673853264685, 0.001799201069399839880008640236042083416, 0.008990994433931174395491225936893897597, 0.010792541725120091689049672822875436395, -0.000000000000000009354657166252341208504, -0.015835346743411488762420802345332049299, -0.019167509184833779467549419450733694248, -0.005459965985622059446435994090052190586, 0.006568207170954977990040557500606155372, -0.000000000000000005317090080755072521592, -0.009469026014475398489445012728538131341, 0.01140226611204093673523907170874736039, 0.058552268255241124439436362081323750317, 0.071837724542372083380570302324485965073, -0.000000000000000021466635397073866283563, -0.116300660559505344981978680607426213101, -0.15922324040717714122017412137211067602, -0.057452898173589478370004712814989034086, 0.116208912714097029050819287476770114154, 0.201752768291331929795262567495228722692, 0.116208912714097029050819287476770114154, -0.057452898173589478370004712814989034086, -0.15922324040717714122017412137211067602, -0.116300660559505344981978680607426213101, -0.000000000000000021466635397073866283563, 0.071837724542372083380570302324485965073, 0.058552268255241124439436362081323750317, 0.01140226611204093673523907170874736039, -0.009469026014475398489445012728538131341, -0.000000000000000005317090080755072521592, 0.006568207170954977990040557500606155372, -0.005459965985622059446435994090052190586, -0.019167509184833779467549419450733694248, -0.015835346743411488762420802345332049299, -0.000000000000000009354657166252341208504, 0.010792541725120091689049672822875436395, 0.008990994433931174395491225936893897597, 0.001799201069399839880008640236042083416, -0.001569897304044256787849964673853264685, -0.000000000000000001922929161567581996809];
                    disp(['Tamaño de h: ', num2str(length(h))]);
                    app.filtered= convolucion(app, x, h, window, app.Samplingfrequency.Value);
            elseif value == 5
                    h = [-0.000000000000000007663637615541334456606, 0.001564166308394175945381543435530602437, -0.001792632987859845957248627890123771067, -0.008958172318842353917811571761831146432, -0.010753142963480786728491800374740705593, 0.000000000000000054897901653096777820552, 0.015777538947276896486027197852308745496, 0.019097537148141507240550751589580613654, 0.005440034082284975550103389707601309055, -0.006544229572784126917894820252286081086, -0.000000000000000021190719014775402505014, 0.009434458819054492365041575396844564239, -0.011360641518304093536273491338306484977, -0.058338520009537278565581175371335120872, -0.071575477014584637514005294178787153214, 0.000000000000000057035387399646394219074, 0.115876098661058898886544454853719798848, 0.158641987292200414838561073338496498764, 0.057243163238271957404812440017849439755, -0.11578468574615942421335290646311477758, 0.804065035269738492829105780401732772589, -0.11578468574615942421335290646311477758, 0.057243163238271957404812440017849439755, 0.158641987292200414838561073338496498764, 0.115876098661058898886544454853719798848, 0.000000000000000057035387399646394219074, -0.071575477014584637514005294178787153214, -0.058338520009537278565581175371335120872, -0.011360641518304093536273491338306484977, 0.009434458819054492365041575396844564239, -0.000000000000000021190719014775402505014, -0.006544229572784126917894820252286081086, 0.005440034082284975550103389707601309055, 0.019097537148141507240550751589580613654, 0.015777538947276896486027197852308745496, 0.000000000000000054897901653096777820552, -0.010753142963480786728491800374740705593, -0.008958172318842353917811571761831146432, -0.001792632987859845957248627890123771067, 0.001564166308394175945381543435530602437, -0.000000000000000007663637615541334456606];
                    disp(['Tamaño de h: ', num2str(length(h))]);
                    app.filtered= convolucion(app, x, h, window, app.Samplingfrequency.Value);
            elseif value == 6 %% FIR hn
                    h= app.valor_usuario;
                    app.filtered= convolucion(app, x, h, window, app.Samplingfrequency.Value);
            elseif value == 7 
                    T = readtable('Filtros_Prueba_Proyecto1_Entregable.xlsx','Sheet','Filtro IIR 1');
                    valores= cellfun(@str2double,T.Valores);
                    coeficiente= [T.Coeficiente];
                    okb= 1;
                    oka= 1;
                    for i= 1:length(valores)
                        if contains(coeficiente{i}, 'b')
                           b(okb)=valores(i);
                           okb= okb+1;
                        elseif contains(coeficiente{i}, 'a')
                             a(oka)=valores(i);
                             oka= oka+1;
                        end
                    end
                    disp(['Coeficientes a: ', num2str(a)]);
                    disp(['Coeficientes b: ', num2str(b)]);
             app.filtered= ec_diference(app,x, a, b, app.Samplingfrequency.Value);
             
              elseif value == 8
                    T = readtable('Filtros_Prueba_Proyecto1_Entregable.xlsx','Sheet','Filtro IIR 2');
                    valores= cellfun(@str2double,T.Valores);
                    coeficiente= [T.Coeficiente];
                    okb= 1;
                    oka= 1;
                    for i= 1:length(valores)
                        if contains(coeficiente{i}, 'b')
                           b(okb)=valores(i);
                           okb= okb+1;
                        elseif contains(coeficiente{i}, 'a')
                             a(oka)=valores(i);
                             oka= oka+1;
                        end
                    end
             app.filtered= ec_diference(app,x, a, b, app.Samplingfrequency.Value);
             
             elseif value == 9 
                    T = readtable('Filtros_Prueba_Proyecto1_Entregable.xlsx','Sheet','Filtro IIR 3');
                    valores= cellfun(@str2double,T.Valores);
                    coeficiente= [T.Coeficiente];
                    okb= 1;
                    oka= 1;
                    for i= 1:length(valores)
                        if contains(coeficiente{i}, 'b')
                           b(okb)=valores(i);
                           okb= okb+1;
                        elseif contains(coeficiente{i}, 'a')
                             a(oka)=valores(i);
                             oka= oka+1;
                        end
                    end
             app.filtered= ec_diference(app,x, a, b, app.Samplingfrequency.Value);
             
             elseif value == 10
                    T = readtable('Filtros_Prueba_Proyecto1_Entregable.xlsx','Sheet','Filtro IIR 4');
                    valores= cellfun(@str2double,T.Valores);
                    coeficiente= [T.Coeficiente];
                    okb= 1;
                    oka= 1;
                    for i= 1:length(valores)
                        if contains(coeficiente{i}, 'b')
                           b(okb)=valores(i);
                           okb= okb+1;
                        elseif contains(coeficiente{i}, 'a')
                             a(oka)=valores(i);
                             oka= oka+1;
                        end
                    end
             app.filtered= ec_diference(app,x, a, b, app.Samplingfrequency.Value);
             
             elseif value == 11
                 
                    b = app.valor_usuariobk;
                    a= app.valor_usuarioak;
                    
              app.filtered= ec_diference(app,x, a, b, app.Samplingfrequency.Value);
                   
            end

            Fs = app.Samplingfrequency.Value;  % Frecuencia de muestreo
            y_length = length(app.filtered);
            app.tiempo_filtered = (0:y_length - 1)/ Fs;
            
        end

        % Button pushed function: FIRhnButton_2
        function FIRhnButton_2Pushed(app, event)

            %Ingresar datos para filtro FIR
            respuesta = inputdlg('Ingresar los valores h(n) separados por espacio: ', 'Filter FIR - h(n)', [1 50]);
            valor_usuario = str2num(respuesta{:});
            valor_usuario = valor_usuario.';
            app.valor_usuario= valor_usuario;
        end

        % Button pushed function: IIRakbkButton_2
        function IIRakbkButton_2Pushed(app, event)
respuesta_ab = inputdlg({'Ingresar los valores ak separados por espacio: ','Ingresar los valores bk separados por espacio: '}, 'Filter IIR - ak & bk', [1 50; 1 50]);
            valor_usuarioak = str2num(respuesta_ab{1}); 
            valor_usuariobk = str2num(respuesta_ab{2}); 
            valor_usuarioak = valor_usuarioak.';
            valor_usuariobk = valor_usuariobk.';
            app.valor_usuarioak = valor_usuarioak;
            app.valor_usuariobk = valor_usuariobk;
        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
            samplingfrequency= app.Samplingfrequency.Value;
            app.playSignal1=audioplayer(app.result,samplingfrequency);
            play(app.playSignal1);
        end

        % Button pushed function: StopButton_4
        function StopButton_4Pushed(app, event)
            stop(app.playSignal1);
        end

        % Button pushed function: PlayButton_4
        function PlayButton_4Pushed(app, event)
            recorded = app.filtered;%selecciona los datos de la celda actual
            Fs = app.filtradaFs;

            app.player = audioplayer(recorded,Fs);
            play(app.player);
        end

        % Button pushed function: StopButton_5
        function StopButton_5Pushed(app, event)
            stop(app.player);
        end

        % Value changed function: SelectfilterDropDown
        function SelectfilterDropDownValueChanged(app, event)
            value = str2double(app.SelectfilterDropDown.Value);
            %value = str2double(app.SelectsignalDropDown_3.Value);
            %SelectsignalDropDown_3ValueChanged(app, event)
            %value = str2double(app.SelectsignalDropDown_3.Value);

            % Llamar al callback de filtros FIR/IIR para asegurar que se aplique el filtro primero
            %FilterDropDownValueChanged(app, event)


            % Verificar si hay una señal filtrada
            if isempty(app.filtered)
                errordlg('No hay una señal filtrada disponible. Primero aplique un filtro FIR/IIR.', 'Error');
                return;
            end

            % Se usa la señal ya filtrada por el filtro FIR/IIR
            signal = app.filtered;
            samplerate = double(app.Samplingfrequency.Value);
            time = app.tiempo_filtered;

            % Parámetros del filtro pasa bajos/altos/banda
            Fc_low = 100;   % Frecuencia de corte baja en Hz
            Fc_high = 300;  % Frecuencia de corte alta en Hz
            filter_order = 4;  % Orden del filtro

            % Normalizar las frecuencias
            Wn_low = Fc_low / (samplerate / 2);  % Frecuencia de corte baja normalizada
            Wn_high = Fc_high / (samplerate / 2); % Frecuencia de corte alta normalizada

            % Verificación de las frecuencias normalizadas
            if Wn_low <= 0 || Wn_low >= 1 || Wn_high <= 0 || Wn_high >= 1
                errordlg('Las frecuencias de corte deben estar entre 0 y la frecuencia de Nyquist');
                return;
            end

            % Asegurarse de que la señal no esté vacía
            if isempty(signal)
                errordlg('La señal seleccionada está vacía. Por favor, selecciona una señal válida.', 'Error');
                return;
            end

            % Aplicar el filtro según la selección del usuario
            switch value
                case 2  % Filtro pasa bajos
                    [b, a] = butter(filter_order, Wn_low, 'low');
                case 3  % Filtro pasa altos
                    [b, a] = butter(filter_order, Wn_high, 'high');
                case 4  % Filtro pasa banda
                    Wn = [Wn_low Wn_high];  % Banda de paso entre Fc_low y Fc_high
                    [b, a] = butter(filter_order, Wn, 'bandpass');
                case 5  % Filtro elimina banda
                    Wn = [Wn_low Wn_high];  % Banda de rechazo entre Fc_low y Fc_high
                    [b, a] = butter(filter_order, Wn, 'stop');
            end

            % Agregar padding a la señal
            signal_length = length(signal);

            % Mostrar la longitud de la señal y el padding para diagnóstico
            disp(['Longitud de la señal: ', num2str(signal_length)]);
            

            % Imprimir los primeros y últimos 5 valores de la señal para verificar si está bien
            disp('Primeros 5 valores de la señal:');
            disp(signal(1:min(5, signal_length)));

            disp('Últimos 5 valores de la señal:');
            disp(signal(max(1, signal_length-4):signal_length));


            % El valor de padding_length no debe exceder la longitud de la señal
            padding_length = min(1000, floor(signal_length / 2));

            disp(['Valor de padding_length: ', num2str(padding_length)]);

            % Verificar que el padding sea posible con la longitud de la señal
            if padding_length < 1 || padding_length > signal_length
                errordlg('La señal es demasiado corta para aplicar padding.', 'Error');
                return;
            end

            % Convertir la señal a vector columna (si no lo es ya)
            signal = signal(:);

            % Intentar aplicar el padding
            try
                % Comprobar los límites de indexación
                disp(['Indexando desde 1 hasta ', num2str(padding_length)]);
                disp(['Indexando desde ', num2str(signal_length - padding_length + 1), ' hasta ', num2str(signal_length)]);

                % Extraer la parte de la señal para el padding y asegurarse de que sean vectores columna
                flipped_start = flipud(signal(1:padding_length));  % Parte inicial invertida
                flipped_end = flipud(signal(end-padding_length+1:end));  % Parte final invertida

                % Verificar dimensiones de flipped_start, signal, y flipped_end
                disp(['Dimensiones de flipped_start: ', num2str(size(flipped_start))]);
                disp(['Dimensiones de signal: ', num2str(size(signal))]);
                disp(['Dimensiones de flipped_end: ', num2str(size(flipped_end))]);

                % Concatenar la señal con padding
                padded_signal = [flipped_start; signal; flipped_end];

                disp('Padding aplicado correctamente.');
            catch ME
                % Mostrar información más detallada sobre el error
                disp(['Error al aplicar el padding: ', ME.message]);
                errordlg('Error al aplicar el padding. Verifica la longitud de la señal.', 'Error');
                return;
            end

            % Filtrar la señal con padding
            filtered_padded_signal = filtfilt(b, a, padded_signal);

            % Eliminar el padding
            filtered_signal = filtered_padded_signal(padding_length+1:end-padding_length);

            % Asegurarse de que la señal filtrada tenga la misma longitud que la original
            filtered_signal = filtered_signal(1:length(signal));

            % Guardar la señal filtrada en la propiedad correspondiente
            app.FilteredSignal = filtered_signal;


            % CALCULAR LA GANANCIA EN LA BANDA DE PASO
            % Obtener la respuesta en frecuencia del filtro
            [H, w] = freqz(b, a, 1024, samplerate);  % Respuesta en frecuencia

            % Convertir la magnitud a dB
            magnitude_dB = 20*log10(abs(H));

            % Encontrar las frecuencias de corte de las bandas de rechazo
            cutoff_index_low = find(magnitude_dB <= -3, 1, 'first');  % Frecuencia de corte inferior
            cutoff_index_high = find(magnitude_dB <= -3, 1, 'last');  % Frecuencia de corte superior
            cutoff_frequency_low = w(cutoff_index_low);
            cutoff_frequency_high = w(cutoff_index_high);

            % Mostrar las frecuencias de corte
            disp(['Frecuencia de corte de la banda de rechazo inferior: ', num2str(cutoff_frequency_low), ' Hz']);
            disp(['Frecuencia de corte de la banda de rechazo superior: ', num2str(cutoff_frequency_high), ' Hz']);

            % Encontrar las frecuencias dentro de la banda de paso (asumiendo un filtro pasa bajo)
            Fc = 300;  % Frecuencia de corte en Hz
            passband_indices = find(w <= Fc);  % Frecuencias menores a Fc están en la banda de paso

            % Calcular la ganancia promedio en la banda de paso
            passband_gain = mean(magnitude_dB(passband_indices));

            % Mostrar la ganancia en la banda de paso
            disp(['Ganancia promedio en la banda de paso: ', num2str(passband_gain), ' dB']);

            disp(['w: ', num2str(Wn_low)]);

            % Graficar la respuesta en frecuencia del filtro
            figure;
            freqz(b, a, 1024, samplerate);
            title('Respuesta en Frecuencia del Filtro');

            % Comparar la señal original y la señal filtrada
            figure;
            subplot(2,1,1);
            plot(time, signal);
            title('Señal Original');
            xlabel('Tiempo (s)');
            ylabel('Amplitud');

            subplot(2,1,2);
            plot(time, app.FilteredSignal, 'Color', [0.8500, 0.3250, 0.0980], 'LineWidth', 1.5);
            title('Señal Filtrada');
            xlabel('Tiempo (s)');
            ylabel('Amplitud');
            
        end

        % Button pushed function: PlayButton_6
        function PlayButton_6Pushed(app, event)
         
            app.signal_res=audioplayer(app.signal_result,app.fs_result);
            play(app.signal_res);
        end

        % Button pushed function: StopButton_6
        function StopButton_6Pushed(app, event)
            stop(app.signal_res);
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
  
        end

        % Value changed function: VolumeSlider_2
        function VolumeSlider_2ValueChanged(app, event)

            
        end

        % Button pushed function: DetectportsButton
        function DetectportsButtonPushed(app, event)
         %DETECTAR LOS PUERTOS SERIAL CONECTADOS   
         app.PortsDropDown.Items = serialportlist;  % Lista de puertos seriales
         app.StatusLabel.Text = 'Seleccione un puerto';
          
        end

        % Value changed function: Fs_HzEditField
        function Fs_HzEditFieldValueChanged(app, event)
          if app.Fs_HzEditField.Value > 0
              value_Fs = 1 / app.Fs_HzEditField.Value;  % Calcular el inverso de la frecuencia
              pause(value_Fs);  % Pausar por el tiempo calculado
        else
           uialert(app.UIFigure, 'El valor de frecuencia debe ser mayor que 0', 'Error');
        end
            
            
        end

        % Drop down opening function: SelectSignalDropDown
        function SelectSignalDropDownOpening(app, event)
          
        end

        % Value changed function: SelectSignalDropDown
        function SelectSignalDropDownValueChanged(app, event)
            value = str2double(app.SelectSignalDropDown.Value);
            TypeofsignalDropDownValueChanged(app, event);
         
            if value== 1
            elseif value ==2
                signal = app.signal1op(1,:);
                t = app.signal1op(2,:);
                fs = app.fs_signal1op;
            elseif value ==3
                signal = app.signal2op(1,:);
                t = app.signal2op(2,:);
                fs = app.fs_signal2op;
            end

            app.selectsignalop_s = signal;
            app.selectsignalop_t = t;
            app.selectsignalop_fs = fs;

            plot(app.UIAxes_scs_4, t, signal);
                xlabel(app.UIAxes_scs_4, 'Time (s)');
                ylabel(app.UIAxes_scs_4, 'Amplitude');
                title(app.UIAxes_scs_4, 'Signal Result');
                grid(app.UIAxes_scs_4, 'on');
            
            app.signal_result = signal;
            app.fs_result = fs;

        end

        % Button pushed function: InvertSignalButton
        function InvertSignalButtonPushed(app, event)
            signal = app.selectsignalop_s;
            t = app.selectsignalop_t;
            fs = app.selectsignalop_fs;

            signal_inver = flip(signal);
            
            plot(app.UIAxes_scs_4, t, signal_inver);
                xlabel(app.UIAxes_scs_4, 'Time (s)');
                ylabel(app.UIAxes_scs_4, 'Amplitude');
                title(app.UIAxes_scs_4, 'Signal Result');
                grid(app.UIAxes_scs_4, 'on');
            
            app.signal_result = signal_inver;
            app.fs_result = fs;

        end

        % Button pushed function: StereotomonoButton
        function StereotomonoButtonPushed(app, event)
            audiostereo = app.signal_result;
            fs = app.fs_result;
            audioMono = mean(audiostereo, 2); % Promedia los dos canales
            t = (0:length(audioMono)-1)/fs;

            plot(app.UIAxes_scs_4, t, audioMono);
                xlabel(app.UIAxes_scs_4, 'Time (s)');
                ylabel(app.UIAxes_scs_4, 'Amplitude');
                title(app.UIAxes_scs_4, 'Signal Result');
                grid(app.UIAxes_scs_4, 'on');
            
            app.signal_result = audioMono;
            app.fs_result = fs;

        end

        % Button pushed function: MonotoStereoButton
        function MonotoStereoButtonPushed(app, event)
            s1 = app.signal1op(1,:);
            fs1 = app.fs_signal1op;
            s2 = app.signal2op(1,:);
            fs2 = app.fs_signal2op;

        % Ajusta la frecuencia de muestreo de la señal de audio2 a la de la señal de audio1 si son diferentes
            if(fs2>=fs1)
                myrecord1_resample=resample(s1,fs1,fs1);
                myrecord2_resample=resample(s2,fs1,fs2);
                fsmin=fs1;
            elseif(fs2<=fs1)
                myrecord1_resample=resample(s1,fs2,fs1);
                myrecord2_resample=resample(s2,fs2,fs2);
                fsmin=fs2;
            end

            max_length = max(length(myrecord1_resample), length(myrecord2_resample));
                  
            %Padding
            s1_pad = [myrecord1_resample, zeros(1, max_length - length(myrecord1_resample))]; %ceros
            s2_pad = [myrecord2_resample, zeros(1, max_length - length(myrecord2_resample))];

            t = (0:length(s1_pad)-1)/fsmin;

            Audio = [s1_pad', s2_pad'];
            app.signal_result = Audio;
            app.fs_result = fsmin;

            plot(app.UIAxes_scs_4, t, s1_pad, 'b'); % Canal izquierdo en azul
            hold(app.UIAxes_scs_4, 'on'); % Mantener el gráfico para la siguiente señal
            plot(app.UIAxes_scs_4, t, s2_pad, 'r'); % Canal derecho en rojo
            hold(app.UIAxes_scs_4, 'off'); % Liberar el gráfico
            grid(app.UIAxes_scs_4, 'on');
            xlabel(app.UIAxes_scs_4, 'Time (s)');
            ylabel(app.UIAxes_scs_4, 'Amplitude');
            title(app.UIAxes_scs_4, 'Signal Result');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputSignalsTab = uitab(app.TabGroup);
            app.InputSignalsTab.Title = 'Input Signals';
            app.TabGroup2 = uitabgroup(app.InputSignalsTab);
            app.AudioTab = uitab(app.TabGroup2);
            app.AudioTab.Title = 'Audio';
            app.UIAxes = uiaxes(app.AudioTab);
            title(app.UIAxes, 'Record')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.AudioTab);
            title(app.UIAxes_2, 'File')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.AudiorecorderPanel = uipanel(app.AudioTab);
            app.AudiorecorderPanel.Title = 'Audio recorder';
            app.StopButton_2 = uibutton(app.AudiorecorderPanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.ModeSwitchLabel = uilabel(app.AudiorecorderPanel);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.AudiorecorderPanel, 'rocker');
            app.ModeSwitch.Items = {'Mono', 'Stereo'};
            app.ModeSwitch.Value = 'Mono';
            app.VolumeSliderLabel = uilabel(app.AudiorecorderPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.AudiorecorderPanel);
            app.VolumeSlider.Limits = [0 1];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.PlayButton_5 = uibutton(app.AudiorecorderPanel, 'push');
            app.PlayButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_5Pushed, true);
            app.PlayButton_5.Text = 'Play';
            app.StopresumeButton = uibutton(app.AudiorecorderPanel, 'push');
            app.StopresumeButton.ButtonPushedFcn = createCallbackFcn(app, @StopresumeButtonPushed, true);
            app.StopresumeButton.Text = 'Stop/resume';
            app.RecordButton = uibutton(app.AudiorecorderPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.SampleRateHzSpinnerLabel = uilabel(app.AudiorecorderPanel);
            app.SampleRateHzSpinnerLabel.Text = 'Sample Rate [Hz]:';
            app.SampleRateHzRecord = uispinner(app.AudiorecorderPanel);
            app.SampleRateHzRecord.Value = 3000;
            app.FilePanel = uipanel(app.AudioTab);
            app.FilePanel.Title = 'File';
            app.StopButton_3 = uibutton(app.FilePanel, 'push');
            app.StopButton_3.ButtonPushedFcn = createCallbackFcn(app, @StopButton_3Pushed, true);
            app.StopButton_3.Text = 'Stop';
            app.PlayButton_2 = uibutton(app.FilePanel, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.VolumeSlider_2Label = uilabel(app.FilePanel);
            app.VolumeSlider_2Label.Text = 'Volume';
            app.VolumeSlider_2 = uislider(app.FilePanel);
            app.VolumeSlider_2.Limits = [0 1];
            app.VolumeSlider_2.ValueChangedFcn = createCallbackFcn(app, @VolumeSlider_2ValueChanged, true);
            app.UploadButton = uibutton(app.FilePanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.StopresumeButton_2 = uibutton(app.FilePanel, 'push');
            app.StopresumeButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopresumeButton_2Pushed, true);
            app.StopresumeButton_2.Text = 'Stop/resume';
            app.SinteticSignalsTab = uitab(app.TabGroup2);
            app.SinteticSignalsTab.Title = 'Sintetic Signals';
            app.StopButton = uibutton(app.SinteticSignalsTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton = uibutton(app.SinteticSignalsTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseResumeButton = uibutton(app.SinteticSignalsTab, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButtonPushed, true);
            app.PauseResumeButton.Text = 'Pause/Resume';
            app.SignalsPanel = uipanel(app.SinteticSignalsTab);
            app.SignalsPanel.Title = 'Signals';
            app.TypeofsignalDropDownLabel = uilabel(app.SignalsPanel);
            app.TypeofsignalDropDownLabel.Text = 'Type of signal';
            app.TypeofsignalDropDown = uidropdown(app.SignalsPanel);
            app.TypeofsignalDropDown.Items = {'Sine', 'Cosine', 'Step', 'Ramp', 'Chirp', 'Square', 'Sawtooth'};
            app.TypeofsignalDropDown.ItemsData = [1 2 3 4 5 6 7];
            app.TypeofsignalDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofsignalDropDownValueChanged, true);
            app.TypeofsignalDropDown.Value = 1;
            app.AmplitudeSpinnerLabel = uilabel(app.SignalsPanel);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.Amplitude = uispinner(app.SignalsPanel);
            app.Amplitude.Limits = [0 Inf];
            app.Amplitude.Value = 1;
            app.InitialFrequencyHzSpinnerLabel = uilabel(app.SignalsPanel);
            app.InitialFrequencyHzSpinnerLabel.Text = 'Initial Frequency [Hz]';
            app.InitialFrequency = uispinner(app.SignalsPanel);
            app.InitialFrequency.Limits = [0 Inf];
            app.InitialFrequency.Value = 1;
            app.FinalFrequencyHzSpinnerLabel = uilabel(app.SignalsPanel);
            app.FinalFrequencyHzSpinnerLabel.Text = 'Final Frequency [Hz]';
            app.FinalFrequency = uispinner(app.SignalsPanel);
            app.FinalFrequency.Limits = [0 Inf];
            app.FinalFrequency.Value = 750;
            app.PhaseSpinnerLabel = uilabel(app.SignalsPanel);
            app.PhaseSpinnerLabel.Text = 'Phase [°]';
            app.Phase = uispinner(app.SignalsPanel);
            app.Phase.Limits = [0 Inf];
            app.Phase.Value = 20;
            app.TimesSpinnerLabel = uilabel(app.SignalsPanel);
            app.TimesSpinnerLabel.Text = 'Time [s]';
            app.Time = uispinner(app.SignalsPanel);
            app.Time.Limits = [0 Inf];
            app.Time.Value = 5;
            app.PlotSignalButton = uibutton(app.SignalsPanel, 'push');
            app.PlotSignalButton.ButtonPushedFcn = createCallbackFcn(app, @generateButtonPushed, true);
            app.PlotSignalButton.Text = 'Generate';
            app.SamplingfrequencySpinnerLabel = uilabel(app.SignalsPanel);
            app.SamplingfrequencySpinnerLabel.Text = 'Sampling frequency';
            app.Samplingfrequency = uispinner(app.SignalsPanel);
            app.Samplingfrequency.Value = 1000;
            app.PlottedSignalPanel = uipanel(app.SinteticSignalsTab);
            app.PlottedSignalPanel.Title = 'Plotted Signal';
            app.UIAxes_scs = uiaxes(app.PlottedSignalPanel);
            title(app.UIAxes_scs, 'Signal')
            xlabel(app.UIAxes_scs, 'X')
            ylabel(app.UIAxes_scs, 'Y')
            zlabel(app.UIAxes_scs, 'Z')
            app.DataAcquisitionTab = uitab(app.TabGroup2);
            app.DataAcquisitionTab.Title = 'Data Acquisition';
            app.UIAxes2 = uiaxes(app.DataAcquisitionTab);
            title(app.UIAxes2, 'Signal')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Temperature')
            zlabel(app.UIAxes2, 'Z')
            app.StartButton = uibutton(app.DataAcquisitionTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RestartButton = uibutton(app.DataAcquisitionTab, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.PortsDropDownLabel = uilabel(app.DataAcquisitionTab);
            app.PortsDropDownLabel.Text = 'Ports';
            app.PortsDropDown = uidropdown(app.DataAcquisitionTab);
            app.PortsDropDown.Items = {''};
            app.PortsDropDown.Value = '';
            app.BaudRateDropDownLabel = uilabel(app.DataAcquisitionTab);
            app.BaudRateDropDownLabel.Text = 'BaudRate';
            app.BaudRateDropDown = uidropdown(app.DataAcquisitionTab);
            app.BaudRateDropDown.Items = {'9600', '19200', '38400', '57600', '115200'};
            app.BaudRateDropDown.Value = '9600';
            app.ExportButton = uibutton(app.DataAcquisitionTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.AcquisitionTimeLabel = uilabel(app.DataAcquisitionTab);
            app.AcquisitionTimeLabel.Text = 'AcquisitionTime';
            app.AcquisitionTimeEditField = uieditfield(app.DataAcquisitionTab, 'numeric');
            app.AcquisitionTimeEditField.ValueChangedFcn = createCallbackFcn(app, @AcquisitionTimeEditFieldValueChanged, true);
            app.StatusLabel = uilabel(app.DataAcquisitionTab);
            app.StatusLabel.Text = 'Status';
            app.StatusLabel_2 = uilabel(app.DataAcquisitionTab);
            app.StatusLabel_2.Text = ' Status';
            app.SecondsLabel = uilabel(app.DataAcquisitionTab);
            app.SecondsLabel.Text = 'Seconds';
            app.DetectportsButton = uibutton(app.DataAcquisitionTab, 'push');
            app.DetectportsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectportsButtonPushed, true);
            app.DetectportsButton.Text = 'Detect ports';
            app.Fs_HzEditField = uieditfield(app.DataAcquisitionTab, 'numeric');
            app.Fs_HzEditField.ValueChangedFcn = createCallbackFcn(app, @Fs_HzEditFieldValueChanged, true);
            app.Fs_HzEditFieldLabel = uilabel(app.DataAcquisitionTab);
            app.Fs_HzEditFieldLabel.Text = 'Fs_Hz';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.TabGroup3 = uitabgroup(app.ProcessingTab);
            app.OperationTab = uitab(app.TabGroup3);
            app.OperationTab.Title = 'Operation';
            app.UIAxes_scs_2 = uiaxes(app.OperationTab);
            title(app.UIAxes_scs_2, 'Signal 1')
            xlabel(app.UIAxes_scs_2, 'X')
            ylabel(app.UIAxes_scs_2, 'Y')
            zlabel(app.UIAxes_scs_2, 'Z')
            app.UIAxes_scs_4 = uiaxes(app.OperationTab);
            title(app.UIAxes_scs_4, 'Result')
            xlabel(app.UIAxes_scs_4, 'X')
            ylabel(app.UIAxes_scs_4, 'Y')
            zlabel(app.UIAxes_scs_4, 'Z')
            app.UIAxes_scs_3 = uiaxes(app.OperationTab);
            title(app.UIAxes_scs_3, 'Signal 1')
            xlabel(app.UIAxes_scs_3, 'X')
            ylabel(app.UIAxes_scs_3, 'Y')
            zlabel(app.UIAxes_scs_3, 'Z')
            app.InputSignalPanel_2 = uipanel(app.OperationTab);
            app.InputSignalPanel_2.Title = 'Input Signal';
            app.SumButton = uibutton(app.InputSignalPanel_2, 'push');
            app.SumButton.ButtonPushedFcn = createCallbackFcn(app, @SumButtonPushed, true);
            app.SumButton.Text = 'Sum';
            app.MultButton = uibutton(app.InputSignalPanel_2, 'push');
            app.MultButton.ButtonPushedFcn = createCallbackFcn(app, @MultButtonPushed, true);
            app.MultButton.Text = 'Mult';
            app.FsEditFieldLabel = uilabel(app.InputSignalPanel_2);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.InputSignalPanel_2, 'numeric');
            app.FsEditField.Editable = 'off';
            app.TimeEditFieldLabel = uilabel(app.InputSignalPanel_2);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.InputSignalPanel_2, 'numeric');
            app.TimeEditField.Editable = 'off';
            app.FsEditField_2 = uieditfield(app.InputSignalPanel_2, 'numeric');
            app.FsEditField_2.Editable = 'off';
            app.FsEditField_2Label = uilabel(app.InputSignalPanel_2);
            app.FsEditField_2Label.Text = 'Fs';
            app.TimeEditField_2Label = uilabel(app.InputSignalPanel_2);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.InputSignalPanel_2, 'numeric');
            app.TimeEditField_2.Editable = 'off';
            app.InputSignal2DropDownLabel = uilabel(app.InputSignalPanel_2);
            app.InputSignal2DropDownLabel.Text = 'Input Signal 2';
            app.InputSignal2DropDown = uidropdown(app.InputSignalPanel_2);
            app.InputSignal2DropDown.Items = {'- Select', 'Sine', 'Cosine', 'Step', 'Ramp', 'Chirp', 'Square', 'Sawtooth', 'Record', 'File', 'Acquisition'};
            app.InputSignal2DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.InputSignal2DropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignal2DropDownValueChanged, true);
            app.InputSignal2DropDown.Value = '1';
            app.InputSignal1DropDownLabel = uilabel(app.InputSignalPanel_2);
            app.InputSignal1DropDownLabel.Text = 'Input Signal 1';
            app.InputSignal1DropDown = uidropdown(app.InputSignalPanel_2);
            app.InputSignal1DropDown.Items = {'- Select', 'Sine', 'Cosine', 'Step', 'Ramp', 'Chirp', 'Square', 'Sawtooth', 'Record', 'File', 'Acquisition'};
            app.InputSignal1DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.InputSignal1DropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignal1DropDownValueChanged, true);
            app.InputSignal1DropDown.Value = '1';
            app.InputSignalPanel_3 = uipanel(app.OperationTab);
            app.InputSignalPanel_3.Title = 'Input Signal';
            app.SelectSignalDropDownLabel = uilabel(app.InputSignalPanel_3);
            app.SelectSignalDropDownLabel.Text = 'Select Signal';
            app.SelectSignalDropDown = uidropdown(app.InputSignalPanel_3);
            app.SelectSignalDropDown.Items = {'- Select', 'Input Signal 1', 'Input Signal 2'};
            app.SelectSignalDropDown.ItemsData = {'1', '2', '3'};
            app.SelectSignalDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectSignalDropDownOpening, true);
            app.SelectSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSignalDropDownValueChanged, true);
            app.SelectSignalDropDown.Value = '1';
            app.StereotomonoButton = uibutton(app.InputSignalPanel_3, 'push');
            app.StereotomonoButton.ButtonPushedFcn = createCallbackFcn(app, @StereotomonoButtonPushed, true);
            app.StereotomonoButton.Text = 'Stereo to mono';
            app.InvertSignalButton = uibutton(app.InputSignalPanel_3, 'push');
            app.InvertSignalButton.ButtonPushedFcn = createCallbackFcn(app, @InvertSignalButtonPushed, true);
            app.InvertSignalButton.Text = 'Invert Signal';
            app.MonotoStereoButton = uibutton(app.InputSignalPanel_3, 'push');
            app.MonotoStereoButton.ButtonPushedFcn = createCallbackFcn(app, @MonotoStereoButtonPushed, true);
            app.MonotoStereoButton.Text = 'Mono to Stereo';
            app.PlayButton_6 = uibutton(app.OperationTab, 'push');
            app.PlayButton_6.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_6Pushed, true);
            app.PlayButton_6.Text = 'Play';
            app.StopButton_6 = uibutton(app.OperationTab, 'push');
            app.StopButton_6.ButtonPushedFcn = createCallbackFcn(app, @StopButton_6Pushed, true);
            app.StopButton_6.Text = 'Stop';
            app.EchoTab = uitab(app.TabGroup3);
            app.EchoTab.Title = 'Echo';
            app.UIAxes_scs_15 = uiaxes(app.EchoTab);
            title(app.UIAxes_scs_15, 'Signal')
            xlabel(app.UIAxes_scs_15, 'X')
            ylabel(app.UIAxes_scs_15, 'Y')
            zlabel(app.UIAxes_scs_15, 'Z')
            app.UIAxes_scs_16 = uiaxes(app.EchoTab);
            title(app.UIAxes_scs_16, 'Signal')
            xlabel(app.UIAxes_scs_16, 'X')
            ylabel(app.UIAxes_scs_16, 'Y')
            zlabel(app.UIAxes_scs_16, 'Z')
            app.Panel = uipanel(app.EchoTab);
            app.Panel.Title = 'Panel';
            app.Plot_echo = uibutton(app.Panel, 'push');
            app.Plot_echo.ButtonPushedFcn = createCallbackFcn(app, @Plot_echoButtonPushed, true);
            app.Plot_echo.Text = 'Generate';
            app.Play_echo = uibutton(app.Panel, 'push');
            app.Play_echo.ButtonPushedFcn = createCallbackFcn(app, @Play_echoButtonPushed, true);
            app.Play_echo.Text = 'Play';
            app.Stop_echo = uibutton(app.Panel, 'push');
            app.Stop_echo.ButtonPushedFcn = createCallbackFcn(app, @Stop_echoButtonPushed, true);
            app.Stop_echo.Text = 'Stop';
            app.InputSignalDropDown_2Label = uilabel(app.Panel);
            app.InputSignalDropDown_2Label.Text = 'Input Signal';
            app.InputSignalDropDown_2 = uidropdown(app.Panel);
            app.InputSignalDropDown_2.Items = {'- Select', 'Sine', 'Cosine', 'Step', 'Ramp', 'Chirp', 'Square', 'Sawtooth', 'Record', 'File', 'Acquisition'};
            app.InputSignalDropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.InputSignalDropDown_2.ValueChangedFcn = createCallbackFcn(app, @InputSignalDropDown_2ValueChanged2, true);
            app.InputSignalDropDown_2.Value = '1';
            app.A2SpinnerLabel = uilabel(app.Panel);
            app.A2SpinnerLabel.Text = 'A2 [%]';
            app.A1Label = uilabel(app.Panel);
            app.A1Label.Text = 'A1 [%]';
            app.k1sLabel = uilabel(app.Panel);
            app.k1sLabel.Text = 'k1 [s]';
            app.k2sLabel = uilabel(app.Panel);
            app.k2sLabel.Text = 'k2 [s]';
            app.k2 = uispinner(app.Panel);
            app.k2.Value = 4;
            app.k1 = uispinner(app.Panel);
            app.k1.Value = 2;
            app.A2 = uispinner(app.Panel);
            app.A2.Value = 40;
            app.A1 = uispinner(app.Panel);
            app.A1.Value = 60;
            app.A3Label = uilabel(app.Panel);
            app.A3Label.Text = 'A3 [%]';
            app.A3 = uispinner(app.Panel);
            app.A3.Value = 20;
            app.k3msLabel = uilabel(app.Panel);
            app.k3msLabel.Text = 'k3 [s]';
            app.k3 = uispinner(app.Panel);
            app.k3.Value = 6;
            app.FourierTab = uitab(app.TabGroup3);
            app.FourierTab.Title = 'Fourier';
            app.UIAxes_scs_11 = uiaxes(app.FourierTab);
            title(app.UIAxes_scs_11, 'Magnitude')
            xlabel(app.UIAxes_scs_11, 'X')
            ylabel(app.UIAxes_scs_11, 'Y')
            zlabel(app.UIAxes_scs_11, 'Z')
            app.UIAxes_scs_12 = uiaxes(app.FourierTab);
            title(app.UIAxes_scs_12, 'Phase')
            xlabel(app.UIAxes_scs_12, 'X')
            ylabel(app.UIAxes_scs_12, 'Y')
            zlabel(app.UIAxes_scs_12, 'Z')
            app.UIAxes_scs_13 = uiaxes(app.FourierTab);
            title(app.UIAxes_scs_13, 'Spectogram')
            xlabel(app.UIAxes_scs_13, 'X')
            ylabel(app.UIAxes_scs_13, 'Y')
            zlabel(app.UIAxes_scs_13, 'Z')
            app.UIAxes_scs_14 = uiaxes(app.FourierTab);
            title(app.UIAxes_scs_14, 'Filtered Signal')
            xlabel(app.UIAxes_scs_14, 'X')
            ylabel(app.UIAxes_scs_14, 'Y')
            zlabel(app.UIAxes_scs_14, 'Z')
            app.Panel2 = uipanel(app.FourierTab);
            app.Panel2.Title = 'Panel2';
            app.MagnitudeButton = uibutton(app.Panel2, 'push');
            app.MagnitudeButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudeButtonPushed, true);
            app.MagnitudeButton.Text = 'Magnitude';
            app.PhaseButton = uibutton(app.Panel2, 'push');
            app.PhaseButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseButtonPushed, true);
            app.PhaseButton.Text = 'Phase';
            app.SpectogramButton = uibutton(app.Panel2, 'push');
            app.SpectogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectogramButtonPushed, true);
            app.SpectogramButton.Text = 'Spectogram';
            app.SelectsignalDropDownLabel = uilabel(app.Panel2);
            app.SelectsignalDropDownLabel.Text = 'Select signal';
            app.SelectsignalDropDown = uidropdown(app.Panel2);
            app.SelectsignalDropDown.Items = {'- Select', 'Input Signal 1', 'Input Signal 2', 'Output Signal 1', 'Output Signal 2'};
            app.SelectsignalDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.SelectsignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectsignalDropDownValueChanged, true);
            app.SelectsignalDropDown.Value = '1';
            app.Panel2_2 = uipanel(app.FourierTab);
            app.Panel2_2.Title = 'Panel2';
            app.SelectsignalDropDown_2Label = uilabel(app.Panel2_2);
            app.SelectsignalDropDown_2Label.Text = 'Select signal';
            app.SelectsignalDropDown_2 = uidropdown(app.Panel2_2);
            app.SelectsignalDropDown_2.Items = {'- Select', 'Low Pass', 'High Pass', 'Band Pass', 'Band Stop'};
            app.SelectsignalDropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.SelectsignalDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectsignalDropDown_2ValueChanged, true);
            app.SelectsignalDropDown_2.Value = '1';
            app.ApplyFilterButton = uibutton(app.Panel2_2, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.GridLayout = uigridlayout(app.FilteringTab);
            app.UIAxes_scs_17 = uiaxes(app.GridLayout);
            title(app.UIAxes_scs_17, 'Signal 1')
            xlabel(app.UIAxes_scs_17, 'X')
            ylabel(app.UIAxes_scs_17, 'Y')
            zlabel(app.UIAxes_scs_17, 'Z')
            app.UIAxes_scs_18 = uiaxes(app.GridLayout);
            title(app.UIAxes_scs_18, 'Signal 1')
            xlabel(app.UIAxes_scs_18, 'X')
            ylabel(app.UIAxes_scs_18, 'Y')
            zlabel(app.UIAxes_scs_18, 'Z')
            app.SignalFilteringPanel = uipanel(app.GridLayout);
            app.SignalFilteringPanel.Title = 'Signal Filtering';
            app.FIRhnButton_2 = uibutton(app.SignalFilteringPanel, 'push');
            app.FIRhnButton_2.ButtonPushedFcn = createCallbackFcn(app, @FIRhnButton_2Pushed, true);
            app.FIRhnButton_2.Text = 'FIR h(n) ';
            app.IIRakbkButton_2 = uibutton(app.SignalFilteringPanel, 'push');
            app.IIRakbkButton_2.ButtonPushedFcn = createCallbackFcn(app, @IIRakbkButton_2Pushed, true);
            app.IIRakbkButton_2.Text = 'IIR a(k), b(k)';
            app.WindowSizeSpinnerLabel = uilabel(app.SignalFilteringPanel);
            app.WindowSizeSpinnerLabel.Text = 'Window Size';
            app.WindowSize = uispinner(app.SignalFilteringPanel);
            app.InputSignal1DropDown_2Label = uilabel(app.SignalFilteringPanel);
            app.InputSignal1DropDown_2Label.Text = 'Input Signal 1';
            app.InputSignal1DropDown_2 = uidropdown(app.SignalFilteringPanel);
            app.InputSignal1DropDown_2.Items = {'- Select', 'Sine', 'Cosine', 'Step', 'Ramp', 'Chirp', 'Square', 'Sawtooth', 'Record', 'File', 'Acquisition'};
            app.InputSignal1DropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.InputSignal1DropDown_2.ValueChangedFcn = createCallbackFcn(app, @InputSignal1DropDown_2ValueChanged, true);
            app.InputSignal1DropDown_2.Value = '1';
            app.FilterDropDownLabel = uilabel(app.SignalFilteringPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.SignalFilteringPanel);
            app.FilterDropDown.Items = {'- Select', 'FIR 1', 'FIR 2', 'FIR 3', 'FIR 4', 'FIR hn', 'IIR 1', 'IIR 2', 'IIR 3', 'IIR 4', 'IIR k'};
            app.FilterDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = '1';
            app.SelectfilterDropDownLabel = uilabel(app.SignalFilteringPanel);
            app.SelectfilterDropDownLabel.Text = 'Select filter';
            app.SelectfilterDropDown = uidropdown(app.SignalFilteringPanel);
            app.SelectfilterDropDown.Items = {'- Select', 'Low Pass', 'High Pass', 'Band Pass', 'Band Stop'};
            app.SelectfilterDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.SelectfilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectfilterDropDownValueChanged, true);
            app.SelectfilterDropDown.Value = '1';
            app.InputSignalPanel = uipanel(app.GridLayout);
            app.InputSignalPanel.Title = 'Input Signal';
            app.PlayButton_3 = uibutton(app.InputSignalPanel, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Text = 'Play';
            app.StopButton_4 = uibutton(app.InputSignalPanel, 'push');
            app.StopButton_4.ButtonPushedFcn = createCallbackFcn(app, @StopButton_4Pushed, true);
            app.StopButton_4.Text = 'Stop';
            app.FilteredSignalPanel = uipanel(app.GridLayout);
            app.FilteredSignalPanel.Title = 'Filtered Signal';
            app.PlayButton_4 = uibutton(app.FilteredSignalPanel, 'push');
            app.PlayButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_4Pushed, true);
            app.PlayButton_4.Text = 'Play';
            app.StopButton_5 = uibutton(app.FilteredSignalPanel, 'push');
            app.StopButton_5.ButtonPushedFcn = createCallbackFcn(app, @StopButton_5Pushed, true);
            app.StopButton_5.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P1_DSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end