classdef ProyectoSistemas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabMaster                       matlab.ui.container.TabGroup
        AdministraciondeSealTab         matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        SignalsAdminTab                 matlab.ui.container.TabGroup
        MicrofonoTab_2                  matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        GrabarButton                    matlab.ui.control.StateButton
        GraficaAudioInput               matlab.ui.control.UIAxes
        SimuladaTab                     matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout21                    matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        SimularSealButton               matlab.ui.control.Button
        SealesSimuladasButtonGroup      matlab.ui.container.ButtonGroup
        SincButton                      matlab.ui.control.RadioButton
        ChirpButton                     matlab.ui.control.RadioButton
        EscalonButton                   matlab.ui.control.RadioButton
        TriangularButton                matlab.ui.control.RadioButton
        RampaButton                     matlab.ui.control.RadioButton
        CosenoButton                    matlab.ui.control.RadioButton
        SenoButton                      matlab.ui.control.RadioButton
        DientedeSierraButton            matlab.ui.control.RadioButton
        ArchivoTab                      matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        CargarArchivoButton             matlab.ui.control.Button
        PathLabel                       matlab.ui.control.Label
        TarjetaTab                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        AdquirirSealButton              matlab.ui.control.Button
        ListTajetas                     matlab.ui.control.DropDown
        GridLayout17                    matlab.ui.container.GridLayout
        CheckSignal2                    matlab.ui.control.CheckBox
        GridLayout20                    matlab.ui.container.GridLayout
        SealDeentradaButtonGroup        matlab.ui.container.ButtonGroup
        TarjetaButton                   matlab.ui.control.RadioButton
        ArchivoButton                   matlab.ui.control.RadioButton
        SimuladaButton                  matlab.ui.control.RadioButton
        MicrofonoButton                 matlab.ui.control.RadioButton
        SignalParametersTab             matlab.ui.container.TabGroup
        MicrofonoTab                    matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        ndeInicioTxtMicrofono           matlab.ui.control.NumericEditField
        nInicialEditFieldLabel          matlab.ui.control.Label
        TiempoGrabsTxt                  matlab.ui.control.NumericEditField
        TiempoGrabsEditFieldLabel       matlab.ui.control.Label
        NmCanalesTxt                    matlab.ui.control.NumericEditField
        NmCanalesEditFieldLabel         matlab.ui.control.Label
        BitsMuestraTxt                  matlab.ui.control.NumericEditField
        BitsMuestraEditFieldLabel       matlab.ui.control.Label
        FrecuenciadeMuestreoTxt         matlab.ui.control.NumericEditField
        FrecuenciadeMuestreoEditFieldLabel  matlab.ui.control.Label
        SimuladaTab_2                   matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        ndeFinTxt                       matlab.ui.control.NumericEditField
        ndeFinEditField_3Label          matlab.ui.control.Label
        ndeInicioTxt                    matlab.ui.control.NumericEditField
        ndeInicioEditField_3Label       matlab.ui.control.Label
        DesplazamientoTxt               matlab.ui.control.NumericEditField
        DesplazamientoEditField_3Label  matlab.ui.control.Label
        FaseTxt                         matlab.ui.control.NumericEditField
        FaseradEditField_3Label         matlab.ui.control.Label
        FrecuenciaMuestreoTxt           matlab.ui.control.NumericEditField
        FrecuenciaMuestreoEditField_3Label  matlab.ui.control.Label
        FrecuenciaAnlogaTxt             matlab.ui.control.NumericEditField
        FrecuenciaAnlogaEditField_3Label  matlab.ui.control.Label
        Gananciatxt                     matlab.ui.control.NumericEditField
        GananciaLabel                   matlab.ui.control.Label
        ArchivoTab_2                    matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        InfoLabel                       matlab.ui.control.Label
        TarjetaTab_2                    matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        InputButtonGroup                matlab.ui.container.ButtonGroup
        A7Button                        matlab.ui.control.RadioButton
        A6Button                        matlab.ui.control.RadioButton
        A5Button                        matlab.ui.control.RadioButton
        A4Button                        matlab.ui.control.RadioButton
        A3Button                        matlab.ui.control.RadioButton
        A2Button                        matlab.ui.control.RadioButton
        A1Button                        matlab.ui.control.RadioButton
        A0Button                        matlab.ui.control.RadioButton
        GridLayout23                    matlab.ui.container.GridLayout
        ndeInicioTxtTarjeta             matlab.ui.control.NumericEditField
        ndeinicioEditFieldLabel         matlab.ui.control.Label
        FrecuenciademuestreoTxt         matlab.ui.control.NumericEditField
        FrecuenciaEditFieldLabel        matlab.ui.control.Label
        TiempoTxt                       matlab.ui.control.NumericEditField
        TiempoEditFieldLabel            matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        ReproducirAudioButton           matlab.ui.control.Button
        GridLayout11                    matlab.ui.container.GridLayout
        GraficoFrecuencia               matlab.ui.control.UIAxes
        GraficoTiempo                   matlab.ui.control.UIAxes
        ManipulaciondesealTab           matlab.ui.container.Tab
        GridLayout24                    matlab.ui.container.GridLayout
        GridLayout29                    matlab.ui.container.GridLayout
        GraficaSinalManipulada          matlab.ui.control.UIAxes
        GraficaSignalInicial            matlab.ui.control.UIAxes
        GridLayout28                    matlab.ui.container.GridLayout
        ManipulacionTab                 matlab.ui.container.TabGroup
        PreprocesamientoyFiltradoTab    matlab.ui.container.Tab
        GridLayout24_2                  matlab.ui.container.GridLayout
        GridLayout27                    matlab.ui.container.GridLayout
        bkTxt                           matlab.ui.control.EditField
        bkEditFieldLabel                matlab.ui.control.Label
        akTxt                           matlab.ui.control.EditField
        akEditFieldLabel                matlab.ui.control.Label
        hnTxt                           matlab.ui.control.EditField
        hnLabel                         matlab.ui.control.Label
        GuardarSealButton               matlab.ui.control.Button
        GridLayout25                    matlab.ui.container.GridLayout
        GridLayout41                    matlab.ui.container.GridLayout
        FiltroCbox                      matlab.ui.control.DropDown
        ProcesodeFIltradosDropDownLabel  matlab.ui.control.Label
        NormalizacionCbox               matlab.ui.control.DropDown
        NormalizacionesDropDownLabel    matlab.ui.control.Label
        EliminaciondedatosNanoInfCheckBox  matlab.ui.control.CheckBox
        Operacionescon1sealTab          matlab.ui.container.Tab
        GridLayout30                    matlab.ui.container.GridLayout
        GridLayout33                    matlab.ui.container.GridLayout
        ConversionButton                matlab.ui.container.ButtonGroup
        ConvertiraEstereofnicaButton    matlab.ui.control.RadioButton
        ConvertiraMonofnicaButton       matlab.ui.control.RadioButton
        SinCambiosButton_3              matlab.ui.control.RadioButton
        GuardarcambiosdesealButton      matlab.ui.control.Button
        InvertirSealCheckBox            matlab.ui.control.CheckBox
        GridLayout31_2                  matlab.ui.container.GridLayout
        DesplazaminetoButton            matlab.ui.container.ButtonGroup
        SinCambiosButton_2              matlab.ui.control.RadioButton
        AtrasoButton                    matlab.ui.control.RadioButton
        AdelantoButton                  matlab.ui.control.RadioButton
        GridLayout32_2                  matlab.ui.container.GridLayout
        DKtxt                           matlab.ui.control.NumericEditField
        DKEditFieldLabel                matlab.ui.control.Label
        DesplazamientoLabel             matlab.ui.control.Label
        GridLayout31                    matlab.ui.container.GridLayout
        CambioMuestreo                  matlab.ui.container.ButtonGroup
        SinCambiosButton                matlab.ui.control.RadioButton
        SobreMuestreoButton             matlab.ui.control.RadioButton
        SubMuestreoButton               matlab.ui.control.RadioButton
        GridLayout32                    matlab.ui.container.GridLayout
        FKtxt                           matlab.ui.control.NumericEditField
        FKEditFieldLabel                matlab.ui.control.Label
        FrecuenciadeMuestreoLabel       matlab.ui.control.Label
        OperacionescondossealesTab      matlab.ui.container.Tab
        GridLayout34                    matlab.ui.container.GridLayout
        GridLayout40                    matlab.ui.container.GridLayout
        A2Txt                           matlab.ui.control.NumericEditField
        A2Label                         matlab.ui.control.Label
        A1Txt                           matlab.ui.control.NumericEditField
        A1Label                         matlab.ui.control.Label
        ProducirEcoCheckBox             matlab.ui.control.CheckBox
        GridLayout36                    matlab.ui.container.GridLayout
        AmplitudLineal_S                matlab.ui.control.Slider
        AmplitudLinealSliderLabel       matlab.ui.control.Label
        ReproducirButton                matlab.ui.control.Button
        Amplitud                        matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel          matlab.ui.control.Label
        AjustedeVolumenLabel            matlab.ui.control.Label
        GridLayout35                    matlab.ui.container.GridLayout
        CrearsealEsteroButton           matlab.ui.control.Button
        MultiplicarLasdossealesButton   matlab.ui.control.Button
        SumarlasdossealesButton         matlab.ui.control.Button
        ManipulacionenfrecuenciaTab     matlab.ui.container.Tab
        GridLayout37                    matlab.ui.container.GridLayout
        GridLayout39                    matlab.ui.container.GridLayout
        FaseAxes                        matlab.ui.control.UIAxes
        MagnitudAxes                    matlab.ui.control.UIAxes
        GraficaFrecuenciaSalida         matlab.ui.control.UIAxes
        GraficaFrecuenciaEntrada        matlab.ui.control.UIAxes
        GraficaTiempoSalida             matlab.ui.control.UIAxes
        GraficaTiempoEntrada            matlab.ui.control.UIAxes
        GridLayout38                    matlab.ui.container.GridLayout
        TransformadadeFurierButton      matlab.ui.control.Button
        ObservarEspectrogramaButton     matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
    end

    
    properties (Access = private)
        A; % Ganancia de la exponencial
        Fa; % Frecuencia análoga
        Fs; % Frecuencia de muestreo
        Fs1;
        Fs2;
        Fase; % Fase de la señal análoga (Rad)
        n0;
        ni;
        ni1;
        ni2;
        nf;
        Ts; %Periodo de muestreo  
        n; %Instantes de tiempo
        Signal; %Señal de radiobuton
        SignalNueva;
        S1;
        S2;
        S1Inicial;
        S2Inicial;
        
        %Variables para adquirir datos tarjeta
        Analog = 0;
        
        %tiempo de reproduccion de audios
        TiempoAudio=0;
        player;
        %Variable para convolucion
        Lh;   
        nhi;  
        nh;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Inicializacion de simulador
            app.A=app.Gananciatxt.Value; % Ganancia de la exponencial
            app.Fa=app.FrecuenciaAnlogaTxt.Value;% Frecuencia análoga
            app.Fs=app.FrecuenciaMuestreoTxt.Value; % Frecuencia de muestreo
            
            app.Fase=app.FaseTxt.Value; % Fase de la señal análoga (Rad)
            
            app.n0=app.DesplazamientoTxt.Value;
            app.ni=app.ndeInicioTxt.Value;
            app.nf=app.ndeFinTxt.Value;

            app.Ts=1/app.Fs; %Periodo de muestreo
            
            app.n=app.ni:app.nf; %Instantes de tiempo
            app.Signal = app.A*sin(2*pi*app.Fa*(app.n-app.n0)/app.Fs+ app.Fase);

            % Obtener los dispositivos de adquisición de datos
            devices = daq.getDevices;
            % Obtener los nombres de los dispositivos
            deviceNames = {devices.ID};
            % Llena el cbox desplegable con los nombres de los dispositivos
            app.ListTajetas.Items = deviceNames;


        end

        % Button pushed function: CargarArchivoButton
        function CargarArchivoButtonPushed(app, event)
            [NombreArch, pathname] = uigetfile('*.*', 'Seleccionar archivo');
            if isequal(NombreArch, 0)
                disp('No se seleccionó ningún archivo');
                
            else
                app.PathLabel.Text = fullfile(pathname, NombreArch);

                NArchivo_Tipo = split(NombreArch, '.');
                if strcmp(NArchivo_Tipo(2), 'wav')

                    [app.Signal,app.Fs] = audioread(fullfile(pathname,NombreArch)); % Leer el archivo de audio
                    % Información del archivo de audio.
                    info = audioinfo(NombreArch);
                    % Mostrar la información en el cuadro de texto
                    app.InfoLabel.Text = sprintf('Nombre: %s\nDuración: %f segundos\nFrecuencia de muestreo: %d Hz\nNumero de canales: %d\nCantidad de Bits: %d\nTitle: %s\nComentarios: %s\nArtista: %s\n',info.Filename, info.TotalSamples/info.SampleRate, info.SampleRate, info.NumChannels, info.BitsPerSample, info.Title, info.Comment, info.Artist);
        
                elseif strcmp(NArchivo_Tipo(2), 'txt')

                    fileID = fopen(NombreArch,'r');
                    app.Signal = fscanf(fileID,'%f');
                    fclose(fileID);

                    app.InfoLabel.Text = sprintf('Nombre: %s\nRuta: %s',NombreArch, app.PathLabel.Text);
                    
                end

                app.n = 0:length(app.Signal)-1;
                plot(app.GraficoTiempo, app.n, app.Signal);
                f = app.Fs*(0:length(app.Signal)-1)/length(app.Signal);
                plot(app.GraficoFrecuencia, f, abs(app.Signal));

                if app.CheckSignal2.Value == false
                    app.S1 = app.Signal;
                    app.ni1 = app.ni;
                    app.Fs1 = app.Fs;
                    app.S1Inicial = app.S1;
                else
                    app.S2 = app.Signal;
                    app.ni2 = app.ni;
                    app.Fs1 = app.Fs;
                    app.S2Inicial = app.S2;
                end
            end


        end

        % Selection changed function: SealesSimuladasButtonGroup
        function SealesSimuladasButtonGroupSelectionChanged(app, event)
            selectedButton = app.SealesSimuladasButtonGroup.SelectedObject;% Funcion para poner valores de señal simulada
            
            %Inicializacion de simulador
            app.A=app.Gananciatxt.Value; % Ganancia de la exponencial
            app.Fa=app.FrecuenciaAnlogaTxt.Value;% Frecuencia análoga
            app.Fs=app.FrecuenciaMuestreoTxt.Value; % Frecuencia de muestreo
            
            app.Fase=app.FaseTxt.Value; % Fase de la señal análoga (Rad)
            
            app.n0=app.DesplazamientoTxt.Value;
            app.ni=app.ndeInicioTxt.Value;
            app.nf=app.ndeFinTxt.Value;

            app.Ts=1/app.Fs; %Periodo de muestreo
            
            app.n=app.ni:app.nf; %Instantes de tiempo


            if app.SenoButton == selectedButton
                
                app.Signal= app.A*sin(2*pi*app.Fa*(app.n-app.n0)/app.Fs+ app.Fase); % Seno

            elseif app.CosenoButton == selectedButton

                app.Signal= app.A*cos(2*pi*app.Fa*(app.n-app.n0)/app.Fs+ app.Fase); % Coseno

            elseif app.DientedeSierraButton == selectedButton

                app.Signal= app.A*sawtooth((2*pi*app.Fa*(app.n-app.n0)/app.Fs+ app.Fase)); % Diente de sierra

            elseif app.RampaButton == selectedButton
    
                app.Signal= app.A*sawtooth((2*pi*app.Fa*(app.n-app.n0)/app.Fs+ app.Fase),1); % Rampa error

            elseif app.TriangularButton == selectedButton

                app.Signal= app.A*sawtooth((2*pi*app.Fa*(app.n-app.n0)/app.Fs+ app.Fase),0.5); % Triangular

            elseif app.EscalonButton == selectedButton

                app.Signal= app.A*heaviside(2*pi*app.Fa*(app.n-app.n0)/app.Fs+ app.Fase); % Escalon

            elseif app.ChirpButton == selectedButton

                app.Signal= app.A*chirp(2*pi*app.Fa*(app.n-app.n0)/app.Fs+ app.Fase); % Chirp

            elseif app.SincButton == selectedButton

                app.Signal= app.A*sinc(2*pi*app.Fa*(app.n-app.n0)/app.Fs+ app.Fase); % Sinc

            end

            if app.CheckSignal2.Value == false
                app.S1 = app.Signal;
            else
                app.S2 = app.Signal;
            end

        end

        % Button pushed function: SimularSealButton
        function SimularSealButtonPushed(app, event)
            
            SealesSimuladasButtonGroupSelectionChanged(app, event)
            plot(app.GraficoTiempo, app.n, app.Signal);
            f = app.Fs*(0:length(app.Signal)-1)/length(app.Signal);
            plot(app.GraficoFrecuencia, f, abs(app.Signal));

            if app.CheckSignal2.Value == false
                app.S1 = app.Signal;
                app.ni1 = app.ni;
                app.Fs1 = app.Fs;
                app.S1Inicial = app.S1;
            else
                app.S2 = app.Signal;
                app.ni2 = app.ni;
                app.Fs2 = app.Fs;
                app.S2Inicial = app.S2;
            end
            
        end

        % Selection changed function: SealDeentradaButtonGroup
        function SealDeentradaButtonGroupSelectionChanged(app, event)
            selectedButton = app.SealDeentradaButtonGroup.SelectedObject;
            if app.MicrofonoButton == selectedButton
                %Activar
                app.FrecuenciadeMuestreoTxt.Enable ="on";
                app.BitsMuestraTxt.Enable ="on";
                app.NmCanalesTxt.Enable ="on";
                app.TiempoGrabsTxt.Enable ="on";
                app.GrabarButton.Enable ="on";
                
                
                %DesActivar
                app.Gananciatxt.Enable = "off";
                app.FrecuenciaAnlogaTxt.Enable = "off";
                app.FrecuenciaMuestreoTxt.Enable = "off";
                app.FaseTxt.Enable = "off";
                app.DesplazamientoTxt.Enable = "off";
                app.ndeInicioTxt.Enable = "off";
                app.ndeFinTxt.Enable = "off";
                app.SimularSealButton.Enable = "off";
                app.CargarArchivoButton.Enable = "off";
                app.ListTajetas.Enable = "off";
                app.AdquirirSealButton.Enable = "off";

                app.SignalsAdminTab.SelectedTab = app.SignalsAdminTab.Children(1);
                app.SignalParametersTab.SelectedTab = app.SignalParametersTab.Children(1);
                
            elseif app.SimuladaButton == selectedButton

                %Activar
                app.Gananciatxt.Enable = "on";
                app.FrecuenciaAnlogaTxt.Enable = "on";
                app.FrecuenciaMuestreoTxt.Enable = "on";
                app.FaseTxt.Enable = "on";
                app.DesplazamientoTxt.Enable = "on";
                app.ndeInicioTxt.Enable = "on";
                app.ndeFinTxt.Enable = "on";
                app.SimularSealButton.Enable = "on";
                
                %DesActivar
                app.FrecuenciadeMuestreoTxt.Enable ="off";
                app.BitsMuestraTxt.Enable ="off";
                app.NmCanalesTxt.Enable ="off";
                app.TiempoGrabsTxt.Enable ="off";
                app.CargarArchivoButton.Enable = "off";
                app.ListTajetas.Enable = "off";
                app.GrabarButton.Enable ="off";
                app.AdquirirSealButton.Enable = "off";

                app.SignalsAdminTab.SelectedTab = app.SignalsAdminTab.Children(2);
                app.SignalParametersTab.SelectedTab = app.SignalParametersTab.Children(2);

            elseif app.ArchivoButton == selectedButton 
               %Activar
               app.CargarArchivoButton.Enable = "on";
                
                %DesActivar
                app.Gananciatxt.Enable = "off";
                app.FrecuenciaAnlogaTxt.Enable = "off";
                app.FrecuenciaMuestreoTxt.Enable = "off";
                app.FaseTxt.Enable = "off";
                app.DesplazamientoTxt.Enable = "off";
                app.ndeInicioTxt.Enable = "off";
                app.ndeFinTxt.Enable = "off";
                app.SimularSealButton.Enable = "off";
                app.FrecuenciadeMuestreoTxt.Enable ="off";
                app.BitsMuestraTxt.Enable ="off";
                app.NmCanalesTxt.Enable ="off";
                app.TiempoGrabsTxt.Enable ="off";
                app.GrabarButton.Enable ="off";
                app.ListTajetas.Enable = "off";
                app.AdquirirSealButton.Enable = "off";

                app.SignalsAdminTab.SelectedTab = app.SignalsAdminTab.Children(3);
                app.SignalParametersTab.SelectedTab = app.SignalParametersTab.Children(3);

            elseif app.TarjetaButton == selectedButton
                %Activar
                app.ListTajetas.Enable = "on";
                app.AdquirirSealButton.Enable = "on";
                app.FrecuenciademuestreoTxt.Enable = "on";
                app.TiempoTxt.Enable = "on";
                
                
                %DesActivar
                app.Gananciatxt.Enable = "off";
                app.FrecuenciaAnlogaTxt.Enable = "off";
                app.FrecuenciaMuestreoTxt.Enable = "off";
                app.FaseTxt.Enable = "off";
                app.DesplazamientoTxt.Enable = "off";
                app.ndeInicioTxt.Enable = "off";
                app.ndeFinTxt.Enable = "off";
                app.SimularSealButton.Enable = "off";
                app.FrecuenciadeMuestreoTxt.Enable ="off";
                app.BitsMuestraTxt.Enable ="off";
                app.NmCanalesTxt.Enable ="off";
                app.TiempoGrabsTxt.Enable ="off";
                app.GrabarButton.Enable ="off";
                app.CargarArchivoButton.Enable = "off";

                app.SignalsAdminTab.SelectedTab = app.SignalsAdminTab.Children(4);
                app.SignalParametersTab.SelectedTab = app.SignalParametersTab.Children(4);
            end
        end

        % Value changed function: GrabarButton
        function GrabarButtonValueChanged(app, event)
            value = app.GrabarButton.Value;
            app.Fs=app.FrecuenciadeMuestreoTxt.Value;    % Frecuencia de muestreo   
            nBit1=app.BitsMuestraTxt.Value;   % Número de bits por muestra
            nChan1=app.NmCanalesTxt.Value;   % Número de canales
            tRec1=app.TiempoGrabsTxt.Value;    % Tiempo de grabación en s.
            app.ni = app.ndeInicioTxtMicrofono.Value;
            countName = 1;

            aud1 = audiorecorder(app.Fs,nBit1,nChan1); % Crear un objeto audiorecorder
            
            disp('Iniciando la grabación...');
            
            recordblocking(aud1, tRec1); % Grabar  audio
            
            disp('Fin de la grabación.');
            
            app.Signal = getaudiodata(aud1); % Obtener los datos de la señal x1
            
            app.n = app.ni:(length(app.Signal)-1)+app.ni;
           
            plot(app.GraficaAudioInput,app.n,app.Signal)

            plot(app.GraficoTiempo,app.n,app.Signal)
            f = app.Fs*(0:length(app.Signal)-1)/length(app.Signal);
            plot(app.GraficoFrecuencia, f, abs(app.Signal));
            
            while exist(['Signal' num2str(countName) '.wav'], 'file') == 2
                countName = countName + 1;
            end
            
            audiowrite(['Signal' num2str(countName) '.wav'],app.Signal,app.Fs); % Guardar los datos en un archivo WAV

            if app.CheckSignal2.Value == false
                app.S1 = app.Signal;
                app.ni1 = app.ni;
                app.Fs1 = app.Fs;
                app.S1Inicial = app.S1;
            else
                app.S2 = app.Signal;
                app.ni2 = app.ni;
                app.Fs2 = app.Fs;
                app.S2Inicial = app.S2;
            end
        end

        % Button pushed function: ReproducirAudioButton
        function ReproducirAudioButtonPushed(app, event)
            if app.Fs>4000
               if app.CheckSignal2.Value == false
                   sound(app.S1,app.Fs);
               else
                   sound(app.S2,app.Fs);
               end
                
            else
                disp("Error frecuencia de muestreo por debajo de 4000")
            end
        end

        % Callback function
        function GroupNormalizacionesSelectionChanged(app, event)
            selectedButton = app.GroupNormalizaciones.SelectedObject;
            
                                                                            %Normalizaciones
             if app.Normalizacion0a1 == selectedButton
                
              app.SignalNueva = normalize(app.Signal, 'range');

            elseif app.Normalizacion1a1 == selectedButton

              app.SignalNueva = normalize(app.Signal, 'range', [-1, 1]);

            elseif app.NormalizacionStandar == selectedButton
               
              app.SignalNueva = normalize(app.Signal);

            else
              app.SignalNueva =  app.Signal;
            end
             
             plot(app.GraficaSignalInicial,app.n,app.Signal)
             plot(app.GraficaSinalManipulada,app.n,app.SignalNueva)

        end

        % Button down function: PreprocesamientoyFiltradoTab
        function PreprocesamientoyFiltradoTabSizeChanged(app, event)
            position = app.PreprocesamientoyFiltradoTab.Position;
            app.NormalizacionCbox.Value = app.NormalizacionCbox.Items{1};
            app.EliminaciondedatosNanoInfCheckBox.Value = false;
            app.FiltroCbox.Value = app.FiltroCbox.Items{1};
            app.bkTxt.Value = "[1 1 1 1 1 1 1 1 1 1]";
            app.akTxt.Value = "[1 1 1 1 1 1 1 1 1 1]";
            app.hnTxt.Value = "[1 1 1 1 1 1 1 1 1 1]";

            if app.CheckSignal2.Value == false
                app.Signal = app.S1;
            else
                app.Signal = app.S2;
            end

        end

        % Value changed function: EliminaciondedatosNanoInfCheckBox
        function EliminaciondedatosNanoInfCheckBoxValueChanged(app, event)
            value = app.EliminaciondedatosNanoInfCheckBox.Value;
                                                                            %Eliminacion de Nan Inf
            if app.EliminaciondedatosNanoInfCheckBox.Value == true
                app.SignalNueva = app.Signal;
                app.SignalNueva = app.SignalNueva(~isnan(app.SignalNueva));%Signalnueva sin nan
                app.SignalNueva = app.SignalNueva(~isinf(app.SignalNueva));
                tiempon = app.ni:(length(app.SignalNueva)-1)+app.ni;

            else
                app.SignalNueva = app.Signal;
            end

            plot(app.GraficaSignalInicial,app.n,app.Signal);  
            plot(app.GraficaSinalManipulada,tiempon,app.SignalNueva);
        end

        % Callback function
        function ProcesodeFiltradoButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProcesodeFiltradoButtonGroup.SelectedObject;
            
            numbers = strsplit(app.hnTxt.Value, ' ');                       %Filtros
            h = str2double(numbers(2:end-1));
            numbers = strsplit(app.akTxt.Value, ' ');
            a = str2double(numbers(2:end-1));
            numbers = strsplit(app.bkTxt.Value, ' ');
            b = str2double(numbers(2:end-1));

            if isvector(h) && isvector(a) && isvector(b) 
                if any(~isnan(h)) && any(~isnan(a)) && any(~isnan(b))
                    if any(~isinf(h)) && any(~isinf(a)) && any(~isinf(b))
                        if app.SistemaFIRButton == selectedButton
            
                            app.SignalNueva = filter(h, 1, app.Signal);
                            Tiempon = 1:length(app.SignalNueva);
            
                        elseif app.SistemasIIRButton == selectedButton
                            
                            app.SignalNueva = filter(b, a, app.Signal);
                            Tiempon = 1:length(app.SignalNueva);
                                
                        else
                            app.SignalNueva =  app.Signal;
                            Tiempon = app.n;
                        end
            
                        plot(app.GraficaSignalInicial,app.n,app.Signal)
                        plot(app.GraficaSinalManipulada,Tiempon,app.SignalNueva)
                    end
                end
            else
                disp("Valores h,a o b <0")
            end
        end

        % Button down function: Operacionescon1sealTab
        function Operacionescon1sealTabSizeChanged(app, event)
            position = app.Operacionescon1sealTab.Position;
            app.DKtxt.Value = 1;
            app.FKtxt.Value = 1;
            app.CambioMuestreo.SelectedObject = app.SinCambiosButton;
            app.DesplazaminetoButton.SelectedObject = app.SinCambiosButton_2;
            app.ConversionButton.SelectedObject = app.SinCambiosButton_3;
            app.InvertirSealCheckBox.Value = false;

            if app.CheckSignal2.Value == false
                app.Signal = app.S1;
            else
                app.Signal = app.S2;
            end
        end

        % Selection changed function: CambioMuestreo
        function CambioMuestreoSelectionChanged(app, event)
            selectedButton = app.CambioMuestreo.SelectedObject;

            k = app.FKtxt.Value;                                %Cambio de muestreo

            if k>0
                if app.SobreMuestreoButton == selectedButton
                       
                    app.SignalNueva = upsample(app.Signal, k);
    
                elseif app.SubMuestreoButton == selectedButton
                    
                    app.SignalNueva = downsample(app.Signal, k);
                else

                    app.SignalNueva = app.Signal;

                end
                
                Tiempon = app.ni:(length(app.SignalNueva)-1)+app.ni;
                
                plot(app.GraficaSignalInicial,app.n,app.Signal)                                %falta eliminacion de nan inf
                plot(app.GraficaSinalManipulada,Tiempon,app.SignalNueva)

            end


        end

        % Selection changed function: DesplazaminetoButton
        function DesplazaminetoButtonSelectionChanged(app, event)
            selectedButton = app.DesplazaminetoButton.SelectedObject;
             k = app.DKtxt.Value;
                                                                % Desplazamiento
                                                                % de señal
            if app.AdelantoButton == selectedButton

                if k>0   
                    app.SignalNueva = circshift(app.Signal, k);
                end

            elseif app.AtrasoButton == selectedButton

                if k>0
                    app.SignalNueva = circshift(app.Signal, k);
                    
                end

            else

                app.SignalNueva = app.Signal;

            end
            
            
            plot(app.GraficaSignalInicial,app.n,app.Signal)                                %falta eliminacion de nan inf
            plot(app.GraficaSinalManipulada,app.n,app.SignalNueva)
            
        end

        % Value changed function: InvertirSealCheckBox
        function InvertirSealCheckBoxValueChanged(app, event)
            value = app.InvertirSealCheckBox.Value;
            
            if app.InvertirSealCheckBox.Value == true

                app.SignalNueva = flip(app.Signal);
            else
                app.SignalNueva = app.Signal;
            end

            plot(app.GraficaSignalInicial,app.n,app.Signal);  
            plot(app.GraficaSinalManipulada,app.n,app.SignalNueva);
    
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            
            app.player = audioplayer(app.Signal*app.Amplitud.Value, app.Fs);
            play(app.player);
            countName = 1;
            % Verificacion si el archivo wav existe
            while exist(['Signal' num2str(countName) '.wav'], 'file') == 2
                countName = countName + 1;
            end
            audiowrite(['Signal' num2str(countName) '.wav'],app.Signal,app.Fs);%Escribir como audio
            

        end

        % Button down function: ManipulaciondesealTab
        function ManipulaciondesealTabSizeChanged(app, event)
            position = app.ManipulaciondesealTab.Position;
            app.ManipulacionTab.SelectedTab = app.ManipulacionTab.Children(1);
            app.NormalizacionCbox.Value = app.NormalizacionCbox.Items{1};
            app.EliminaciondedatosNanoInfCheckBox.Value = false;
            app.FiltroCbox.Value = app.FiltroCbox.Items{1};
            app.bkTxt.Value = "[1 1 1 1 1 1 1 1 1 1]";
            app.akTxt.Value = "[1 1 1 1 1 1 1 1 1 1]";
            app.hnTxt.Value = "[1 1 1 1 1 1 1 1 1 1]";
            cla(app.GraficaSignalInicial);
            cla(app.GraficaSinalManipulada);

            if app.CheckSignal2.Value == false
                app.Signal = app.S1;
            else
                app.Signal = app.S2;
            end

           
        end

        % Button down function: AdministraciondeSealTab
        function AdministraciondeSealTabSizeChanged(app, event)
            position = app.AdministraciondeSealTab.Position;
            
            if app.CheckSignal2.Value == false
                app.Signal = app.S1;
            else
                app.Signal = app.S2;
            end
            
            
        end

        % Button down function: ManipulacionenfrecuenciaTab
        function ManipulacionenfrecuenciaTabSizeChanged(app, event)
           position = app.ManipulacionenfrecuenciaTab.Position;
            if app.CheckSignal2.Value == false
                app.Signal = app.S1;
            else
                app.Signal = app.S2;
            end

            
        end

        % Button down function: OperacionescondossealesTab
        function OperacionescondossealesTabSizeChanged(app, event)
            position = app.OperacionescondossealesTab.Position;
            app.Amplitud.Value = 5;
            app.AmplitudLineal_S.Value = 5;
            if app.CheckSignal2.Value == false
                app.Signal = app.S1;
            else
                app.Signal = app.S2;
            end

        end

        % Selection changed function: ConversionButton
        function ConversionButtonSelectionChanged(app, event)
            selectedButton = app.ConversionButton.SelectedObject;
                                                             %Mono- Stereo
                                                                    
            if app.ConvertiraMonofnicaButton == selectedButton

                app.SignalNueva = mean(app.Signal, 2);
                Tiempon = app.ni:(length(app.Signal)-1)+app.ni;

            elseif app.ConvertiraEstereofnicaButton == selectedButton

                app.SignalNueva = [app.S1, app.S1];
                Tiempon = app.ni:(length(app.Signal)-1)+app.ni;
                

            else

                app.SignalNueva = app.Signal;
                Tiempon = app.n;

            end

            plot(app.GraficaSignalInicial,app.n,app.Signal);  
            plot(app.GraficaSinalManipulada, Tiempon, app.SignalNueva);
        end

        % Button pushed function: GuardarSealButton, 
        % ...and 1 other component
        function GuardarSealButtonPushed(app, event)
            % Guardar en equipo
            countName = 1;
            % Verificacion si el archivo txt existe
            while exist(['Signal' num2str(countName) '.txt'], 'file') == 2
                countName = countName + 1;
            end
            archivo = fopen(['Signal' num2str(countName) '.txt'], 'w');
            fprintf(archivo, '%d\n', app.SignalNueva);% Escribir en el documento
            fclose(archivo);
            % Verificacion si el archivo wav existe
            %while exist(['Signal' num2str(countName) '.wav'], 'file') == 2
            %    countName = countName + 1;
            %end
            %audiowrite(['Signal' num2str(countName) '.wav'],app.SignalNueva,app.Fs);%Escribir como audio
            % Guardar en sistema
            if app.CheckSignal2.Value == false
                app.S1 = app.SignalNueva;
                app.ni = app.ni1;
            else
                app.S2 = app.SignalNueva;
                app.ni = app.ni2;
            end
            
        end

        % Value changed function: AmplitudLineal_S
        function AmplitudLineal_SValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(app.player)
                %Se obtiene el tiempo de reproduccion
                app.TiempoAudio = app.player.CurrentSample;
                stop(app.player);%se detienen la reproduccion
                %Se recorta la señal desde el tiempo en el que quedó
                app.SignalNueva = app.Signal(app.TiempoAudio:end);
                %se reproduce de nuevo la señal
                app.player = audioplayer(app.Amplitud.Value*app.SignalNueva,app.Fs); 
                play(app.player); 

                
            end
            %se cambia el valor de la amplitud
            app.Amplitud.Value = app.AmplitudLineal_S.Value;

        end

        % Value changed function: Amplitud
        function AmplitudValueChanged(app, event)
            value = app.Amplitud.Value;   
            if ~isempty(app.player)
                if app.Amplitud.Value <= 100
                    %Se obtiene el tiempo de reproduccion
                    app.TiempoAudio = app.player.CurrentSample;
                    stop(app.player);%se detienen la reproduccion
                    %Se recorta la señal desde el tiempo en el que quedó
                    app.SignalNueva = app.Signal(app.TiempoAudio:end);
                    %se reproduce de nuevo la señal
                    app.player = audioplayer(app.Amplitud.Value*app.SignalNueva,app.Fs); 
                    play(app.player); 
                end
            end
            %se cambia el valor de la amplitud
            app.AmplitudLineal_S.Value = app.Amplitud.Value;
        end

        % Button pushed function: SumarlasdossealesButton
        function SumarlasdossealesButtonPushed(app, event)
            n1 = app.ni1:(length(app.S1)+app.ni1); % Tiempo de señal 1
            n2 = app.ni2:(length(app.S2)+app.ni2); % Tiempo de señal 2
             

            if app.Fs1>app.Fs2
                % Alineando los instantes iniciales de S1 con respecto a S2
                [S2_aligned, n_Aligned] = alignsignals(app.S2, n2, app.S1, n1, 'CommonStart'); 
                % Remuestreando la frecuencia más baja
                S2_Remuestreada = resample(S2_aligned, app.Fs1, app.Fs2);

                % Se el que menor datos tenga para llenar con ceros
                if length(S2_Remuestreada)>length(app.S1)

                    Signal1 = [app.S1; zeros(1, length(S2_Remuestreada) - length(app.S1))];
                    Signal2 = S2_Remuestreada;

                elseif length(S2_Remuestreada)<length(app.S1)

                    Signal2 = [S2_Remuestreada; zeros(1, length(app.S1) - length(S2_Remuestreada))];
                    Signal1 = app.S1;

                else
                    
                    Signal2 = S2_Remuestreada;
                    Signal1 = app.S1;

                end


            elseif app.Fs2>app.Fs1
                % Alineando los instantes iniciales de S1 con respecto a S2
                [S1_aligned, n_Aligned] = alignsignals(app.S1, n1, app.S2, n2, 'CommonStart'); 
                % Remuestreando la frecuencia más baja
                S1_Remuestreada = resample(S1_aligned, app.Fs2, app.Fs1);

                % Se el que menor datos tenga para llenar con ceros
                if length(S1_Remuestreada)>length(app.S2)

                    Signal2 = [app.S2; zeros(1, length(S1_Remuestreada) - length(app.S2))];
                    Signal1 = S1_Remuestreada;

                elseif length(S1_Remuestreada)<length(app.S2)

                    Signal1 = [S1_Remuestreada; zeros(1, length(app.S2) - length(S1_Remuestreada))];
                    Signal2 = app.S2;

                else
                    
                    Signal1 = S1_Remuestreada;
                    Signal2 = app.S2;

                end

            else
                % Ya que las frecuencias son Iguales
                if length(app.S1)>length(app.S2)
                    %Se buscan los ceros de relleno
                    ceros = zeros(1, (length(app.S2)-length(app.S1)))';
                    
                    % Buscando el centro para dezplazar
                    if app.ni1>app.ni2
                        k = app.ni2 - app.ni1;
    
                        Signal1 = [circshift(app.S1, k); ceros];
                        Signal2 = app.S2;
    
                    elseif app.ni2>app.ni1
                        k = app.ni1 - app.ni2;
    
                        Signal2 = [circshift(app.S2, k); ceros];
                        Signal1 = app.S1;
    
                    else
                        
                        Signal1 = app.S1;
                        Signal2 = [app.S2; ceros];
    
                    end
                elseif length(app.S2)>length(app.S1)
                    %Se buscan los ceros de relleno
                    ceros = zeros(1, (length(app.S2)-length(app.S1)))';
                    % Buscando el centro para dezplazar
                    if app.ni1>app.ni2
                        k = app.ni2 - app.ni1;
    
                        Signal1 = circshift(app.S1, k);
                        Signal2 = [app.S2; ceros];
    
                    elseif app.ni2>app.ni1
                        k = app.ni1 - app.ni2;
    
                        Signal2 = circshift(app.S2, k);
                        Signal1 = [app.S1; ceros];
    
                    else
                        
                        Signal1 = [app.S1; ceros];
                        Signal2 = app.S2;
    
                    end
                else
                    %Si la frecuencia es la misma y el tamaño es el mismo 
                    if app.ni1>app.ni2
                        k = app.ni2 - app.ni1;
    
                        Signal1 = circshift(app.S1, k);
                        Signal2 = app.S2;
    
                    elseif app.ni2>app.ni1
                        k = app.ni1 - app.ni2;
    
                        Signal2 = circshift(app.S2, k);
                        Signal1 = app.S1;
    
                    else
                        
                        Signal1 = app.S1;
                        Signal2 = app.S2;
    
                    end
                end
            end
            app.SignalNueva = Signal1 + Signal2;
            n1 = 1:length(Signal1);
            n2 = 1:length(Signal2);
            tn = 1:length(app.SignalNueva);
            plot(app.GraficaSignalInicial,n1,Signal1, n2, Signal2)
            plot(app.GraficaSinalManipulada,tn,app.SignalNueva)

            app.Signal = app.SignalNueva;
            app.n = tn;

            %app.Fs = lenth(app.n);
            
        end

        % Button pushed function: MultiplicarLasdossealesButton
        function MultiplicarLasdossealesButtonPushed(app, event)
            n1 = app.ni1:(length(app.S1)+app.ni1); % Tiempo de señal 1
            n2 = app.ni2:(length(app.S2)+app.ni2); % Tiempo de señal 2
             

            if app.Fs1>app.Fs2
                % Alineando los instantes iniciales de S1 con respecto a S2
                [S2_aligned, n_Aligned] = alignsignals(app.S2, n2, app.S1, n1, 'CommonStart'); 
                % Remuestreando la frecuencia más baja
                S2_Remuestreada = resample(S2_aligned, app.Fs1, app.Fs2);

                % Se el que menor datos tenga para llenar con ceros
                if length(S2_Remuestreada)>length(app.S1)

                    Signal1 = [app.S1; zeros(1, length(S2_Remuestreada) - length(app.S1))];
                    Signal2 = S2_Remuestreada;

                elseif length(S2_Remuestreada)<length(app.S1)

                    Signal2 = [S2_Remuestreada; zeros(1, length(app.S1) - length(S2_Remuestreada))];
                    Signal1 = app.S1;

                else
                    
                    Signal2 = S2_Remuestreada;
                    Signal1 = app.S1;

                end


            elseif app.Fs2>app.Fs1
                % Alineando los instantes iniciales de S1 con respecto a S2
                [S1_aligned, n_Aligned] = alignsignals(app.S1, n1, app.S2, n2, 'CommonStart'); 
                % Remuestreando la frecuencia más baja
                S1_Remuestreada = resample(S1_aligned, app.Fs2, app.Fs1);

                % Se el que menor datos tenga para llenar con ceros
                if length(S1_Remuestreada)>length(app.S2)

                    Signal2 = [app.S2; zeros(1, length(S1_Remuestreada) - length(app.S2))];
                    Signal1 = S1_Remuestreada;

                elseif length(S1_Remuestreada)<length(app.S2)

                    Signal1 = [S1_Remuestreada; zeros(1, length(app.S2) - length(S1_Remuestreada))];
                    Signal2 = app.S2;

                else
                    
                    Signal1 = S1_Remuestreada;
                    Signal2 = app.S2;

                end

            else
                % Ya que las frecuencias son Iguales
                if length(app.S1)>length(app.S2)
                    %Se buscan los ceros de relleno
                    ceros = zeros(1, (length(app.S2)-length(app.S1)))';
                    
                    % Buscando el centro para dezplazar
                    if app.ni1>app.ni2
                        k = app.ni2 - app.ni1;
    
                        Signal1 = [circshift(app.S1, k); ceros];
                        Signal2 = app.S2;
    
                    elseif app.ni2>app.ni1
                        k = app.ni1 - app.ni2;
    
                        Signal2 = [circshift(app.S2, k); ceros];
                        Signal1 = app.S1;
    
                    else
                        
                        Signal1 = app.S1;
                        Signal2 = [app.S2; ceros];
    
                    end
                elseif length(app.S2)>length(app.S1)
                    %Se buscan los ceros de relleno
                    ceros = zeros(1, (length(app.S2)-length(app.S1)))';
                    % Buscando el centro para dezplazar
                    if app.ni1>app.ni2
                        k = app.ni2 - app.ni1;
    
                        Signal1 = circshift(app.S1, k);
                        Signal2 = [app.S2; ceros];
    
                    elseif app.ni2>app.ni1
                        k = app.ni1 - app.ni2;
    
                        Signal2 = circshift(app.S2, k);
                        Signal1 = [app.S1; ceros];
    
                    else
                        
                        Signal1 = [app.S1; ceros];
                        Signal2 = app.S2;
    
                    end
                else
                    %Si la frecuencia es la misma y el tamaño es el mismo 
                    if app.ni1>app.ni2
                        k = app.ni2 - app.ni1;
    
                        Signal1 = circshift(app.S1, k);
                        Signal2 = app.S2;
    
                    elseif app.ni2>app.ni1
                        k = app.ni1 - app.ni2;
    
                        Signal2 = circshift(app.S2, k);
                        Signal1 = app.S1;
    
                    else
                        
                        Signal1 = app.S1;
                        Signal2 = app.S2;
    
                    end
                end
            end

            app.SignalNueva = Signal1 .* Signal2;

            n1 = 1:length(Signal1);
            n2 = 1:length(Signal2);
            tn = 1:length(app.SignalNueva);
            plot(app.GraficaSignalInicial,n1,Signal1, n2, Signal2)
            plot(app.GraficaSinalManipulada,tn,app.SignalNueva)

            app.Signal = app.SignalNueva;
            app.n = tn;
            %app.Fs = 1/(lenth(app.n)-1);
        end

        % Button pushed function: CrearsealEsteroButton
        function CrearsealEsteroButtonPushed(app, event)
            n1 = app.ni1:(length(app.S1)+app.ni1); % Tiempo de señal 1
            n2 = app.ni2:(length(app.S2)+app.ni2); % Tiempo de señal 2
             

            if app.Fs1>app.Fs2
                % Alineando los instantes iniciales de S1 con respecto a S2
                [S2_aligned, n_Aligned] = alignsignals(app.S2, n2, app.S1, n1, 'CommonStart'); 
                % Remuestreando la frecuencia más baja
                S2_Remuestreada = resample(S2_aligned, app.Fs1, app.Fs2);

                % Se el que menor datos tenga para llenar con ceros
                if length(S2_Remuestreada)>length(app.S1)

                    Signal1 = [app.S1; zeros(1, length(S2_Remuestreada) - length(app.S1))];
                    Signal2 = S2_Remuestreada;

                elseif length(S2_Remuestreada)<length(app.S1)

                    Signal2 = [S2_Remuestreada; zeros(1, length(app.S1) - length(S2_Remuestreada))];
                    Signal1 = app.S1;

                else
                    
                    Signal2 = S2_Remuestreada;
                    Signal1 = app.S1;

                end


            elseif app.Fs2>app.Fs1
                % Alineando los instantes iniciales de S1 con respecto a S2
                [S1_aligned, n_Aligned] = alignsignals(app.S1, n1, app.S2, n2, 'CommonStart'); 
                % Remuestreando la frecuencia más baja
                S1_Remuestreada = resample(S1_aligned, app.Fs2, app.Fs1);

                % Se el que menor datos tenga para llenar con ceros
                if length(S1_Remuestreada)>length(app.S2)

                    Signal2 = [app.S2; zeros(1, length(S1_Remuestreada) - length(app.S2))];
                    Signal1 = S1_Remuestreada;

                elseif length(S1_Remuestreada)<length(app.S2)

                    Signal1 = [S1_Remuestreada; zeros(1, length(app.S2) - length(S1_Remuestreada))];
                    Signal2 = app.S2;

                else
                    
                    Signal1 = S1_Remuestreada;
                    Signal2 = app.S2;

                end

            else
                % Ya que las frecuencias son Iguales
                if length(app.S1)>length(app.S2)
                    %Se buscan los ceros de relleno
                    ceros = zeros(1, (length(app.S2)-length(app.S1)))';
                    
                    % Buscando el centro para dezplazar
                    if app.ni1>app.ni2
                        k = app.ni2 - app.ni1;
    
                        Signal1 = [circshift(app.S1, k); ceros];
                        Signal2 = app.S2;
    
                    elseif app.ni2>app.ni1
                        k = app.ni1 - app.ni2;
    
                        Signal2 = [circshift(app.S2, k); ceros];
                        Signal1 = app.S1;
    
                    else
                        
                        Signal1 = app.S1;
                        Signal2 = [app.S2; ceros];
    
                    end
                elseif length(app.S2)>length(app.S1)
                    %Se buscan los ceros de relleno
                    ceros = zeros(1, (length(app.S2)-length(app.S1)))';
                    % Buscando el centro para dezplazar
                    if app.ni1>app.ni2
                        k = app.ni2 - app.ni1;
    
                        Signal1 = circshift(app.S1, k);
                        Signal2 = [app.S2; ceros];
    
                    elseif app.ni2>app.ni1
                        k = app.ni1 - app.ni2;
    
                        Signal2 = circshift(app.S2, k);
                        Signal1 = [app.S1; ceros];
    
                    else
                        
                        Signal1 = [app.S1; ceros];
                        Signal2 = app.S2;
    
                    end
                else
                    %Si la frecuencia es la misma y el tamaño es el mismo 
                    if app.ni1>app.ni2
                        k = app.ni2 - app.ni1;
    
                        Signal1 = circshift(app.S1, k);
                        Signal2 = app.S2;
    
                    elseif app.ni2>app.ni1
                        k = app.ni1 - app.ni2;
    
                        Signal2 = circshift(app.S2, k);
                        Signal1 = app.S1;
    
                    else
                        
                        Signal1 = app.S1;
                        Signal2 = app.S2;
    
                    end
                end
            end

            app.SignalNueva = [Signal1  Signal2];

            n1 = 1:length(Signal1);
            n2 = 1:length(Signal2);
            tn = 1:length(app.SignalNueva);
            plot(app.GraficaSignalInicial,n1,Signal1, n2, Signal2)
            plot(app.GraficaSinalManipulada,tn,app.SignalNueva)

            app.Signal = app.SignalNueva;
            app.n = tn;
            %app.Fs = mean(diff(tn));
        end

        % Button pushed function: ObservarEspectrogramaButton
        function ObservarEspectrogramaButtonPushed(app, event)
                      
            if app.CheckSignal2.Value == false

                %Graficas de Entrada en tiempo y espectrograma para señal 1
                T1 = 1:length(app.S1Inicial);
                plot(app.GraficaTiempoEntrada,T1,app.S1Inicial)

                v = hamming(512);% ventana Hamming para observar el espectograma
                es = spectrogram(app.S1Inicial, v,256,512,app.Fs);
                imagesc(app.GraficaFrecuenciaEntrada, abs(es));%graficacion del espectograma
                colormap(app.GraficaFrecuenciaEntrada, 'jet');

                %Grafica de Salida en tiempo  y espectrograma para señal 1
                T1 = 1:length(app.S1);
                plot(app.GraficaTiempoSalida,T1,app.S1)

                v = hamming(512);
                es = spectrogram(app.S1Inicial, v,256,512,app.Fs);
                imagesc(app.GraficaFrecuenciaSalida, abs(es));
                colormap(app.GraficaFrecuenciaSalida, 'jet');
            else

                %Graficas de Salida en tiempo y espectrograma para señal 2
                T2 = 1:length(app.S2Inicial);
                plot(app.GraficaTiempoEntrada,T2,app.S2Inicial)

                v = hamming(512);
                es = spectrogram(app.S2Inicial, v,256,512,app.Fs);
                imagesc(app.GraficaFrecuenciaEntrada, abs(es));
                colormap(app.GraficaFrecuenciaEntrada, 'jet');

                %Grafica de Salida en tiempo  y espectrograma para señal 2
                T2 = 1:length(app.S2);
                plot(app.GraficaTiempoSalida,T2,app.S2)
                
                v = hamming(512);
                es = spectrogram(app.S2Inicial, v,256,512,app.Fs);
                imagesc(app.GraficaFrecuenciaSalida, abs(es));
                colormap(app.GraficaFrecuenciaSalida, 'jet');

            end
            
            
        end

        % Button down function: SimuladaTab_2
        function SimuladaTab_2ButtonDown(app, event)
            app.SignalsAdminTab.SelectedTab = app.SignalsAdminTab.Children(2);
            app.SealDeentradaButtonGroup.SelectedObject = app.SimuladaButton;

        end

        % Button down function: MicrofonoTab
        function MicrofonoTabButtonDown(app, event)
            app.SignalsAdminTab.SelectedTab = app.SignalsAdminTab.Children(1);
            app.SealDeentradaButtonGroup.SelectedObject = app.MicrofonoButton;
        end

        % Button down function: ArchivoTab_2
        function ArchivoTab_2ButtonDown(app, event)
            app.SignalsAdminTab.SelectedTab = app.SignalsAdminTab.Children(3);
            app.SealDeentradaButtonGroup.SelectedObject = app.ArchivoButton;
        end

        % Button down function: TarjetaTab_2
        function TarjetaTab_2ButtonDown(app, event)
            app.SignalsAdminTab.SelectedTab = app.SignalsAdminTab.Children(4);
            app.SealDeentradaButtonGroup.SelectedObject = app.TarjetaButton;
        end

        % Button down function: MicrofonoTab_2
        function MicrofonoTab_2ButtonDown(app, event)
            app.SignalParametersTab.SelectedTab = app.SignalParametersTab.Children(1);
            app.SealDeentradaButtonGroup.SelectedObject = app.MicrofonoButton;
        end

        % Button down function: SimuladaTab
        function SimuladaTabButtonDown(app, event)
            app.SignalParametersTab.SelectedTab = app.SignalParametersTab.Children(2);
            app.SealDeentradaButtonGroup.SelectedObject = app.SimuladaButton;
        end

        % Button down function: ArchivoTab
        function ArchivoTabButtonDown(app, event)
            app.SignalParametersTab.SelectedTab = app.SignalParametersTab.Children(3);
            app.SealDeentradaButtonGroup.SelectedObject = app.ArchivoButton;
        end

        % Button down function: TarjetaTab
        function TarjetaTabButtonDown(app, event)
            app.SignalParametersTab.SelectedTab = app.SignalParametersTab.Children(4);
            app.SealDeentradaButtonGroup.SelectedObject = app.TarjetaButton;
        end

        % Value changed function: ProducirEcoCheckBox
        function ProducirEcoCheckBoxValueChanged(app, event)
            value = app.ProducirEcoCheckBox.Value;
             if app.ProducirEcoCheckBox.Value == false
                
                app.SignalNueva = app.Signal;
                Tiempon = app.n;

             else
                K1 = round(50/1000*app.Fs);%Discretizacion de las muestras en dominio del tiempo
                K2 = round(100/1000*app.Fs);
                app.SignalNueva = app.Signal + app.A1Txt.Value*circshift(app.Signal, K1) + app.A2Txt.Value*circshift(app.Signal, K2);
                Tiempon = 1:length(app.SignalNueva);
            end
            
            plot(app.GraficaSignalInicial,app.n,app.Signal)
            plot(app.GraficaSinalManipulada,Tiempon,app.SignalNueva)
        end

        % Value changed function: A1Txt
        function A1TxtValueChanged(app, event)
            value = app.A1Txt.Value;
            
            if app.ProducirEcoCheckBox.Value == false
                
                app.SignalNueva = app.Signal;
                Tiempon = app.n;

             else
                K1 = round(50/1000*app.Fs);
                K2 = round(100/1000*app.Fs);
                app.SignalNueva = app.Signal + app.A1Txt.Value*circshift(app.Signal, K1) + app.A2Txt.Value*circshift(app.Signal, K2);
                Tiempon = 1:length(app.SignalNueva);
            end
            
            plot(app.GraficaSignalInicial,app.n,app.Signal)
            plot(app.GraficaSinalManipulada,Tiempon,app.SignalNueva)
        end

        % Value changed function: A2Txt
        function A2TxtValueChanged(app, event)
            value = app.A2Txt.Value;
            if app.ProducirEcoCheckBox.Value == false
                
                app.SignalNueva = app.Signal;
                Tiempon = app.n;

             else
                K1 = round(50/1000*app.Fs);
                K2 = round(100/1000*app.Fs);
                app.SignalNueva = app.Signal + app.A1Txt.Value*circshift(app.Signal, K1) + app.A2Txt.Value*circshift(app.Signal, K2);
                Tiempon = 1:length(app.SignalNueva);
            end
            
            plot(app.GraficaSignalInicial,app.n,app.Signal)
            plot(app.GraficaSinalManipulada,Tiempon,app.SignalNueva)
        end

        % Button pushed function: TransformadadeFurierButton
        function TransformadadeFurierButtonPushed(app, event)
            furier = fft(app.Signal);
            M = abs(furier);% Magnitud
            Tiempon = 1:length(M);
            plot(app.MagnitudAxes,Tiempon,M);
            F = angle(furier);% Angulo
            Tiempon = 1:length(F);
            plot(app.FaseAxes,Tiempon,F);
        end

        % Button pushed function: AdquirirSealButton
        function AdquirirSealButtonPushed(app, event)
            % Crear una sesión de adquisición de datos
            sesion = daq.createSession('ni');
            countName = 1;
            % Agregar un canal de entrada analógica
            addAnalogInputChannel(sesion, app.ListTajetas.Value, app.Analog, 'Voltage');
            
            % Configurar la tasa de muestreo
            sesion.Rate = app.FrecuenciademuestreoTxt.Value; % 1000 muestras por segundo
            app.Fs =  app.FrecuenciademuestreoTxt.Value;
            
            % Configurar la duración de la adquisición
            sesion.DurationInSeconds = app.TiempoTxt.Value; % Adquirir durante 10 segundos
            
            % Obtener datos
            app.Signal = sesion.startForeground();
            app.ni = app.ndeInicioTxtTarjeta.Value;

            % Plotear los datos
            app.n = app.ni:(length(app.Signal)-1)+app.ni;

            plot(app.GraficoTiempo, app.n, app.Signal);
            f = app.Fs*(0:length(app.Signal)-1)/length(app.Signal);
            plot(app.GraficoFrecuencia, f, abs(app.Signal));
            
            %Guardar Señal
            while exist(['Signal' num2str(countName) '.txt'], 'file') == 2
                countName = countName + 1;
            end

            archivo = fopen(['Signal' num2str(countName) '.txt'], 'w');
            fprintf(archivo, '%d\n', app.Signal);% Escribir en el documento
            fclose(archivo);

            if app.CheckSignal2.Value == false
                app.S1 = app.Signal;
                app.ni1 = app.ni;
                app.Fs1 = app.Fs;
                app.S1Inicial = app.S1;
            else
                app.S2 = app.Signal;
                app.ni2 = app.ni;
                app.Fs1 = app.Fs;
                app.S2Inicial = app.S2;
            end
        end

        % Selection changed function: InputButtonGroup
        function InputButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputButtonGroup.SelectedObject;
            if app.A0Button == selectedButton
                app.Analog = 0;
            elseif app.A1Button == selectedButton
                app.Analog = 1;
            elseif app.A2Button == selectedButton
                app.Analog = 2;
            elseif app.A3Button == selectedButton
                app.Analog = 3;
            elseif app.A4Button == selectedButton
                app.Analog = 4;
            elseif app.A5Button == selectedButton
                app.Analog = 5;
            elseif app.A6Button == selectedButton
                app.Analog = 6;
            elseif app.A7Button == selectedButton
                app.Analog = 7;
            end
        end

        % Value changed function: NormalizacionCbox
        function NormalizacionCboxValueChanged(app, event)
            value = app.NormalizacionCbox.Value;
                                                                     %Normalizaciones
            
            switch app.NormalizacionCbox.Value
               case 'Sin Normalización'
                   app.SignalNueva =  app.Signal;
               case 'Normalización 0 a 1'
                   app.SignalNueva = normalize(app.Signal, 'range');
               case 'Normalización -1 a 1'
                   app.SignalNueva = normalize(app.Signal, 'range', [-1, 1]);
               case 'Normalización Estandar'
                   app.SignalNueva = zscore(app.Signal);
                   
            end
               
            plot(app.GraficaSignalInicial,app.n,app.Signal)
            plot(app.GraficaSinalManipulada,app.n,app.SignalNueva)
        end

        % Value changed function: FiltroCbox
        function FiltroCboxValueChanged(app, event)
            value = app.FiltroCbox.Value;

            numbers = strsplit(app.hnTxt.Value, ' ');                       %Filtros
            h = str2double(numbers(2:end-1));
            numbers = strsplit(app.akTxt.Value, ' ');
            a = str2double(numbers(2:end-1));
            numbers = strsplit(app.bkTxt.Value, ' ');
            b = str2double(numbers(2:end-1));

            if isvector(h) && isvector(a) && isvector(b) 
                if any(~isnan(h)) && any(~isnan(a)) && any(~isnan(b))
                    if any(~isinf(h)) && any(~isinf(a)) && any(~isinf(b))
                        
                        plot(app.GraficaSignalInicial,app.n,app.Signal)
                        switch app.FiltroCbox.Value
                           case 'Sin Filtro'
                                app.SignalNueva =  app.Signal;
                           case 'Filtro con entrada h(n)'

                                % Cálculo de la convolución 

                                [app.SignalNueva, nyi,nyf] = Convolucion(app.Signal, app.ni, str2double(app.hnTxt.Value), 0); %Llamado a función
                                app.n = nyi:nyf;
                                disp(app.SignalNueva)
                                disp(app.n)
                            case 'Filtro Pasabajos'
                                
                                fileID = fopen("SistemaF_PasBaj.txt",'r');
                                Filtro= fscanf(fileID,'%f');
                                fclose(fileID);
                                
                                disp(Filtro)
                                % Cálculo de la convolución 
                                [app.SignalNueva, nyi, nyf] = Convolucion(app.Signal, app.ni, Filtro, 0); %Llamado a función
                                disp("ok2")
                                app.n = nyi:nyf;

                                disp("ok3")
                                
                           case 'Filtro Pasa Altos'
                                fileID = fopen("SistemaF_PasAlt.txt",'r');
                                Filtro = fscanf(fileID,'%f');
                                fclose(fileID);
                                % Cálculo de la convolución 
                                [app.SignalNueva, nyi, nyf] = Convolucion(app.Signal, app.ni, Filtro, 0); %Llamado a función
                                app.n = nyi:nyf;
                            case 'Filtro IIR'   
                               
                                app.SignalNueva = filter(b, a, app.Signal);
                                app.n = app.ni:(length(app.SignalNueva)-1)+app.ni;
                        end
            
                        
                        plot(app.GraficaSinalManipulada,app.n,app.SignalNueva)
                    else
                        disp("Valores h,a o b tiene valores  inf");
                    end
                else
                    disp("Valores h,a o b tiene valores  nan");
                end
            else
                disp("Valores h,a o b <0");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabMaster = uitabgroup(app.GridLayout);
            app.AdministraciondeSealTab = uitab(app.TabMaster);
            app.AdministraciondeSealTab.Title = 'Administracion de Señal';
            app.AdministraciondeSealTab.ButtonDownFcn = createCallbackFcn(app, @AdministraciondeSealTabSizeChanged, true);
            app.GridLayout_2 = uigridlayout(app.AdministraciondeSealTab);
            app.GridLayout10 = uigridlayout(app.GridLayout_2);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.GraficoTiempo = uiaxes(app.GridLayout11);
            title(app.GraficoTiempo, 'Tiempo')
            xlabel(app.GraficoTiempo, 'Tiempo')
            ylabel(app.GraficoTiempo, 'Amplitud')
            app.GraficoFrecuencia = uiaxes(app.GridLayout11);
            title(app.GraficoFrecuencia, 'Frecuencia')
            xlabel(app.GraficoFrecuencia, 'Frecuencia')
            ylabel(app.GraficoFrecuencia, 'Amplitud')
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.ReproducirAudioButton = uibutton(app.GridLayout12, 'push');
            app.ReproducirAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirAudioButtonPushed, true);
            app.ReproducirAudioButton.Text = 'Reproducir Audio';
            app.SignalParametersTab = uitabgroup(app.GridLayout_2);
            app.MicrofonoTab = uitab(app.SignalParametersTab);
            app.MicrofonoTab.Title = 'Microfono';
            app.MicrofonoTab.ButtonDownFcn = createCallbackFcn(app, @MicrofonoTabButtonDown, true);
            app.GridLayout14 = uigridlayout(app.MicrofonoTab);
            app.FrecuenciadeMuestreoEditFieldLabel = uilabel(app.GridLayout14);
            app.FrecuenciadeMuestreoEditFieldLabel.Text = 'Frecuencia de Muestreo';
            app.FrecuenciadeMuestreoTxt = uieditfield(app.GridLayout14, 'numeric');
            app.FrecuenciadeMuestreoTxt.ValueDisplayFormat = '%.0f';
            app.FrecuenciadeMuestreoTxt.Value = 44100;
            app.BitsMuestraEditFieldLabel = uilabel(app.GridLayout14);
            app.BitsMuestraEditFieldLabel.Text = 'Bits/Muestra';
            app.BitsMuestraTxt = uieditfield(app.GridLayout14, 'numeric');
            app.BitsMuestraTxt.Value = 16;
            app.NmCanalesEditFieldLabel = uilabel(app.GridLayout14);
            app.NmCanalesEditFieldLabel.Text = 'Núm. Canales';
            app.NmCanalesTxt = uieditfield(app.GridLayout14, 'numeric');
            app.NmCanalesTxt.Value = 1;
            app.TiempoGrabsEditFieldLabel = uilabel(app.GridLayout14);
            app.TiempoGrabsEditFieldLabel.Text = 'Tiempo Grab. (s)';
            app.TiempoGrabsTxt = uieditfield(app.GridLayout14, 'numeric');
            app.TiempoGrabsTxt.Value = 3;
            app.nInicialEditFieldLabel = uilabel(app.GridLayout14);
            app.nInicialEditFieldLabel.Text = '''n'' Inicial';
            app.ndeInicioTxtMicrofono = uieditfield(app.GridLayout14, 'numeric');
            app.SimuladaTab_2 = uitab(app.SignalParametersTab);
            app.SimuladaTab_2.Title = 'Simulada';
            app.SimuladaTab_2.ButtonDownFcn = createCallbackFcn(app, @SimuladaTab_2ButtonDown, true);
            app.GridLayout2 = uigridlayout(app.SimuladaTab_2);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GananciaLabel = uilabel(app.GridLayout3);
            app.GananciaLabel.Text = 'Ganancia:';
            app.Gananciatxt = uieditfield(app.GridLayout3, 'numeric');
            app.Gananciatxt.Enable = 'off';
            app.Gananciatxt.Value = 1.5;
            app.FrecuenciaAnlogaEditField_3Label = uilabel(app.GridLayout3);
            app.FrecuenciaAnlogaEditField_3Label.Text = 'Frecuencia Análoga:';
            app.FrecuenciaAnlogaTxt = uieditfield(app.GridLayout3, 'numeric');
            app.FrecuenciaAnlogaTxt.Enable = 'off';
            app.FrecuenciaAnlogaTxt.Value = 20;
            app.FrecuenciaMuestreoEditField_3Label = uilabel(app.GridLayout3);
            app.FrecuenciaMuestreoEditField_3Label.Text = 'Frecuencia Muestreo:';
            app.FrecuenciaMuestreoTxt = uieditfield(app.GridLayout3, 'numeric');
            app.FrecuenciaMuestreoTxt.Enable = 'off';
            app.FrecuenciaMuestreoTxt.Value = 400;
            app.FaseradEditField_3Label = uilabel(app.GridLayout3);
            app.FaseradEditField_3Label.Text = 'Fase (rad):';
            app.FaseTxt = uieditfield(app.GridLayout3, 'numeric');
            app.FaseTxt.Enable = 'off';
            app.FaseTxt.Value = 0.5;
            app.DesplazamientoEditField_3Label = uilabel(app.GridLayout3);
            app.DesplazamientoEditField_3Label.Text = 'Desplazamiento:';
            app.DesplazamientoTxt = uieditfield(app.GridLayout3, 'numeric');
            app.DesplazamientoTxt.Enable = 'off';
            app.DesplazamientoTxt.Value = 2;
            app.ndeInicioEditField_3Label = uilabel(app.GridLayout3);
            app.ndeInicioEditField_3Label.Text = '''n'' de Inicio:';
            app.ndeInicioTxt = uieditfield(app.GridLayout3, 'numeric');
            app.ndeInicioTxt.Enable = 'off';
            app.ndeInicioTxt.Value = -5;
            app.ndeFinEditField_3Label = uilabel(app.GridLayout3);
            app.ndeFinEditField_3Label.Text = '''n'' de Fin:';
            app.ndeFinTxt = uieditfield(app.GridLayout3, 'numeric');
            app.ndeFinTxt.Enable = 'off';
            app.ndeFinTxt.Value = 60;
            app.GridLayout13 = uigridlayout(app.GridLayout2);
            app.ArchivoTab_2 = uitab(app.SignalParametersTab);
            app.ArchivoTab_2.Title = 'Archivo';
            app.ArchivoTab_2.ButtonDownFcn = createCallbackFcn(app, @ArchivoTab_2ButtonDown, true);
            app.GridLayout15 = uigridlayout(app.ArchivoTab_2);
            app.InfoLabel = uilabel(app.GridLayout15);
            app.InfoLabel.Text = '';
            app.TarjetaTab_2 = uitab(app.SignalParametersTab);
            app.TarjetaTab_2.Title = 'Tarjeta';
            app.TarjetaTab_2.ButtonDownFcn = createCallbackFcn(app, @TarjetaTab_2ButtonDown, true);
            app.GridLayout16 = uigridlayout(app.TarjetaTab_2);
            app.GridLayout23 = uigridlayout(app.GridLayout16);
            app.TiempoEditFieldLabel = uilabel(app.GridLayout23);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoTxt = uieditfield(app.GridLayout23, 'numeric');
            app.TiempoTxt.Enable = 'off';
            app.TiempoTxt.Value = 10;
            app.FrecuenciaEditFieldLabel = uilabel(app.GridLayout23);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia: ';
            app.FrecuenciademuestreoTxt = uieditfield(app.GridLayout23, 'numeric');
            app.FrecuenciademuestreoTxt.Enable = 'off';
            app.FrecuenciademuestreoTxt.Value = 1000;
            app.ndeinicioEditFieldLabel = uilabel(app.GridLayout23);
            app.ndeinicioEditFieldLabel.Text = '''n'' de inicio';
            app.ndeInicioTxtTarjeta = uieditfield(app.GridLayout23, 'numeric');
            app.InputButtonGroup = uibuttongroup(app.GridLayout16);
            app.InputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputButtonGroupSelectionChanged, true);
            app.InputButtonGroup.Title = 'Input';
            app.A0Button = uiradiobutton(app.InputButtonGroup);
            app.A0Button.Text = 'A0';
            app.A0Button.Value = true;
            app.A1Button = uiradiobutton(app.InputButtonGroup);
            app.A1Button.Text = 'A1';
            app.A2Button = uiradiobutton(app.InputButtonGroup);
            app.A2Button.Text = 'A2';
            app.A3Button = uiradiobutton(app.InputButtonGroup);
            app.A3Button.Text = 'A3';
            app.A4Button = uiradiobutton(app.InputButtonGroup);
            app.A4Button.Text = 'A4';
            app.A5Button = uiradiobutton(app.InputButtonGroup);
            app.A5Button.Text = 'A5';
            app.A6Button = uiradiobutton(app.InputButtonGroup);
            app.A6Button.Text = 'A6';
            app.A7Button = uiradiobutton(app.InputButtonGroup);
            app.A7Button.Text = 'A7';
            app.GridLayout17 = uigridlayout(app.GridLayout_2);
            app.GridLayout20 = uigridlayout(app.GridLayout17);
            app.SealDeentradaButtonGroup = uibuttongroup(app.GridLayout20);
            app.SealDeentradaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SealDeentradaButtonGroupSelectionChanged, true);
            app.SealDeentradaButtonGroup.Title = 'Señal de entrada';
            app.MicrofonoButton = uiradiobutton(app.SealDeentradaButtonGroup);
            app.MicrofonoButton.Text = 'Microfono';
            app.MicrofonoButton.Value = true;
            app.SimuladaButton = uiradiobutton(app.SealDeentradaButtonGroup);
            app.SimuladaButton.Text = 'Simulada';
            app.ArchivoButton = uiradiobutton(app.SealDeentradaButtonGroup);
            app.ArchivoButton.Text = 'Archivo';
            app.TarjetaButton = uiradiobutton(app.SealDeentradaButtonGroup);
            app.TarjetaButton.Text = 'Tarjeta';
            app.CheckSignal2 = uicheckbox(app.GridLayout17);
            app.CheckSignal2.Text = 'Operar señal 2';
            app.SignalsAdminTab = uitabgroup(app.GridLayout_2);
            app.MicrofonoTab_2 = uitab(app.SignalsAdminTab);
            app.MicrofonoTab_2.Title = 'Microfono';
            app.MicrofonoTab_2.ButtonDownFcn = createCallbackFcn(app, @MicrofonoTab_2ButtonDown, true);
            app.GridLayout8 = uigridlayout(app.MicrofonoTab_2);
            app.GraficaAudioInput = uiaxes(app.GridLayout8);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.GrabarButton = uibutton(app.GridLayout9, 'state');
            app.GrabarButton.ValueChangedFcn = createCallbackFcn(app, @GrabarButtonValueChanged, true);
            app.GrabarButton.Text = 'Grabar';
            app.SimuladaTab = uitab(app.SignalsAdminTab);
            app.SimuladaTab.Title = 'Simulada';
            app.SimuladaTab.ButtonDownFcn = createCallbackFcn(app, @SimuladaTabButtonDown, true);
            app.GridLayout4 = uigridlayout(app.SimuladaTab);
            app.GridLayout21 = uigridlayout(app.GridLayout4);
            app.SealesSimuladasButtonGroup = uibuttongroup(app.GridLayout21);
            app.SealesSimuladasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SealesSimuladasButtonGroupSelectionChanged, true);
            app.SealesSimuladasButtonGroup.Title = 'Señales Simuladas';
            app.DientedeSierraButton = uiradiobutton(app.SealesSimuladasButtonGroup);
            app.DientedeSierraButton.Text = 'Diente de Sierra';
            app.SenoButton = uiradiobutton(app.SealesSimuladasButtonGroup);
            app.SenoButton.Text = 'Seno';
            app.SenoButton.Value = true;
            app.CosenoButton = uiradiobutton(app.SealesSimuladasButtonGroup);
            app.CosenoButton.Text = 'Coseno';
            app.RampaButton = uiradiobutton(app.SealesSimuladasButtonGroup);
            app.RampaButton.Text = 'Rampa';
            app.TriangularButton = uiradiobutton(app.SealesSimuladasButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.EscalonButton = uiradiobutton(app.SealesSimuladasButtonGroup);
            app.EscalonButton.Text = 'Escalon';
            app.ChirpButton = uiradiobutton(app.SealesSimuladasButtonGroup);
            app.ChirpButton.Text = 'Chirp';
            app.SincButton = uiradiobutton(app.SealesSimuladasButtonGroup);
            app.SincButton.Text = 'Sinc';
            app.GridLayout22 = uigridlayout(app.GridLayout21);
            app.SimularSealButton = uibutton(app.GridLayout22, 'push');
            app.SimularSealButton.ButtonPushedFcn = createCallbackFcn(app, @SimularSealButtonPushed, true);
            app.SimularSealButton.Enable = 'off';
            app.SimularSealButton.Text = 'Simular Señal';
            app.ArchivoTab = uitab(app.SignalsAdminTab);
            app.ArchivoTab.Title = 'Archivo';
            app.ArchivoTab.ButtonDownFcn = createCallbackFcn(app, @ArchivoTabButtonDown, true);
            app.GridLayout6 = uigridlayout(app.ArchivoTab);
            app.PathLabel = uilabel(app.GridLayout6);
            app.PathLabel.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.CargarArchivoButton = uibutton(app.GridLayout7, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoButtonPushed, true);
            app.CargarArchivoButton.Enable = 'off';
            app.CargarArchivoButton.Text = 'Cargar Archivo';
            app.TarjetaTab = uitab(app.SignalsAdminTab);
            app.TarjetaTab.Title = 'Tarjeta';
            app.TarjetaTab.ButtonDownFcn = createCallbackFcn(app, @TarjetaTabButtonDown, true);
            app.GridLayout5 = uigridlayout(app.TarjetaTab);
            app.ListTajetas = uidropdown(app.GridLayout5);
            app.ListTajetas.Items = {};
            app.ListTajetas.Enable = 'off';
            app.ListTajetas.Value = {};
            app.AdquirirSealButton = uibutton(app.GridLayout5, 'push');
            app.AdquirirSealButton.ButtonPushedFcn = createCallbackFcn(app, @AdquirirSealButtonPushed, true);
            app.AdquirirSealButton.Enable = 'off';
            app.AdquirirSealButton.Text = 'Adquirir Señal';
            app.ManipulaciondesealTab = uitab(app.TabMaster);
            app.ManipulaciondesealTab.Title = 'Manipulacion de señal';
            app.ManipulaciondesealTab.ButtonDownFcn = createCallbackFcn(app, @ManipulaciondesealTabSizeChanged, true);
            app.GridLayout24 = uigridlayout(app.ManipulaciondesealTab);
            app.GridLayout28 = uigridlayout(app.GridLayout24);
            app.ManipulacionTab = uitabgroup(app.GridLayout28);
            app.PreprocesamientoyFiltradoTab = uitab(app.ManipulacionTab);
            app.PreprocesamientoyFiltradoTab.Title = 'Preprocesamiento y Filtrado';
            app.PreprocesamientoyFiltradoTab.ButtonDownFcn = createCallbackFcn(app, @PreprocesamientoyFiltradoTabSizeChanged, true);
            app.GridLayout24_2 = uigridlayout(app.PreprocesamientoyFiltradoTab);
            app.GridLayout25 = uigridlayout(app.GridLayout24_2);
            app.EliminaciondedatosNanoInfCheckBox = uicheckbox(app.GridLayout25);
            app.EliminaciondedatosNanoInfCheckBox.ValueChangedFcn = createCallbackFcn(app, @EliminaciondedatosNanoInfCheckBoxValueChanged, true);
            app.EliminaciondedatosNanoInfCheckBox.Text = 'Eliminacion de datos Nan o Inf';
            app.GridLayout41 = uigridlayout(app.GridLayout25);
            app.NormalizacionesDropDownLabel = uilabel(app.GridLayout41);
            app.NormalizacionesDropDownLabel.Text = 'Normalizaciones';
            app.NormalizacionCbox = uidropdown(app.GridLayout41);
            app.NormalizacionCbox.Items = {'Sin Normalización ', 'Normalización 0 a 1', 'Normalización -1 a 1', 'Normalización Estandar'};
            app.NormalizacionCbox.ValueChangedFcn = createCallbackFcn(app, @NormalizacionCboxValueChanged, true);
            app.NormalizacionCbox.Value = 'Sin Normalización ';
            app.ProcesodeFIltradosDropDownLabel = uilabel(app.GridLayout41);
            app.ProcesodeFIltradosDropDownLabel.Text = 'Proceso de FIltrados';
            app.FiltroCbox = uidropdown(app.GridLayout41);
            app.FiltroCbox.Items = {'Sin Filtro', 'Filtro con entrada h(n)', 'Filtro Pasabajos', 'Filtro Pasa Altos', 'Filtro IIR'};
            app.FiltroCbox.ValueChangedFcn = createCallbackFcn(app, @FiltroCboxValueChanged, true);
            app.FiltroCbox.Value = 'Sin Filtro';
            app.GridLayout27 = uigridlayout(app.GridLayout24_2);
            app.GuardarSealButton = uibutton(app.GridLayout27, 'push');
            app.GuardarSealButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarSealButtonPushed, true);
            app.GuardarSealButton.Text = 'Guardar Señal';
            app.hnLabel = uilabel(app.GridLayout27);
            app.hnLabel.Text = 'h(n):';
            app.hnTxt = uieditfield(app.GridLayout27, 'text');
            app.hnTxt.Value = '[1 1 1 1 1 1 1 1 1 1]';
            app.akEditFieldLabel = uilabel(app.GridLayout27);
            app.akEditFieldLabel.Text = 'ak:';
            app.akTxt = uieditfield(app.GridLayout27, 'text');
            app.akTxt.Value = '[1 1 1 1 1 1 1 1 1 1]';
            app.bkEditFieldLabel = uilabel(app.GridLayout27);
            app.bkEditFieldLabel.Text = 'bk:';
            app.bkTxt = uieditfield(app.GridLayout27, 'text');
            app.bkTxt.Value = '[1 1 1 1 1 1 1 1 1 1]';
            app.Operacionescon1sealTab = uitab(app.ManipulacionTab);
            app.Operacionescon1sealTab.Title = 'Operaciones con 1 señal';
            app.Operacionescon1sealTab.ButtonDownFcn = createCallbackFcn(app, @Operacionescon1sealTabSizeChanged, true);
            app.GridLayout30 = uigridlayout(app.Operacionescon1sealTab);
            app.GridLayout31 = uigridlayout(app.GridLayout30);
            app.FrecuenciadeMuestreoLabel = uilabel(app.GridLayout31);
            app.FrecuenciadeMuestreoLabel.Text = 'Frecuencia de Muestreo';
            app.GridLayout32 = uigridlayout(app.GridLayout31);
            app.FKEditFieldLabel = uilabel(app.GridLayout32);
            app.FKEditFieldLabel.Text = 'FK:';
            app.FKtxt = uieditfield(app.GridLayout32, 'numeric');
            app.FKtxt.Value = 1;
            app.CambioMuestreo = uibuttongroup(app.GridLayout31);
            app.CambioMuestreo.SelectionChangedFcn = createCallbackFcn(app, @CambioMuestreoSelectionChanged, true);
            app.SubMuestreoButton = uiradiobutton(app.CambioMuestreo);
            app.SubMuestreoButton.Text = 'SubMuestreo';
            app.SobreMuestreoButton = uiradiobutton(app.CambioMuestreo);
            app.SobreMuestreoButton.Text = 'SobreMuestreo';
            app.SinCambiosButton = uiradiobutton(app.CambioMuestreo);
            app.SinCambiosButton.Text = 'SinCambios';
            app.SinCambiosButton.Value = true;
            app.GridLayout31_2 = uigridlayout(app.GridLayout30);
            app.DesplazamientoLabel = uilabel(app.GridLayout31_2);
            app.DesplazamientoLabel.Text = 'Desplazamiento';
            app.GridLayout32_2 = uigridlayout(app.GridLayout31_2);
            app.DKEditFieldLabel = uilabel(app.GridLayout32_2);
            app.DKEditFieldLabel.Text = 'DK:';
            app.DKtxt = uieditfield(app.GridLayout32_2, 'numeric');
            app.DKtxt.Value = 1;
            app.DesplazaminetoButton = uibuttongroup(app.GridLayout31_2);
            app.DesplazaminetoButton.SelectionChangedFcn = createCallbackFcn(app, @DesplazaminetoButtonSelectionChanged, true);
            app.AdelantoButton = uiradiobutton(app.DesplazaminetoButton);
            app.AdelantoButton.Text = 'Adelanto';
            app.AtrasoButton = uiradiobutton(app.DesplazaminetoButton);
            app.AtrasoButton.Text = 'Atraso';
            app.SinCambiosButton_2 = uiradiobutton(app.DesplazaminetoButton);
            app.SinCambiosButton_2.Text = 'SinCambios';
            app.SinCambiosButton_2.Value = true;
            app.GridLayout33 = uigridlayout(app.GridLayout30);
            app.InvertirSealCheckBox = uicheckbox(app.GridLayout33);
            app.InvertirSealCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertirSealCheckBoxValueChanged, true);
            app.InvertirSealCheckBox.Text = 'Invertir Señal';
            app.GuardarcambiosdesealButton = uibutton(app.GridLayout33, 'push');
            app.GuardarcambiosdesealButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarSealButtonPushed, true);
            app.GuardarcambiosdesealButton.Text = 'Guardar cambios de señal';
            app.ConversionButton = uibuttongroup(app.GridLayout33);
            app.ConversionButton.SelectionChangedFcn = createCallbackFcn(app, @ConversionButtonSelectionChanged, true);
            app.SinCambiosButton_3 = uiradiobutton(app.ConversionButton);
            app.SinCambiosButton_3.Text = 'SinCambios';
            app.SinCambiosButton_3.Value = true;
            app.ConvertiraMonofnicaButton = uiradiobutton(app.ConversionButton);
            app.ConvertiraMonofnicaButton.Text = 'Convertir a Monofónica';
            app.ConvertiraEstereofnicaButton = uiradiobutton(app.ConversionButton);
            app.ConvertiraEstereofnicaButton.Text = 'Convertir a Estereofónica';
            app.OperacionescondossealesTab = uitab(app.ManipulacionTab);
            app.OperacionescondossealesTab.Title = 'Operaciones con dos señales';
            app.OperacionescondossealesTab.ButtonDownFcn = createCallbackFcn(app, @OperacionescondossealesTabSizeChanged, true);
            app.GridLayout34 = uigridlayout(app.OperacionescondossealesTab);
            app.GridLayout35 = uigridlayout(app.GridLayout34);
            app.SumarlasdossealesButton = uibutton(app.GridLayout35, 'push');
            app.SumarlasdossealesButton.ButtonPushedFcn = createCallbackFcn(app, @SumarlasdossealesButtonPushed, true);
            app.SumarlasdossealesButton.Text = 'Sumar las dos señales';
            app.MultiplicarLasdossealesButton = uibutton(app.GridLayout35, 'push');
            app.MultiplicarLasdossealesButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicarLasdossealesButtonPushed, true);
            app.MultiplicarLasdossealesButton.Text = 'Multiplicar Las dos señales';
            app.CrearsealEsteroButton = uibutton(app.GridLayout35, 'push');
            app.CrearsealEsteroButton.ButtonPushedFcn = createCallbackFcn(app, @CrearsealEsteroButtonPushed, true);
            app.CrearsealEsteroButton.Text = 'Crear señal Estero ';
            app.GridLayout36 = uigridlayout(app.GridLayout34);
            app.AjustedeVolumenLabel = uilabel(app.GridLayout36);
            app.AjustedeVolumenLabel.Text = 'Ajuste de Volumen';
            app.AmplitudEditFieldLabel = uilabel(app.GridLayout36);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.Amplitud = uieditfield(app.GridLayout36, 'numeric');
            app.Amplitud.ValueChangedFcn = createCallbackFcn(app, @AmplitudValueChanged, true);
            app.Amplitud.Value = 5;
            app.ReproducirButton = uibutton(app.GridLayout36, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.AmplitudLinealSliderLabel = uilabel(app.GridLayout36);
            app.AmplitudLinealSliderLabel.Text = 'Amplitud Lineal';
            app.AmplitudLineal_S = uislider(app.GridLayout36);
            app.AmplitudLineal_S.ValueChangedFcn = createCallbackFcn(app, @AmplitudLineal_SValueChanging, true);
            app.AmplitudLineal_S.Value = 5;
            app.GridLayout40 = uigridlayout(app.GridLayout34);
            app.ProducirEcoCheckBox = uicheckbox(app.GridLayout40);
            app.ProducirEcoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProducirEcoCheckBoxValueChanged, true);
            app.ProducirEcoCheckBox.Text = 'Producir Eco';
            app.A1Label = uilabel(app.GridLayout40);
            app.A1Label.Text = 'A1:';
            app.A1Txt = uieditfield(app.GridLayout40, 'numeric');
            app.A1Txt.ValueChangedFcn = createCallbackFcn(app, @A1TxtValueChanged, true);
            app.A1Txt.Value = 0.5;
            app.A2Label = uilabel(app.GridLayout40);
            app.A2Label.Text = 'A2:';
            app.A2Txt = uieditfield(app.GridLayout40, 'numeric');
            app.A2Txt.ValueChangedFcn = createCallbackFcn(app, @A2TxtValueChanged, true);
            app.A2Txt.Value = 0.3;
            app.GridLayout29 = uigridlayout(app.GridLayout24);
            app.GraficaSignalInicial = uiaxes(app.GridLayout29);
            title(app.GraficaSignalInicial, 'Señal Inicial')
            xlabel(app.GraficaSignalInicial, 'Tiempo')
            ylabel(app.GraficaSignalInicial, 'Amplitud')
            app.GraficaSinalManipulada = uiaxes(app.GridLayout29);
            title(app.GraficaSinalManipulada, 'Señal Manipulada')
            xlabel(app.GraficaSinalManipulada, 'Tiempo')
            ylabel(app.GraficaSinalManipulada, 'Amplitud')
            app.ManipulacionenfrecuenciaTab = uitab(app.TabMaster);
            app.ManipulacionenfrecuenciaTab.Title = 'Manipulacion en frecuencia';
            app.ManipulacionenfrecuenciaTab.ButtonDownFcn = createCallbackFcn(app, @ManipulacionenfrecuenciaTabSizeChanged, true);
            app.GridLayout37 = uigridlayout(app.ManipulacionenfrecuenciaTab);
            app.GridLayout38 = uigridlayout(app.GridLayout37);
            app.ObservarEspectrogramaButton = uibutton(app.GridLayout38, 'push');
            app.ObservarEspectrogramaButton.ButtonPushedFcn = createCallbackFcn(app, @ObservarEspectrogramaButtonPushed, true);
            app.ObservarEspectrogramaButton.Text = 'Observar Espectrograma';
            app.TransformadadeFurierButton = uibutton(app.GridLayout38, 'push');
            app.TransformadadeFurierButton.ButtonPushedFcn = createCallbackFcn(app, @TransformadadeFurierButtonPushed, true);
            app.TransformadadeFurierButton.Text = 'Transformada de Furier';
            app.GridLayout39 = uigridlayout(app.GridLayout37);
            app.GraficaTiempoEntrada = uiaxes(app.GridLayout39);
            title(app.GraficaTiempoEntrada, 'Entrada')
            xlabel(app.GraficaTiempoEntrada, 'Tiempo')
            ylabel(app.GraficaTiempoEntrada, 'Amplitud')
            zlabel(app.GraficaTiempoEntrada, 'Z')
            app.GraficaTiempoSalida = uiaxes(app.GridLayout39);
            title(app.GraficaTiempoSalida, 'Salida')
            xlabel(app.GraficaTiempoSalida, 'Tiempo')
            ylabel(app.GraficaTiempoSalida, 'Amplitud')
            zlabel(app.GraficaTiempoSalida, 'Z')
            app.GraficaFrecuenciaEntrada = uiaxes(app.GridLayout39);
            title(app.GraficaFrecuenciaEntrada, 'Espectrograma de Entrada')
            xlabel(app.GraficaFrecuenciaEntrada, 'Frecuencia')
            ylabel(app.GraficaFrecuenciaEntrada, 'Frecuencia')
            zlabel(app.GraficaFrecuenciaEntrada, 'Z')
            app.GraficaFrecuenciaSalida = uiaxes(app.GridLayout39);
            title(app.GraficaFrecuenciaSalida, 'Espectrograma de salida')
            xlabel(app.GraficaFrecuenciaSalida, 'Tiempo')
            ylabel(app.GraficaFrecuenciaSalida, 'Frecuencia')
            zlabel(app.GraficaFrecuenciaSalida, 'Z')
            app.MagnitudAxes = uiaxes(app.GridLayout39);
            title(app.MagnitudAxes, 'Magnitud')
            xlabel(app.MagnitudAxes, 'Frecuencia')
            ylabel(app.MagnitudAxes, 'Magnitud')
            zlabel(app.MagnitudAxes, 'Z')
            app.FaseAxes = uiaxes(app.GridLayout39);
            title(app.FaseAxes, 'Fase')
            xlabel(app.FaseAxes, 'Frecuencia')
            ylabel(app.FaseAxes, 'Fase (Radianes)')
            zlabel(app.FaseAxes, 'Z')
            app.Tab = uitab(app.TabMaster);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoSistemas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end