classdef ProyectoSistemasV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ArchivoMenu                matlab.ui.container.Menu
        NuevaMenu                  matlab.ui.container.Menu
        GuardarMenu                matlab.ui.container.Menu
        BorrarMenu                 matlab.ui.container.Menu
        ExportarSealMenu           matlab.ui.container.Menu
        CSVMenu                    matlab.ui.container.Menu
        WAVMenu                    matlab.ui.container.Menu
        SalirMenu                  matlab.ui.container.Menu
        HerramientasMenu           matlab.ui.container.Menu
        NormalizacinMenu           matlab.ui.container.Menu
        FiltrarMenu                matlab.ui.container.Menu
        EntradadesistemaHnMenu     matlab.ui.container.Menu
        FiltroIIRMenu              matlab.ui.container.Menu
        PasabajosMenu              matlab.ui.container.Menu
        PasaaltosMenu              matlab.ui.container.Menu
        EliminarNANeINFMenu        matlab.ui.container.Menu
        SubMuestrearMenu           matlab.ui.container.Menu
        SobreMuestrearMenu         matlab.ui.container.Menu
        DesplazarMenu              matlab.ui.container.Menu
        InvertirSealMenu           matlab.ui.container.Menu
        ProducirEcoMenu            matlab.ui.container.Menu
        OperarMenu                 matlab.ui.container.Menu
        ConvertirMonofnicaMenu     matlab.ui.container.Menu
        ConvertirEstereofnicaMenu  matlab.ui.container.Menu
        Operar2SealesMenu          matlab.ui.container.Menu
        CrearSealEstereoMenu       matlab.ui.container.Menu
        transformadadeFurierMenu   matlab.ui.container.Menu
        SpectogramaMenu            matlab.ui.container.Menu
        AudioMenu                  matlab.ui.container.Menu
        AcercadeMenu               matlab.ui.container.Menu
        InfoMenu                   matlab.ui.container.Menu
        Version20Menu              matlab.ui.container.Menu
        Menu                       matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        SignalCbox                 matlab.ui.control.DropDown
        Graficas                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %Parámetro de la señal
        tipo;
        Ganancia=[];FrecuenciaAnaloga=[];FrecuenciaMuestreo=[];Fase=[];Desplazamiento=[];
        

        %Señales
        Signals = {}
        Signal_New = {}

        %Tiempos
        n_inicio={};
        n_final={};
        ni_New = {};
        nf_New = {};
        n = {};
        n_New = {};
        
        % Variables glovales de aplicacion
        Cbidx = 0; % Indice del DropDown, lista desplegable o Combobox 
        
    end
    
    methods (Access = public)
        
        % Funcion para actualizar la Grafica y los parámetros desde la
        % simulacion de señales
        

        %Funcion para graficar las señales
        function Graficar(app, IndiceSignal) 
            cla(app.Graficas, 'reset'); 

            disp("Original Señal (Signal/tiempo)")
            disp(length(app.Signals{IndiceSignal}));
            disp(length(app.n{IndiceSignal}));

            stem(app.Graficas, app.n{IndiceSignal}, app.Signals{IndiceSignal}, 'b', 'filled'); % Usando Stem para graficar en modo discreto 

            title(app.Graficas, app.SignalCbox.Value); %Parametros de Titilo
            xlabel(app.Graficas, "Tiempo");
            ylabel(app.Graficas, "Amplitud");

            % Cambiar color del título y etiquetas de los ejes a blanco
            app.Graficas.Title.Color = 'w';   % Título blanco
            app.Graficas.XLabel.Color = 'w';  % Etiqueta del eje X blanca
            app.Graficas.YLabel.Color = 'w';  % Etiqueta del eje Y blanca
            
            % Cambiar color de los números de los ejes a blanco
            app.Graficas.XColor = 'w';  % Color de los números del eje X
            app.Graficas.YColor = 'w';  % Color de los números del eje Y
            grid(app.Graficas, 'on');   % Activando la grilla
            app.Graficas.GridColor = [0.85 0.37 0.17];  % Color de la grilla
        end

        function GraficarNueva(app, IndiceSignal, K, Tipo) 
            cla(app.Graficas, 'reset'); 
            
            if(Tipo==0)
                app.n_New{IndiceSignal} = app.n_inicio{app.Cbidx }:app.n_final{app.Cbidx};
            elseif(Tipo == 1)       % Si tipo = 0 -> Submuestreo, Si tipo = 1 -> Sobremuestreo
                app.n_New{IndiceSignal} = app.ni_New{app.Cbidx} : K : app.nf_New{app.Cbidx};
            elseif(Tipo == 2)
                app.n_New{IndiceSignal} = app.ni_New{app.Cbidx} : 1/K : app.nf_New{app.Cbidx};
            end

            disp("Nueva Señal (Signal/tiempo)")
            disp(length(app.Signal_New{IndiceSignal}));
            disp(length(app.n_New{IndiceSignal}));
            stem(app.Graficas, app.n_New{IndiceSignal}, app.Signal_New{IndiceSignal}, 'b', 'filled'); % Usando Stem para graficar en modo discreto 

            title(app.Graficas, app.SignalCbox.Value); %Parametros de Titilo
            xlabel(app.Graficas, "Tiempo");
            ylabel(app.Graficas, "Amplitud");

            % Cambiar color del título y etiquetas de los ejes a blanco
            app.Graficas.Title.Color = 'w';   % Título blanco
            app.Graficas.XLabel.Color = 'w';  % Etiqueta del eje X blanca
            app.Graficas.YLabel.Color = 'w';  % Etiqueta del eje Y blanca
            
            % Cambiar color de los números de los ejes a blanco
            app.Graficas.XColor = 'w';  % Color de los números del eje X
            app.Graficas.YColor = 'w';  % Color de los números del eje Y
            grid(app.Graficas, 'on');   % Activando la grilla
            app.Graficas.GridColor = [0.85 0.37 0.17];  % Color de la grilla
        end
        
        %Funcion para graficar las señales
        function Graficar2Signal(app, IndiceSignal) 
            cla(app.Graficas, 'reset'); 

            disp("2 Señal (Signal/tiempo)")
            disp(length(app.Signals{IndiceSignal}));
            disp(length(app.n{IndiceSignal}));
            
            hold(app.Graficas, 'on');  % Mantiene el gráfico actual
            Canal1 = app.Signals{IndiceSignal}(:, 1); % Toda la columna 1
            Canal2 = app.Signals{IndiceSignal}(:, 2); % Toda la columna 2
            stem(app.Graficas, app.n{IndiceSignal}, Canal1, 'b', 'filled');
            stem(app.Graficas, app.n{IndiceSignal}, Canal2, 'r', 'filled');;
            hold(app.Graficas, 'off'); % Libera el gráfico

            
            title(app.Graficas, app.SignalCbox.Value); %Parametros de Titilo
            xlabel(app.Graficas, "Tiempo");
            ylabel(app.Graficas, "Amplitud");

            % Cambiar color del título y etiquetas de los ejes a blanco
            app.Graficas.Title.Color = 'w';   % Título blanco
            app.Graficas.XLabel.Color = 'w';  % Etiqueta del eje X blanca
            app.Graficas.YLabel.Color = 'w';  % Etiqueta del eje Y blanca
            
            % Cambiar color de los números de los ejes a blanco
            app.Graficas.XColor = 'w';  % Color de los números del eje X
            app.Graficas.YColor = 'w';  % Color de los números del eje Y
            grid(app.Graficas, 'on');   % Activando la grilla
            app.Graficas.GridColor = [0.85 0.37 0.17];  % Color de la grilla
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: NormalizacinMenu, NuevaMenu
        function GenerarnuevasealMenuSelected(app, event)
            f = length(app.Signals); % Capturar cantidad de Señales creadas

           
            %************************************************************
            app2 = Crear(); % Crea la nueva app
            Signal = [];
            % Pausa la ejecución hasta que el usuario cierre la ventana
            
            uiwait(app2.UIFigure);
            
            if isvalid(app2)    
                %if(app2.Tipo == 2 || app2.Tipo == 3)% Tipo 2 son las señales simuladas
                    
                Signal = app2.Signal;
                app.Ganancia = [app.Ganancia, app2.Ganancia];
                app.FrecuenciaAnaloga = [app.FrecuenciaAnaloga , app2.FrecuenciaAnaloga];
                app.FrecuenciaMuestreo = [app.FrecuenciaMuestreo , app2.FrecuenciaMuestreo];
                app.Fase = [app.Fase, app2.Fase];
                app.Desplazamiento = [app.Desplazamiento, app2.Desplazamiento];
                app.n_inicio = [app.n_inicio,  app2.n_inicio];
                app.n_final = [app.n_final,  app2.n_final];
                app.n = [app.n,  app2.n];
    
                %end
                
               
                %************************************************************
                if isempty(app.Signals)     % Si no hay señales se crea la nueva etiqueta en el dropdown
    
                    app.SignalCbox.Items= "Señal 1";
                    app.Signals{1} = Signal; % Primera señal
    
                else                        % Si ya hay señales se agrega una nueva señal con el indice de una nueva señal
    
                    app.SignalCbox.Items = [app.SignalCbox.Items, {['Señal ' num2str(f+1)]}];
                    app.SignalCbox.ValueIndex = f+1; % Se pone el dropdown por defecto en la nueva señal
                    app.Signals{end+1} = Signal;
    
                end
                app.Cbidx = length(app.Signals);
                
                app.Graficar(f+1); % Graficar señal
            end
            % Siempre eliminar la app2
            if isvalid(app2)
                % Restaurar el focus a tu App
                figure(app.UIFigure) % <- Esto hace que tu app vuelva al frente
                delete(app2);

            end
            clear app2;

        end

        % Menu selected function: EntradadesistemaHnMenu
        function EntradadesistemaHnMenuSelected(app, event)
           
        end

        % Menu selected function: FiltroIIRMenu
        function FiltroIIRMenuSelected(app, event)
            
        end

        % Menu selected function: BorrarMenu
        function BorrarMenuSelected(app, event)
            

            app.Signals(app.Cbidx) = [];    % Se elimina el valor de la señal que se quiere de 
                                            % Cbidx = indice del cbox 
            
            app.n_inicio(app.Cbidx) = [];
            app.n_final(app.Cbidx)= [];
            app.n(app.Cbidx) = [];

            if app.Cbidx > 0 && app.Cbidx <= numel(app.Signal_New)
                app.Signal_New(app.Cbidx) = []; % Borra toda la información de copias y tiempos
                                            % ya que los arreglos van desde 1 y el dropdown desde 0
                app.ni_New(app.Cbidx) = [];
                app.nf_New(app.Cbidx)= [];
                app.n_New(app.Cbidx) = [];
            end
            app.Ganancia(app.Cbidx) = [];
            app.FrecuenciaAnaloga(app.Cbidx) = [];
            app.FrecuenciaMuestreo(app.Cbidx) = [];
            app.Fase(app.Cbidx) = [];
            app.Desplazamiento(app.Cbidx) = [];


            if isempty(app.Signals) % Si no hay señales solo borra lo que hay en el marco sino grafica la primera
                app.Cbidx = 0;
                app.SignalCbox.Items= "Signals";
                cla(app.Graficas, 'reset'); % Borra todo y restablece
                grid(app.Graficas, 'on'); % Vuelve a activar la cuadrícula
 
            else
                 f= length(app.Signals);         % verifica la cantidad de señales existentes primero
                
                app.SignalCbox.Items(f) = [];% se elimina el ultimo valor del dropdown dejando la cantidad exacta de items para las señales  
                app.SignalCbox.ValueIndex = 1;
        
                app.Graficar(1);
                app.Cbidx = 1;
                app.SignalCbox.Items= "Señal 1";
                for i=2:f
                    app.SignalCbox.Items = [app.SignalCbox.Items, {['Señal ' num2str(i)]}];
                    
                end
            end
        end

        % Value changed function: SignalCbox
        function SignalCboxValueChanged(app, event)
            app.Cbidx = app.SignalCbox.ValueIndex;% Se consigue el indice del valor del dropdown
            app.Graficar(app.Cbidx); % Graficar señal
            
        end

        % Menu selected function: GuardarMenu
        function GuardarMenuSelected(app, event)
            app.Signals{app.Cbidx} = app.Signal_New{app.Cbidx};
            app.n_inicio{app.Cbidx} = app.ni_New{app.Cbidx};
            app.n_final{app.Cbidx} = app.nf_New{app.Cbidx};
            app.n{app.Cbidx} = app.n_New{app.Cbidx};
            
        end

        % Menu selected function: CSVMenu
        function ExportarSealMenuSelected(app, event)
            %Guardar en equipo 
            countName = 1;
            % Verificacion si el archivo csv existe
            while exist(['Signal' num2str(countName) '.csv'], 'file') == 2
                countName = countName + 1;
            end
            
            archivoName = ['Signal' num2str(countName) '.csv'];
            archivo = fopen(archivoName, 'w');
            
            data = app.Signals{app.Cbidx}; % Tu señal, supongo que es [N x 2]
            
            % Ahora escribimos fila por fila
            if size(data,2) == 1
                % Solo 1 canal
                for i = 1:size(data,1)
                    fprintf(archivo, '%f\n', data(i,1));
                end
            elseif size(data,2) == 2
                % Dos canales
                for i = 1:size(data,1)
                    fprintf(archivo, '%f,%f\n', data(i,1), data(i,2));
                end
            else
                % Por si algún día tienes más de 2 canales
                msgbox('La señal tiene más de 2 canales, no se exportó. ', 'Confirmación', 'help');
            end
            
            fclose(archivo);

            msgbox(['Señal exportada en ' archivoName], 'Confirmación', 'help');

            
        end

        % Menu selected function: SubMuestrearMenu
        function SubMuestrearMenuSelected(app, event)
            prompt = {'Valor de la frecuencia de muestreo K (Fk):'};              % Pregunta
            dlgtitle = 'Sub_Muestreo';                                          % Título del cuadro
            dims = [1 40];                                                      % Tamaño del cuadro de entrada
            definput = {'1'};                                                   % Valor por defecto
            
            respuesta = inputdlg(prompt, dlgtitle, dims, definput);             % Mostrar cuadro
            
            if ~isempty(respuesta)
                K = str2double(respuesta{1});                                       % K = constante de intervalos nuevos
                if(K>0)
                    if isnan(K)
                        msgbox('La entrada no es un número válido.', 'Error', 'error');
                    else
                        app.Signal_New{app.Cbidx} = decimate(app.Signals{app.Cbidx}, K);% Aqui realiza el cambio de submuestreo
                        app.ni_New{app.Cbidx} = app.n_inicio{app.Cbidx};
                        app.nf_New{app.Cbidx} = app.ni_New{app.Cbidx} + (length(app.Signal_New{app.Cbidx}) - 1) * K;
    
                    end
                else
                    msgbox('Entrada cancelada. K debe ser mayor a 0', 'Cancelado');
                end
            else
                msgbox('Entrada cancelada.', 'Cancelado');
            end
            app.GraficarNueva(app.Cbidx, K, 1);
        end

        % Menu selected function: SobreMuestrearMenu
        function SobreMuestrearMenuSelected(app, event)
            prompt = {'Valor de la frecuencia de muestreo K (Fk):'};              % Pregunta
            dlgtitle = 'Sobre-Muestreo';                                        % Título del cuadro
            dims = [1 40];                                                      % Tamaño del cuadro de entrada
            definput = {'1'};                                                   % Valor por defecto
            
            respuesta = inputdlg(prompt, dlgtitle, dims, definput);             % Mostrar cuadro
            
            if ~isempty(respuesta)
                K = str2double(respuesta{1});                                   % K = constante de intervalos nuevos
                if(K>0)
                    if isnan(K)
                        msgbox('La entrada no es un número válido.', 'Error', 'error');
                    else
                        app.Signal_New{app.Cbidx} = interp(app.Signals{app.Cbidx}, K);% Aqui realiza el cambio de submuestreo
                        app.ni_New{app.Cbidx} = app.n_inicio{app.Cbidx};
                        app.nf_New{app.Cbidx} = app.ni_New{app.Cbidx} + (length(app.Signal_New{app.Cbidx}) - 1) / K;
    
                    end
                else
                    msgbox('Entrada cancelada. K debe ser mayor a 0', 'Cancelado');
                end
            else
                msgbox('Entrada cancelada.', 'Cancelado');
            end
            app.GraficarNueva(app.Cbidx,K,2);
        end

        % Callback function
        function AdelantoMenuSelected(app, event)
            
        end

        % Menu selected function: DesplazarMenu
        function DesplazarMenuSelected(app, event)
            prompt = {'Valor de desplazamiento K/-K (Dk):'};              % Pregunta
            dlgtitle = 'Desplazamiento';                                          % Título del cuadro
            dims = [1 40];                                                      % Tamaño del cuadro de entrada
            definput = {'0'};                                                   % Valor por defecto
            
            respuesta = inputdlg(prompt, dlgtitle, dims, definput);             % Mostrar cuadro
            
            if ~isempty(respuesta)
                K = str2double(respuesta{1});  % Valor del desplazamiento
    
                if isnan(K)
                    msgbox('La entrada no es un número válido.', 'Error', 'error');
                else
                    x = app.Signals{app.Cbidx};  % Señal original
                    N = length(x);               % Tamaño de la señal
                    
                    if K > 0
                        % Atraso: repetir el primer valor K veces al inicio
                        x_new = [repmat(x(1), 1, K), x(1:end-K)];
                        app.ni_New{app.Cbidx} = app.n_inicio{app.Cbidx} + K;
                        app.nf_New{app.Cbidx} = app.n_final{app.Cbidx} + K;
            
                    elseif K < 0
                        % Adelanto: repetir el último valor al final
                        K = abs(K);
                        x_new = [x(K+1:end), repmat(x(end), 1, K)];
                        app.ni_New{app.Cbidx} = app.n_inicio{app.Cbidx} - K;
                        app.nf_New{app.Cbidx} = app.n_final{app.Cbidx} - K;
            
                    else
                        % Sin desplazamiento
                        x_new = x;
                        app.ni_New{app.Cbidx} = app.n_inicio{app.Cbidx};
                        app.nf_New{app.Cbidx} = app.nf_inicio{app.Cbidx};
                    end
            
                    % Guardar nueva señal
                    app.Desplazamiento(app.Cbidx) = K;
                    app.Signal_New{app.Cbidx} = x_new;
                end
            else
                msgbox('Entrada cancelada.', 'Cancelado');
            end
            
            app.GraficarNueva(app.Cbidx,0,0);
        end

        % Menu selected function: Operar2SealesMenu
        function Operar2SealesMenuSelected(app, event)
            disp(app.Cbidx);
            if ~isempty(app.Signals)     % Verifica si por lo menos hay una señal
                
                %************************************************************
                app2 = TwoSignals(); % Crea la nueva app
                app2.cargarDropdown(app.Signals, app.n);

                % Pausa la ejecución hasta que el usuario cierre la ventana
                uiwait(app2.UIFigure);
                % Verificar si el usuario cerró la ventana sin seleccionar nada
                if isvalid(app2)

                    app.SignalCbox.Items = [app.SignalCbox.Items, {['Señal ' num2str(length(app.Signals)+1)]}];
                    Signal = app2.Signal;                       %Trae la nueva señal creada 
                    
                    app.Signals{end+1} = Signal;
                    app.n_inicio = [app.n_inicio,  app2.ni];
                    app.n_final = [app.n_final,  app2.nf];
                    app.n = [app.n,  app2.n];
                    
                    app.Ganancia = [app.Ganancia, app2.Amplitud];
                    app.FrecuenciaAnaloga = [app.FrecuenciaAnaloga , app2.frecuenciaAnalogica];
                    app.FrecuenciaMuestreo = [app.FrecuenciaMuestreo , app2.FrecuenciaMuestreo];
                    app.Fase = [app.Fase, 0];
                    app.Desplazamiento = [app.Desplazamiento, app2.Desplazamiento];

                    app.Cbidx = app.Cbidx+1;
                    app.SignalCbox.ValueIndex = app.Cbidx;    % pone los valores por defecto
                    app.Graficar(app.Cbidx);
                end

                % Siempre eliminar la app2
                if isvalid(app2)

                    delete(app2);

                end
                clear app2;

            else                       
                msgbox('No hay señales.', 'Cancelado');
            end
           
        end

        % Menu selected function: CrearSealEstereoMenu
        function CrearSealEstereoMenuSelected(app, event)
            
            if ~isempty(app.Signals)     % Verifica si por lo menos hay una señal
                
                %************************************************************
                app2 = EstereoSignals(); % Crea la nueva app
                app2.cargarDropdown(app.Signals, app.n,app.n_inicio,app.FrecuenciaMuestreo);

                % Pausa la ejecución hasta que el usuario cierre la ventana
                uiwait(app2.UIFigure);
                % Verificar si el usuario cerró la ventana sin seleccionar nada
                if isvalid(app2)

                    app.SignalCbox.Items = [app.SignalCbox.Items, {['Señal ' num2str(length(app.Signals)+1)]}];
                    Signal = app2.Signal;                       %Trae la nueva señal creada 
                    
                    app.Signals{end+1} = Signal;
                    app.n_inicio = [app.n_inicio,  app2.ni];
                    app.n_final = [app.n_final,  app2.nf];
                    app.n = [app.n,  app2.n];
                    
                    app.Ganancia = [app.Ganancia, app2.Amplitud];
                    app.FrecuenciaAnaloga = [app.FrecuenciaAnaloga , app2.frecuenciaAnalogica];
                    app.FrecuenciaMuestreo = [app.FrecuenciaMuestreo , app2.FrecuenciaMuestreo];
                    app.Fase = [app.Fase, 0];
                    app.Desplazamiento = [app.Desplazamiento, app2.Desplazamiento];

                    app.Cbidx = length(app.Signals);
                    disp(app.Cbidx);
                    app.SignalCbox.ValueIndex = app.Cbidx;    % pone los valores por defecto

                    app.Graficar2Signal(app.Cbidx);
                end

                % Siempre eliminar la app2
                if isvalid(app2)

                    delete(app2);

                end
                clear app2;

            else                       
                msgbox('No hay señales.', 'Cancelado');
            end
        end

        % Menu selected function: AudioMenu
        function AudioMenuSelected(app, event)
            if ~isempty(app.Signals)     % Verifica si por lo menos hay una señal
                %************************************************************
                app2 = Audio(); % Crea la nueva app
                index = app.Cbidx;
                app2.AgregarSignal(app.Signals{index},app.FrecuenciaMuestreo(index),app.Ganancia(index));
                %Signal = [];
                % Pausa la ejecución hasta que el usuario cierre la ventana
                uiwait(app2.UIFigure);
                if isvalid(app2)
                    disp(length (app2.Signal));
                    app.Signal_New{index} = app2.Signal;
                    app.ni_New{index} = app.n_inicio{index};
                    app.nf_New{index} = app.n_final{index};
                    app.n_New{index} = app.n{index} ;

                    app.GraficarNueva(index,0,0);
                end

                % Siempre eliminar la app2
                if isvalid(app2)

                    delete(app2);

                end
                clear app2;
            else
                msgbox('No hay señales.', 'Cancelado');
            end
            
        end

        % Menu selected function: ConvertirMonofnicaMenu
        function ConvertirMonofnicaMenuSelected(app, event)
            if ~isempty(app.Signals)     % Verifica si por lo menos hay una señal
                %************************************************************
                index = app.Cbidx;
                app.Signal_New{index} = mean(app.Signals{index}, 2);
                app.ni_New{index} = app.n_inicio{index};
                app.nf_New{index} = app.n_final{index};
                app.n_New{index} = app.n{index} ;
                
                app.GraficarNueva(index,0,0);
            else
                msgbox('No hay señales.', 'Cancelado');
            end

        end

        % Menu selected function: ConvertirEstereofnicaMenu
        function ConvertirEstereofnicaMenuSelected(app, event)
              if ~isempty(app.Signals)     % Verifica si por lo menos hay una señal
                %************************************************************  
                index = app.Cbidx;
                app.Signal_New = { [app.Signals{index}, app.Signals{index}] };
                app.ni_New{index} = app.n_inicio{index};
                app.nf_New{index} = app.n_final{index};
                app.n_New{index} = app.n{index} ;
                
                %disp(app.Signal_New);
                app.GraficarNueva(index,0,0);
              else
                msgbox('No hay señales.', 'Cancelado');
              end
        end

        % Menu selected function: WAVMenu
        function WAVMenuSelected(app, event)
           
            % Guardar en equipo en formato WAV
            countName = 1;
            
            % Verificar si el archivo WAV existe
            while exist(['Signal' num2str(countName) '.wav'], 'file') == 2
                countName = countName + 1;
            end
            
            archivoName = ['Signal' num2str(countName) '.wav'];
            
            data = app.Signals{app.Cbidx}; % Tu señal, supongo que es [N x 1] o [N x 2]
            
            % Normalizar si es necesario para que esté entre -1 y 1
            if max(abs(data), [], 'all') > 1
                data = data / max(abs(data), [], 'all'); % Normalizar
            end
            
            % Validar que exista frecuencia de muestreo válida
            if isempty(app.FrecuenciaMuestreo) || app.FrecuenciaMuestreo <= 80
                uialert(app.UIFigure, 'Frecuencia de muestreo inválida.', 'Error');
                return;
            end
            
            % Guardar el archivo WAV
            audiowrite(archivoName, data, app.FrecuenciaMuestreo);
            
            % Mensaje de confirmación
            msgbox(['Señal exportada en ' archivoName], 'Confirmación', 'help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.NuevaMenu = uimenu(app.ArchivoMenu);
            app.NuevaMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerarnuevasealMenuSelected, true);
            app.NuevaMenu.Text = 'Nueva';
            app.GuardarMenu = uimenu(app.ArchivoMenu);
            app.GuardarMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarMenuSelected, true);
            app.GuardarMenu.Separator = 'on';
            app.GuardarMenu.Text = 'Guardar';
            app.BorrarMenu = uimenu(app.ArchivoMenu);
            app.BorrarMenu.MenuSelectedFcn = createCallbackFcn(app, @BorrarMenuSelected, true);
            app.BorrarMenu.Text = 'Borrar';
            app.ExportarSealMenu = uimenu(app.ArchivoMenu);
            app.ExportarSealMenu.Text = 'Exportar Señal';
            app.CSVMenu = uimenu(app.ExportarSealMenu);
            app.CSVMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportarSealMenuSelected, true);
            app.CSVMenu.Text = 'CSV';
            app.WAVMenu = uimenu(app.ExportarSealMenu);
            app.WAVMenu.MenuSelectedFcn = createCallbackFcn(app, @WAVMenuSelected, true);
            app.WAVMenu.Text = 'WAV';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.Separator = 'on';
            app.SalirMenu.Text = 'Salir';
            app.HerramientasMenu = uimenu(app.UIFigure);
            app.HerramientasMenu.Text = 'Herramientas';
            app.NormalizacinMenu = uimenu(app.HerramientasMenu);
            app.NormalizacinMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerarnuevasealMenuSelected, true);
            app.NormalizacinMenu.Text = 'Normalización ';
            app.FiltrarMenu = uimenu(app.HerramientasMenu);
            app.FiltrarMenu.Text = 'Filtrar';
            app.EntradadesistemaHnMenu = uimenu(app.FiltrarMenu);
            app.EntradadesistemaHnMenu.MenuSelectedFcn = createCallbackFcn(app, @EntradadesistemaHnMenuSelected, true);
            app.EntradadesistemaHnMenu.Text = 'Entrada de sistema H(n)';
            app.FiltroIIRMenu = uimenu(app.FiltrarMenu);
            app.FiltroIIRMenu.MenuSelectedFcn = createCallbackFcn(app, @FiltroIIRMenuSelected, true);
            app.FiltroIIRMenu.Text = 'Filtro IIR';
            app.PasabajosMenu = uimenu(app.FiltrarMenu);
            app.PasabajosMenu.Text = 'Pasa bajos';
            app.PasaaltosMenu = uimenu(app.FiltrarMenu);
            app.PasaaltosMenu.Text = 'Pasa altos';
            app.EliminarNANeINFMenu = uimenu(app.HerramientasMenu);
            app.EliminarNANeINFMenu.Text = 'Eliminar NAN e INF';
            app.SubMuestrearMenu = uimenu(app.HerramientasMenu);
            app.SubMuestrearMenu.MenuSelectedFcn = createCallbackFcn(app, @SubMuestrearMenuSelected, true);
            app.SubMuestrearMenu.Separator = 'on';
            app.SubMuestrearMenu.Text = 'Sub-Muestrear';
            app.SobreMuestrearMenu = uimenu(app.HerramientasMenu);
            app.SobreMuestrearMenu.MenuSelectedFcn = createCallbackFcn(app, @SobreMuestrearMenuSelected, true);
            app.SobreMuestrearMenu.Text = 'Sobre-Muestrear';
            app.DesplazarMenu = uimenu(app.HerramientasMenu);
            app.DesplazarMenu.MenuSelectedFcn = createCallbackFcn(app, @DesplazarMenuSelected, true);
            app.DesplazarMenu.Text = 'Desplazar';
            app.InvertirSealMenu = uimenu(app.HerramientasMenu);
            app.InvertirSealMenu.Separator = 'on';
            app.InvertirSealMenu.Text = 'Invertir Señal';
            app.ProducirEcoMenu = uimenu(app.HerramientasMenu);
            app.ProducirEcoMenu.Text = 'Producir Eco';
            app.OperarMenu = uimenu(app.UIFigure);
            app.OperarMenu.Text = 'Operar';
            app.ConvertirMonofnicaMenu = uimenu(app.OperarMenu);
            app.ConvertirMonofnicaMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvertirMonofnicaMenuSelected, true);
            app.ConvertirMonofnicaMenu.Text = 'Convertir Monofónica';
            app.ConvertirEstereofnicaMenu = uimenu(app.OperarMenu);
            app.ConvertirEstereofnicaMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvertirEstereofnicaMenuSelected, true);
            app.ConvertirEstereofnicaMenu.Text = 'Convertir Estereofónica';
            app.Operar2SealesMenu = uimenu(app.OperarMenu);
            app.Operar2SealesMenu.MenuSelectedFcn = createCallbackFcn(app, @Operar2SealesMenuSelected, true);
            app.Operar2SealesMenu.Separator = 'on';
            app.Operar2SealesMenu.Text = 'Operar 2 Señales';
            app.CrearSealEstereoMenu = uimenu(app.OperarMenu);
            app.CrearSealEstereoMenu.MenuSelectedFcn = createCallbackFcn(app, @CrearSealEstereoMenuSelected, true);
            app.CrearSealEstereoMenu.Text = 'Crear Señal Estereo';
            app.transformadadeFurierMenu = uimenu(app.OperarMenu);
            app.transformadadeFurierMenu.Separator = 'on';
            app.transformadadeFurierMenu.Text = 'transformada de Furier';
            app.SpectogramaMenu = uimenu(app.OperarMenu);
            app.SpectogramaMenu.Text = 'Spectograma';
            app.AudioMenu = uimenu(app.OperarMenu);
            app.AudioMenu.MenuSelectedFcn = createCallbackFcn(app, @AudioMenuSelected, true);
            app.AudioMenu.Separator = 'on';
            app.AudioMenu.Text = 'Audio';
            app.AcercadeMenu = uimenu(app.UIFigure);
            app.AcercadeMenu.Text = 'Acerca de';
            app.InfoMenu = uimenu(app.AcercadeMenu);
            app.InfoMenu.Text = 'Info';
            app.Version20Menu = uimenu(app.AcercadeMenu);
            app.Version20Menu.Separator = 'on';
            app.Version20Menu.Text = 'Version 2.0';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Graficas = uiaxes(app.GridLayout);
            title(app.Graficas, 'Title')
            xlabel(app.Graficas, 'X')
            ylabel(app.Graficas, 'Y')
            zlabel(app.Graficas, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SignalCbox = uidropdown(app.GridLayout2);
            app.SignalCbox.Items = {'Signals'};
            app.SignalCbox.ValueChangedFcn = createCallbackFcn(app, @SignalCboxValueChanged, true);
            app.SignalCbox.Value = 'Signals';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoSistemasV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end