classdef TwoSignals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        GridLayout2      matlab.ui.container.GridLayout
        Btn_CrearSignal  matlab.ui.control.Button
        GridLayout_2     matlab.ui.container.GridLayout
        Cbx_Signal2      matlab.ui.control.DropDown
        Cbx_Operation    matlab.ui.control.DropDown
        Cbx_Signal1      matlab.ui.control.DropDown
    end

    
    properties (Access = public)
         Main; % VentanaPrincipal
         Signal1 = {};
         Signal2 = {};
         n1 {};
         n2 {};
         FrecuenciaMuestreo = 0;
         Amplitud = 0;
         Desplazamiento = 0;
         frecuenciaAnalogica = 0;

         Signal = [];
         ni = 0;
         nf = 0;
         n = [];
    end
    
    methods (Access = public)
        
        function cargarDropdown(app, signal,  n)
            
            app.Signal1 = signal;
            app.Signal2 = signal;
            app.n1 = n;
            app.n2 = n;
            app.Cbx_Signal1.Items = "Señal 1";
            app.Cbx_Signal2.Items = "Señal 1";
            final = length(signal);
            if(length(signal)>1)
                for i = 1:1:final-1
                    app.Cbx_Signal1.Items = [app.Cbx_Signal1.Items, ['Señal ' num2str(i+1)]];
                    app.Cbx_Signal2.Items = [app.Cbx_Signal2.Items, ['Señal ' num2str(i+1)]];
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Btn_CrearSignal
        function Btn_CrearSignalButtonPushed(app, event)
            Cb1idx = app.Cbx_Signal1.ValueIndex; % Conseguir el valor de la señal actual
            Cb2idx = app.Cbx_Signal2.ValueIndex;

            len1 = length(app.Signal1{Cb1idx});
            len2 = length(app.Signal2{Cb2idx});
            maxLength = max(len1, len2);        % Verifica el tamaño de las dos señales y obtiene el mayor
            
            % Rellenar con ceros
            app.Signal1{Cb1idx}(end+1:maxLength) = 0;
            app.Signal2{Cb2idx}(end+1:maxLength) = 0;


            dt1 = app.n1{Cb1idx}(2) - app.n1{Cb1idx}(1); % obtener los intervalos de tiempo
            dt2 = app.n2{Cb2idx}(2) - app.n2{Cb2idx}(1);

            
            if dt1 ~= dt2

                msgbox('Las señales no tienen el mismo intervalo de muestreo. No se pueden operar directamente.', 'Cancelado');

            else
                % Obtener nuevo instante inicial y final (intersección)
                
                app.ni = max(app.n1{Cb1idx}(1), app.n2{Cb2idx}(1));   % nuevo instante inicial
                app.nf = min(app.n1{Cb1idx}(end), app.n2{Cb2idx}(end)); % nuevo instante final
                
                % Crear eje de tiempo común
                app.n = app.ni:dt1:app.nf;
                
                if(app.Cbx_Operation.ValueIndex==1)

                    disp("Suma");
                    % Sumar señales (o cualquier operación)
                    app.Signal = app.Signal1{Cb1idx} + app.Signal2{Cb2idx};

                elseif(app.Cbx_Operation.ValueIndex==2)

                    disp("Resta");
                    % Sumar señales (o cualquier operación)
                    app.Signal = app.Signal1{Cb1idx} - app.Signal2{Cb2idx};
               
                elseif(app.Cbx_Operation.ValueIndex==3)

                    disp("Multiplicar");
                    % Sumar señales (o cualquier operación)
                    app.Signal = app.Signal1{Cb1idx} .* app.Signal2{Cb2idx};

                end

                dt = app.n(2) - app.n(1);  % Paso de tiempo
                app.FrecuenciaMuestreo = 1 / dt;           % Frecuencia de muestreo
                
                app.Amplitud = (max(app.Signal) - min(app.Signal)) / 2;
                app.Desplazamiento = mean(app.Signal);  % Promedio (DC offset)
                
                N = length(app.Signal);
                Y = abs(fft(app.Signal));
                f = (0:N-1)*(app.FrecuenciaMuestreo/N);     % Vector de frecuencias
                [~, idx] = max(Y(1:floor(N/2)));            % Pico en la mitad baja
                app.frecuenciaAnalogica = f(idx);     % Frecuencia dominante

                
            end
            
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_2 = uigridlayout(app.GridLayout);
            app.Cbx_Signal1 = uidropdown(app.GridLayout_2);
            app.Cbx_Signal1.Items = {'Signals', ''};
            app.Cbx_Signal1.Value = 'Signals';
            app.Cbx_Operation = uidropdown(app.GridLayout_2);
            app.Cbx_Operation.Items = {'+', '-', 'x'};
            app.Cbx_Operation.Value = '+';
            app.Cbx_Signal2 = uidropdown(app.GridLayout_2);
            app.Cbx_Signal2.Items = {'Signals'};
            app.Cbx_Signal2.Value = 'Signals';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Btn_CrearSignal = uibutton(app.GridLayout2, 'push');
            app.Btn_CrearSignal.ButtonPushedFcn = createCallbackFcn(app, @Btn_CrearSignalButtonPushed, true);
            app.Btn_CrearSignal.Text = 'Crear Señal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoSignals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end